classdef ModelSelection_CML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        RatioTrainTrainValLabel    matlab.ui.control.Label
        RatioEditField             matlab.ui.control.NumericEditField
        UseValDataCheckBox         matlab.ui.control.CheckBox
        ParameterLabel_1           matlab.ui.control.Label
        ParameterLabel_2           matlab.ui.control.Label
        ParameterLabel_3           matlab.ui.control.Label
        ParameterEditField_1       matlab.ui.control.NumericEditField
        ParameterEditField_2       matlab.ui.control.NumericEditField
        ParameterEditField_3       matlab.ui.control.NumericEditField
        HyperparametersLabel       matlab.ui.control.Label
        ModelLabel                 matlab.ui.control.Label
        AddToModelSelectionButton  matlab.ui.control.Button
        ModelDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        function resetHyperparameters(app)
            app.ParameterEditField_1.Visible = 'off';
            app.ParameterEditField_1.Value = 0;
            app.ParameterLabel_1.Visible = 'off';
            app.ParameterLabel_1.Text = '';
            app.ParameterEditField_2.Visible = 'off';
            app.ParameterEditField_2.Value = 0;
            app.ParameterLabel_2.Visible = 'off';
            app.ParameterLabel_2.Text = '';
            app.ParameterEditField_3.Visible = 'off';
            app.ParameterEditField_3.Value = 0;
            app.ParameterLabel_3.Visible = 'off';
            app.ParameterLabel_3.Text = '';
        end
        
        function showHyperparametersFor(app, model)
            switch model
                case 'OC-SVM'
                case 'iForest'
                    app.ParameterEditField_1.Value = 100;
                    app.ParameterEditField_1.Visible = 'on';
                    app.ParameterLabel_1.Text = 'NumLearners';
                    app.ParameterLabel_1.Visible = 'on';
                    app.ParameterEditField_2.Value = 200;
                    app.ParameterEditField_2.Visible = 'on';
                    app.ParameterLabel_2.Text = 'NumObservationsPerLearner';
                    app.ParameterLabel_2.Visible = 'on';
                case 'LOF'
                case 'ABOD'
                case 'Merlin'
                    app.ParameterEditField_1.Value = 20;
                    app.ParameterEditField_1.Visible = 'on';
                    app.ParameterLabel_1.Text = 'minL';
                    app.ParameterLabel_1.Visible = 'on';
                    app.ParameterEditField_2.Value = 100;
                    app.ParameterEditField_2.Visible = 'on';
                    app.ParameterLabel_2.Text = 'maxL';
                    app.ParameterLabel_2.Visible = 'on';
                case 'LDOF'
                    app.ParameterEditField_1.Value = 20;
                    app.ParameterEditField_1.Visible = 'on';
                    app.ParameterLabel_1.Text = 'knn';
                    app.ParameterLabel_1.Visible = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            options.type = 'CML';
            model = app.ModelDropDown.Value;
            options.model = model;

            switch model
                case 'iForest'
                    options.hyperparameters.model.numLearners.value = app.ParameterEditField_1.Value;
                    options.hyperparameters.model.numLearners.type = 'integer';
                    options.hyperparameters.model.numObservationsPerLearner.value = app.ParameterEditField_2.Value;
                    options.hyperparameters.model.numObservationsPerLearner.type = 'integer';
                case 'OC-SVM'
                case 'ABOD'
                case 'LOF'
                case 'Merlin'
                    options.hyperparameters.model.minL.value = app.ParameterEditField_1.Value;
                    options.hyperparameters.model.minL.type = 'integer';
                    options.hyperparameters.model.maxL.value = app.ParameterEditField_2.Value;
                    options.hyperparameters.model.maxL.type = 'integer';
                case 'LDOF'
                    options.hyperparameters.model.knn.value = app.ParameterEditField_1.Value;
                    options.hyperparameters.model.knn.type = 'integer';
            end

            if app.UseValDataCheckBox.Value
                options.hyperparameters.training.ratioTrainVal.value = app.RatioEditField.Value;
                options.hyperparameters.training.ratioTrainVal.type = 'real';
            else
                options.hyperparameters.training.ratioTrainVal.value = 1;
                options.hyperparameters.training.ratioTrainVal.type = 'real';
            end
            
            app.callingApp.addModel_CML(options);
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            app.resetHyperparameters();
            app.showHyperparametersFor(value);
        end

        % Value changed function: UseValDataCheckBox
        function UseValDataCheckBoxValueChanged(app, event)
            value = app.UseValDataCheckBox.Value;
            if value
                app.RatioEditField.Enable = 'on';
                app.RatioTrainTrainValLabel.Enable = 'on';
            else
                app.RatioEditField.Enable = 'off';
                app.RatioTrainTrainValLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ModelDropDown = uidropdown(app.Panel);
            app.ModelDropDown.Items = {'iForest', 'Merlin', 'OC-SVM', 'LOF', 'ABOD', 'LDOF'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'iForest';
            app.AddToModelSelectionButton = uibutton(app.Panel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.ModelLabel = uilabel(app.Panel);
            app.ModelLabel.Text = 'Model:';
            app.HyperparametersLabel = uilabel(app.Panel);
            app.HyperparametersLabel.Text = 'Hyperparameters:';
            app.ParameterEditField_3 = uieditfield(app.Panel, 'numeric');
            app.ParameterEditField_3.Visible = 'off';
            app.ParameterEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ParameterEditField_2.Value = 200;
            app.ParameterEditField_1 = uieditfield(app.Panel, 'numeric');
            app.ParameterEditField_1.Value = 100;
            app.ParameterLabel_3 = uilabel(app.Panel);
            app.ParameterLabel_3.Visible = 'off';
            app.ParameterLabel_3.Text = '';
            app.ParameterLabel_2 = uilabel(app.Panel);
            app.ParameterLabel_2.Text = 'NumObersvationsPerLearner';
            app.ParameterLabel_1 = uilabel(app.Panel);
            app.ParameterLabel_1.Text = 'NumLearners';
            app.UseValDataCheckBox = uicheckbox(app.Panel);
            app.UseValDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseValDataCheckBoxValueChanged, true);
            app.UseValDataCheckBox.Text = 'Use Val Data';
            app.RatioEditField = uieditfield(app.Panel, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.Enable = 'off';
            app.RatioEditField.Value = 0.8;
            app.RatioTrainTrainValLabel = uilabel(app.Panel);
            app.RatioTrainTrainValLabel.Enable = 'off';
            app.RatioTrainTrainValLabel.Text = 'Ratio Train/(Train+Val)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_CML(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end