classdef OptimizeAll < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OptimizationPanel           matlab.ui.container.Panel
        OptimizeScoreDropDown       matlab.ui.control.DropDown
        OptimizeScoreDropDownLabel  matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        RunOptimizationButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        selection       % Either 'all', 'DNN_selection', 'CML_selection' or 'S_selection'
    end
    
    methods (Access = private)
        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.OptimizationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.OptimizationPanel.Enable = 'on';
            set(app.UIFigure, 'pointer', oldPointer);
        end
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, selection)
            app.callingApp = mainApp;
            app.selection = selection;
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            iterations = app.IterationsEditField.Value;
            score = app.OptimizeScoreDropDown.Value;

            app.callingApp.runOptimizationFor(iterations, score, app.selection);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.RunOptimizationButton = uibutton(app.OptimizationPanel, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 30;
            app.OptimizeScoreDropDownLabel = uilabel(app.OptimizationPanel);
            app.OptimizeScoreDropDownLabel.Text = 'Optimize Score:';
            app.OptimizeScoreDropDown = uidropdown(app.OptimizationPanel);
            app.OptimizeScoreDropDown.Items = {'Composite F1 Score', 'Point-wise F1 Score', 'Event-wise F1 Score', 'Point-adjusted F1 Score'};
            app.OptimizeScoreDropDown.Value = 'Composite F1 Score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizeAll(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end