classdef OptimizeParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OptimizationPanel             matlab.ui.container.Panel
        RunOptimizationforLabel       matlab.ui.control.Label
        RunForAllButton               matlab.ui.control.Button
        OptimizeScoreDropDown         matlab.ui.control.DropDown
        OptimizeScoreDropDownLabel    matlab.ui.control.Label
        IterationsEditField           matlab.ui.control.NumericEditField
        IterationsEditFieldLabel      matlab.ui.control.Label
        HyperparameterSelectionPanel  matlab.ui.container.Panel
        ClearButton                   matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        OptimizationVariablesListBox  matlab.ui.control.ListBox
        RunForSelectionButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        models          % The model on which to perform optimization (struct)
        modelType       % Type of model (DNN, CML or S (deep learning, classic machine learning, statistical))
        optVars         % The optimization hyperparameters
        AddPopup        % Popup for adding hyperparameter
    end
    
    methods (Access = private)
        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.OptimizationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.OptimizationPanel.Enable = 'on';
            set(app.UIFigure, 'pointer', oldPointer);
        end
    end
    
    methods (Access = public)
        function addHyperparameter(app, name, value, type)
            if ismember(name, app.OptimizationVariablesListBox.ItemsData)
                errordlg("Can't add the same hyperparameter twice!");
                return;
            end
            app.optVars.(name).value = value;
            app.optVars.(name).type = type;
            app.OptimizationVariablesListBox.Items = [app.OptimizationVariablesListBox.Items sprintf('%s    from: %d to: %d', name, value(1), value(2))];
            app.OptimizationVariablesListBox.ItemsData = [app.OptimizationVariablesListBox.ItemsData name];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, models, modelType)
            app.callingApp = mainApp;
            app.models = models;
            app.modelType = modelType;
            numOfModels = length(app.models);
            if numOfModels > 1
                value = cell(1, numOfModels);
                for i = 1:numOfModels
                    value{1, i} = app.models(i).options.model;
                end
                app.optVars.model.value = value;
                app.optVars.model.type = 'categorical';
            end
        end

        % Button pushed function: RunForSelectionButton
        function RunForSelectionButtonPushed(app, event)
            if isempty(app.optVars)
                uialert(app.UIFigure, 'No hyperparameters selected for optimization!', 'Parameter Error', Icon='error');
                return;
            end
            app.callingApp.runOptimization(app.models, app.optVars, ...
                app.IterationsEditField.Value, app.OptimizeScoreDropDown.Value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.AddPopup = AddHyperparameterPopup(app, app.models);
        end

        % Button pushed function: RunForAllButton
        function RunForAllButtonPushed(app, event)
            if length(app.models) > 1
                uialert(app.UIFigure, 'No automatic optimization for multiple different models!', 'Model Error', Icon='error');
                return;
            end
            
            app.optVars = getOptimizationVariables(app.models(1).options.model, 'config_optimization.json');
            if isempty(app.optVars)
                uialert(app.UIFigure, 'No hyperparameters found for optimization!', 'Parameter Error', Icon='error');
                return;
            end
            
            app.callingApp.runOptimization(app.models, app.optVars, ...
                app.IterationsEditField.Value, app.OptimizeScoreDropDown.Value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.optVars = {};
            app.OptimizationVariablesListBox.Items = [];
            app.OptimizationVariablesListBox.ItemsData = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.RunForSelectionButton = uibutton(app.OptimizationPanel, 'push');
            app.RunForSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunForSelectionButtonPushed, true);
            app.RunForSelectionButton.Text = 'Selection';
            app.HyperparameterSelectionPanel = uipanel(app.OptimizationPanel);
            app.HyperparameterSelectionPanel.Title = 'Hyperparameter Selection';
            app.OptimizationVariablesListBox = uilistbox(app.HyperparameterSelectionPanel);
            app.OptimizationVariablesListBox.Items = {};
            app.OptimizationVariablesListBox.Value = {};
            app.AddButton = uibutton(app.HyperparameterSelectionPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ClearButton = uibutton(app.HyperparameterSelectionPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 30;
            app.OptimizeScoreDropDownLabel = uilabel(app.OptimizationPanel);
            app.OptimizeScoreDropDownLabel.Text = 'Optimize Score:';
            app.OptimizeScoreDropDown = uidropdown(app.OptimizationPanel);
            app.OptimizeScoreDropDown.Items = {'Composite F1 Score', 'Point-wise F1 Score', 'Event-wise F1 Score', 'Point-adjusted F1 Score'};
            app.OptimizeScoreDropDown.Value = 'Composite F1 Score';
            app.RunForAllButton = uibutton(app.OptimizationPanel, 'push');
            app.RunForAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunForAllButtonPushed, true);
            app.RunForAllButton.Text = 'All';
            app.RunOptimizationforLabel = uilabel(app.OptimizationPanel);
            app.RunOptimizationforLabel.Text = 'Run Optimization for:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizeParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end