classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainPanel                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        DataPanel                       matlab.ui.container.Panel
        D_NextButton                    matlab.ui.control.Button
        D_AVSVehicleScenarioButtonGroup  matlab.ui.container.ButtonGroup
        D_AVSTownButton                 matlab.ui.control.RadioButton
        D_AVSHighwayButton              matlab.ui.control.RadioButton
        D_UAVSensorDataButtonGroup      matlab.ui.container.ButtonGroup
        D_UAVGyroscopeButton            matlab.ui.control.RadioButton
        D_UAVAccelerometerButton        matlab.ui.control.RadioButton
        D_SWaTSelectSensorDropDown      matlab.ui.control.DropDown
        D_SWaTSelectSensorLabel         matlab.ui.control.Label
        D_LoadDataButton                matlab.ui.control.Button
        D_MainButtonGroup               matlab.ui.container.ButtonGroup
        D_ImportCustomDatasetEditField  matlab.ui.control.EditField
        D_AvailableDatasetsDropDownLabel  matlab.ui.control.Label
        D_AvailableDatasetsDropDown     matlab.ui.control.DropDown
        D_ImportCustomDatasetLabel      matlab.ui.control.Label
        D_BrowseCustomDatasetButton     matlab.ui.control.Button
        D_AvailableDatasetsButton       matlab.ui.control.RadioButton
        D_CustomDatasetButton           matlab.ui.control.RadioButton
        D_RawTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        D_RawTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        PreprocessingTab                matlab.ui.container.Tab
        PreprocessingPanel              matlab.ui.container.Panel
        P_MethodButtonGroup             matlab.ui.container.ButtonGroup
        P_RescaleButton                 matlab.ui.control.RadioButton
        P_StandardizeButton             matlab.ui.control.RadioButton
        P_RawDataButton                 matlab.ui.control.RadioButton
        P_PreviousButton                matlab.ui.control.Button
        P_NextButton                    matlab.ui.control.Button
        P_PreprocessedTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        P_PreprocessedTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        ModelTrainingTab                matlab.ui.container.Tab
        ModelTrainingPanel              matlab.ui.container.Panel
        MT_TrainParallelLabel           matlab.ui.control.Label
        MT_TrainAllNetworksParallelButton  matlab.ui.control.Button
        MT_TrainSelectedNetworksParallelButton  matlab.ui.control.Button
        MT_TrainConsecutiveLabel        matlab.ui.control.Label
        MT_PreviewPanel                 matlab.ui.container.Panel
        MT_ShowNetworkButton            matlab.ui.control.Button
        MT_PreviewOptionsPanel          matlab.ui.container.Panel
        MT_PreviewContentRatioTrainValLabel  matlab.ui.control.Label
        MT_PreviewContentMinibatchSizeLabel  matlab.ui.control.Label
        MT_PreviewContentEpochsLabel    matlab.ui.control.Label
        MT_PreviewContentStepSizeLabel  matlab.ui.control.Label
        MT_PreviewContentWindowSizeLabel  matlab.ui.control.Label
        MT_PreviewContentModelHyperparameter_2_Label  matlab.ui.control.Label
        MT_PreviewContentModelHyperparameter_1_Label  matlab.ui.control.Label
        MT_PreviewContentModelTypeLabel  matlab.ui.control.Label
        MT_PreviewContentModelLabel     matlab.ui.control.Label
        MT_PreviewRatioTrainValLabel    matlab.ui.control.Label
        MT_PreviewMinibatchSizeLabel    matlab.ui.control.Label
        MT_PreviewEpochsLabel           matlab.ui.control.Label
        MT_PreviewStepSizeLabel         matlab.ui.control.Label
        MT_PreviewWindowSizeLabel       matlab.ui.control.Label
        MT_PreviewModelHyperparameter_2_Label  matlab.ui.control.Label
        MT_PreviewModelHyperparameter_1_Label  matlab.ui.control.Label
        MT_PreviewModelTypeLabel        matlab.ui.control.Label
        MT_PreviewModelLabel            matlab.ui.control.Label
        MT_PreviewUIAxes                matlab.ui.control.UIAxes
        MT_TrainingStatusLabel          matlab.ui.control.Label
        MT_TrainAllNetworksButton       matlab.ui.control.Button
        MT_TrainingStatusInformationLamp  matlab.ui.control.Lamp
        MT_TrainSelectedNetworksButton  matlab.ui.control.Button
        MT_PreviousButton               matlab.ui.control.Button
        MT_NetworkSelectionButtonGroup  matlab.ui.container.ButtonGroup
        MT_ClearNetworksButton          matlab.ui.control.Button
        MT_AddNetworkButton             matlab.ui.control.Button
        MT_NetworksLabel                matlab.ui.control.Label
        MT_NetworksListBox              matlab.ui.control.ListBox
        MT_PretrainedNetworkLoadButton  matlab.ui.control.Button
        MT_PretrainedNetworkLabel       matlab.ui.control.Label
        MT_PretrainedNetworkTextArea    matlab.ui.control.TextArea
        MT_PretrainedNetworkBrowseButton  matlab.ui.control.Button
        MT_PretrainedNetworkButton      matlab.ui.control.RadioButton
        MT_NewNetworksButton            matlab.ui.control.RadioButton
        MT_NextButton                   matlab.ui.control.Button
        DetectionEvaluationTab          matlab.ui.container.Tab
        DetectionEvaluationPanel        matlab.ui.container.Panel
        DE_DetectionPanel               matlab.ui.container.Panel
        DE_DetectionTabGroup            matlab.ui.container.TabGroup
        DE_PlotsTab                     matlab.ui.container.Tab
        zrangeSliderLabel               matlab.ui.control.Label
        DE_ZRangeSlider                 matlab.ui.control.Slider
        WindowSizeSliderLabel           matlab.ui.control.Label
        DE_WindowSizeSlider             matlab.ui.control.Slider
        DE_DynamicThresholdingUITable   matlab.ui.control.Table
        DE_AnomalyPaddingSlider         matlab.ui.control.Slider
        AnomalyPaddingSliderLabel       matlab.ui.control.Label
        DE_StaticThresholdingUITable    matlab.ui.control.Table
        DE_StaticThresholdingLabel      matlab.ui.control.Label
        DE_DynamicThresholdingLabel     matlab.ui.control.Label
        DE_StaticThresholdingParametricCheckBox  matlab.ui.control.CheckBox
        DE_StaticThresholdingDataUIAxes  matlab.ui.control.UIAxes
        DE_DynamicThresholdingDataUIAxes  matlab.ui.control.UIAxes
        DE_DynamicThresholdingResidualUIAxes  matlab.ui.control.UIAxes
        DE_StaticThresholdingResidualUIAxes  matlab.ui.control.UIAxes
        DE_TableTab                     matlab.ui.container.Tab
        DE_FullDetectionUITable         matlab.ui.control.Table
        DE_DetectionShownForLabel       matlab.ui.control.Label
        DE_RunDetectionForAllButton     matlab.ui.control.Button
        DE_RunDetectionForLabel         matlab.ui.control.Label
        DE_RunDetectionForSelectedButton  matlab.ui.control.Button
        DE_FaultyDataSelectionDropDown  matlab.ui.control.DropDown
        SelectfaultyDataLabel           matlab.ui.control.Label
        DE_TrainedNetworksPanel         matlab.ui.container.Panel
        DE_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        DE_TrainedNetworksListBox       matlab.ui.control.ListBox
        MT_NextButton_2                 matlab.ui.control.Button
        MT_PreviousButton_2             matlab.ui.control.Button
        DynamicSwitchTab                matlab.ui.container.Tab
        FaultydataselectionPanel_SWaT   matlab.ui.container.Panel
        OnlinedetectionsimulationButton_2  matlab.ui.control.Button
        VisualizedataButton_6           matlab.ui.control.Button
        PredictdataButton_3             matlab.ui.control.Button
        DayListBox                      matlab.ui.control.ListBox
        DayListBoxLabel                 matlab.ui.control.Label
        FaultydataselectionPanel_UAVAcc  matlab.ui.container.Panel
        OnlinedetectionsimulationButton_3  matlab.ui.control.Button
        VisualizedataButton_7           matlab.ui.control.Button
        FaulttypeButtonGroup_4          matlab.ui.container.ButtonGroup
        StuckvalueButton_3              matlab.ui.control.ToggleButton
        NoiseButton_3                   matlab.ui.control.ToggleButton
        OffsetButton_4                  matlab.ui.control.ToggleButton
        PredictdataButton_2             matlab.ui.control.Button
        ScenarioListBox_2               matlab.ui.control.ListBox
        ScenarioListBox_2Label          matlab.ui.control.Label
        FaulttypeButtonGroup_2          matlab.ui.container.ButtonGroup
        StuckvalueButton_2              matlab.ui.control.ToggleButton
        PackagedropButton               matlab.ui.control.ToggleButton
        OffsetButton_2                  matlab.ui.control.ToggleButton
        FaultydataselectionPanel_AVS    matlab.ui.container.Panel
        OnlinedetectionsimulationButton  matlab.ui.control.Button
        PredictdataButton               matlab.ui.control.Button
        ScenarioListBox                 matlab.ui.control.ListBox
        ScenarioListBoxLabel            matlab.ui.control.Label
        FaultdurationDropDown           matlab.ui.control.DropDown
        FaultdurationDropDownLabel      matlab.ui.control.Label
        VisualizedataButton_5           matlab.ui.control.Button
        FaulttypeButtonGroup            matlab.ui.container.ButtonGroup
        StuckvalueButton                matlab.ui.control.ToggleButton
        NoiseButton                     matlab.ui.control.ToggleButton
        OffsetButton                    matlab.ui.control.ToggleButton
        UAVPreparationPanel             matlab.ui.container.Panel
        VisualizedataButton_4           matlab.ui.control.Button
        NextButton_6                    matlab.ui.control.Button
        RestartButton_4                 matlab.ui.control.Button
        SensordataselectionButtonGroup  matlab.ui.container.ButtonGroup
        GyroscopeButton                 matlab.ui.control.ToggleButton
        AccelerometerButton             matlab.ui.control.ToggleButton
        AVSPreparationPanel             matlab.ui.container.Panel
        VisualizedataButton_3           matlab.ui.control.Button
        NextButton_5                    matlab.ui.control.Button
        RestartButton_3                 matlab.ui.control.Button
        VehiculescenarioButtonGroup     matlab.ui.container.ButtonGroup
        TownButton                      matlab.ui.control.ToggleButton
        HighwayButton                   matlab.ui.control.ToggleButton
        NetworkOptionsPanel             matlab.ui.container.Panel
        RatioEditField                  matlab.ui.control.NumericEditField
        RatioEditField_2Label           matlab.ui.control.Label
        NextButton_4                    matlab.ui.control.Button
        NetworkdesciptionButton         matlab.ui.control.Button
        UsevaldataCheckBox              matlab.ui.control.CheckBox
        SavenettoworkspaceButton        matlab.ui.control.Button
        MinibatchsizeEditField          matlab.ui.control.NumericEditField
        MinibatchsizeEditFieldLabel     matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        FilterNumberEditField           matlab.ui.control.NumericEditField
        FilterNumberEditFieldLabel      matlab.ui.control.Label
        StepSizesEditField              matlab.ui.control.NumericEditField
        StepSizesEditFieldLabel         matlab.ui.control.Label
        WindowsizewEditField            matlab.ui.control.NumericEditField
        WindowsizewEditFieldLabel       matlab.ui.control.Label
        HiddenUnitsEditField            matlab.ui.control.NumericEditField
        HiddenUnitsEditFieldLabel       matlab.ui.control.Label
        NumberofneuronsEditField        matlab.ui.control.NumericEditField
        NumberofneuronsEditFieldLabel   matlab.ui.control.Label
        NetworkspecificityDropDown      matlab.ui.control.DropDown
        NetworkspecificityDropDownLabel  matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        RestartButton_2                 matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SWaTDataPanel                   matlab.ui.container.Panel
        SelectedSensorLamp              matlab.ui.control.Lamp
        SelectedSensorLampLabel         matlab.ui.control.Label
        VisualizedataButton_2           matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        SelectedSensorCheckBox          matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        PreprocessingPanel_Old          matlab.ui.container.Panel
        NetworkButtonGroup_2            matlab.ui.container.ButtonGroup
        ReconstructionButton            matlab.ui.control.RadioButton
        PredictiveButton                matlab.ui.control.RadioButton
        NextButton_2                    matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        PreprocessingmethodButtonGroup  matlab.ui.container.ButtonGroup
        RawdataButton                   matlab.ui.control.ToggleButton
        Rescale01Button                 matlab.ui.control.ToggleButton
        StandardizeButton               matlab.ui.control.ToggleButton
        DataPreparationPanel            matlab.ui.container.Panel
        NextButton                      matlab.ui.control.Button
        NetworkButtonGroup              matlab.ui.container.ButtonGroup
        TrainnewnetworkButton           matlab.ui.control.RadioButton
        PretrainedButton                matlab.ui.control.RadioButton
        SelectDatasetButtonGroup        matlab.ui.container.ButtonGroup
        AVSButton                       matlab.ui.control.ToggleButton
        UAVButton                       matlab.ui.control.ToggleButton
        SWaTButton                      matlab.ui.control.ToggleButton
        selectedModelsContextMenu       matlab.ui.container.ContextMenu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
%% TODO
%  Random splitting of datasets into train/val and test/val
%  New Models
%  Add multiple models add once
%%
        %% App Status Information        
        loadedTrainingData = false; % True once training data has been loaded
        finishedPreprocessingTrainingData = false; % True once training data has been preprocessed
        finishedTrainingModels = false; % True once a model / multiple models have been successfully trained
        %%


        %% Data
        datasetType = 'None'; % Tracks the loaded dataset
        preprocessingMethod = 'None'; % Tracks the applied preprocessing method
        
        filesTrainingData % Array of filenames of the training dataset
        filesTestingData % Array of filenames of the testing dataset

        labelsTestingData % Anomaly labels for testing data (0 for normal, 1 for anomaly)

        rawTrainingData % Initial fault free raw data
        rawTestingData % Initial faulty raw data

        preprocessedTrainingData % Preprocessed fault free data
        preprocessedTestingData % Preprocessed faulty data

        testValData % Anomalous validation data (already preprocessed)
        testValLabels % Anomalous validation data labels

        timestampsTrainingData % Timestamps of training data
        timestampsTestingData % Timestamps of the testing data  

        SWaTData % Data of the SWaT dataset
        selectedSWaTSensor % The selected sensor of the SWaT dataset
        idx_selectedSWaTSensor % Index of the selecte SWaT sensor

        UAVAccData % UAV Accelerometer dataset
        UAVGyrData % UAV Gyroscope dataset
        selectedUAVSensor % The selected sensor of the UAV dataset
        %%

        %% Preprocessing
        maximum % Maximum value of training data
        minimum % Minimum value of training data

        mu % Mean of training data
        sigma % Standard deviation of training data
        %%

        %% Model
        selectedNetworks % Structured data of selected models and their hyperparameters
        trainedNetworks % Trained deep neural networks array
        %%

        %% Detection
        currentPred % Currently shown prediction in detection tab
        currentYTest % YTest for currently shown prediction
        currentLabels % Labels of currently shown prediction
        currentNetName % Index of network used for current prediction
        %%
        
        %% Popup Apps
        modelSelectionApp % Popup for selecting the models
        %%
    end
    
    properties (Access = public)
%         SWaT = load(fullfile(pwd, "myApp_resources\SWaT\SWaT Data.mat"));
%         UAVAcc = load(fullfile(pwd, "myApp_resources\UAV\UAV Acc.mat"), "faultFreeAcc");
%         UAVAccFault = load(fullfile(pwd, "myApp_resources\UAV\faultyAcc.mat"));
%         UAVGyrFault = load(fullfile(pwd, "myApp_resources\UAV\faultyGyr.mat"));
%         UAVGyr = load(fullfile(pwd, "myApp_resources\UAV\UAV Gyr.mat"), "faultFreeGyr");
%         AVS = load(fullfile(pwd, "myApp_resources\AVS\AVS.mat"));
%         SWaTAttacks = load(fullfile(pwd, "myApp_resources\SWaT\attackList.mat"))
        net % Network
        netOptions % Network options
        dataTest % Test data for simulink
        labels % Labels for simulink
    end
    
    methods (Access = private)
%% App interaction and helper functions

        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.DataPanel.Enable = 'off';
            app.PreprocessingPanel.Enable = 'off';
            app.ModelTrainingPanel.Enable = 'off';
            app.DetectionEvaluationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.DataPanel.Enable = 'on';
            if app.loadedTrainingData
                app.PreprocessingPanel.Enable = 'on';
            end
%             if app.finishedPreprocessingTrainingData
%                 app.ModelTrainingPanel.Enable = 'on';
%             end
            app.ModelTrainingPanel.Enable = 'on';
%             if app.finishedTrainingModels
%                 app.DetectionEvaluationPanel.Enable = 'on';
%             end
            app.DetectionEvaluationPanel.Enable = 'on';

            set(app.UIFigure, 'pointer', oldPointer);
        end
        
        function showDataPreparationFor(app, dataset)
            % Hides every data preperation tab except the on for the
            % selected dataset. Updates the label for the selected dataset.
            switch dataset
                case 'SWaT'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'on';
                    app.D_SWaTSelectSensorDropDown.Visible = 'on';
                case 'UAV'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'on';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'AVS'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'on';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'Custom'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'None'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
            end
        end
        
        function toggleTrainingStatus(app, state)
            if state
                app.MT_TrainingStatusLabel.Text = 'Training Status: finished';
                app.MT_TrainingStatusInformationLamp.Color = 'green';
                app.finishedTrainingModels = true;
            else
                app.MT_TrainingStatusLabel.Text = 'Training Status: unfinished';
                app.MT_TrainingStatusInformationLamp.Color = 'red';
                app.finishedTrainingModels = false;
            end
        end
        
        function togglePreprocessingTab(app, state)
            if state
                app.loadedTrainingData = true;
                app.PreprocessingPanel.Enable = 'on';
                app.D_NextButton.Enable = 'on';
            else
                app.loadedTrainingData = false;
                app.PreprocessingPanel.Enable = 'off';
                app.D_NextButton.Enable = 'off';
            end
        end

        function toggleModelTrainingTab(app, state)
            if state
                app.finishedPreprocessingTrainingData = true;
                app.ModelTrainingPanel.Enable = 'on';
                app.P_NextButton.Enable = 'on';
            else
                app.finishedPreprocessingTrainingData = false;
                app.ModelTrainingPanel.Enable = 'off';
                app.P_NextButton.Enable = 'off';
            end
        end

        function toggleDetectionEvaluationTab(app, state)
            if state
                app.DetectionEvaluationPanel.Enable = 'on';
            else
                app.DetectionEvaluationPanel.Enable = 'off';
            end
        end
        
        function toggleTrainButtons(app, state)
            if state
                app.MT_TrainAllNetworksButton.Enable = 'on';
                app.MT_TrainSelectedNetworksButton.Enable = 'on';
                app.MT_TrainConsecutiveLabel.Enable = 'on';
                app.MT_TrainAllNetworksParallelButton.Enable = 'on';
                app.MT_TrainSelectedNetworksParallelButton.Enable = 'on';
                app.MT_TrainParallelLabel.Enable = 'on';
            else
                app.MT_TrainAllNetworksButton.Enable = 'off';
                app.MT_TrainSelectedNetworksButton.Enable = 'off';
                app.MT_TrainConsecutiveLabel.Enable = 'off';
                app.MT_TrainAllNetworksParallelButton.Enable = 'off';
                app.MT_TrainSelectedNetworksParallelButton.Enable = 'off';
                app.MT_TrainParallelLabel.Enable = 'off';
            end
        end

        function graph = createModelGraphForPreview(app, options)
            layers = app.getLayers(options, 1, 1);

            layers = layerGraph(layers);

            a = layers.Connections;
            b = extractBefore(a.Source, '/');
            c = extractBefore(a.Destination, '/');
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end
        
        function [label, id] = getLabelForNetwork(app, model)
            label = strcat(model, '  (1)');
            id = strcat(model, '_1');
            id = replace(id, ' ', '_');
            id = replace(id, '-', '_');
            id = replace(id, '(', '');
            id = replace(id, ')', '');

            itemCounter = 0;
            for i = 1:length(app.MT_NetworksListBox.Items)
                item = split(app.MT_NetworksListBox.Items(i), '  ');
                if strcmp(model, item{1})
                    itemCounter = itemCounter + 1;
                end
            end
            if itemCounter > 0
                label = sprintf('%s  (%d)', model, itemCounter + 1);
                id = sprintf('%s_%d', model, itemCounter + 1);
                id = replace(id, ' ', '_');
                id = replace(id, '-', '_');
                id = replace(id, '(', '');
                id = replace(id, ')', '');
            end
        end

        function updateNetworkLabels(app)
            itemsLength = length(app.MT_NetworksListBox.Items);
            newItems = cell(itemsLength, 1);
            newItemsData = struct('model', {}, ...
                'modelType', {}, ...
                'neurons', {}, ...
                'hiddenUnits', {}, ...
                'filter', {}, ...
                'windowSize', {}, ...
                'stepSize', {}, ...
                'epochs', {}, ...
                'minibatchSize', {}, ...
                'ratioTrainVal', {}, ...
                'id', {}, ...
                'label', {});
            idx = 1;
            labelCounter = 0;
            updatedLabels = strings();

            for i = 1:itemsLength
                label = split(app.MT_NetworksListBox.ItemsData(i).label, '  ');
                if ~ismember(label{1}, updatedLabels)
                    for j = 1:itemsLength
                        itemCmp = split(app.MT_NetworksListBox.ItemsData(j).label, '  ');
                        if strcmp(label{1}, itemCmp{1})
                            newItems{idx} = sprintf('%s  (%d)', itemCmp{1}, labelCounter + 1);
                            newItemsData(idx) = app.MT_NetworksListBox.ItemsData(j);
                            newItemsData(idx).id = sprintf('%s_%d', itemCmp{1}, labelCounter + 1);
                            newItemsData(idx).id = replace(newItemsData(idx).id, ' ', '_');
                            newItemsData(idx).id = replace(newItemsData(idx).id, '-', '_');
                            newItemsData(idx).id = replace(newItemsData(idx).id, '(', '');
                            newItemsData(idx).id = replace(newItemsData(idx).id, ')', '');
                            newItemsData(idx).label = newItems{idx};
                            idx = idx + 1;
                            labelCounter = labelCounter + 1;
                        end
                    end
                    updatedLabels(end + 1) = label{1};
                    labelCounter = 0;
                end
            end
            app.MT_NetworksListBox.Items = newItems;
            app.MT_NetworksListBox.ItemsData = newItemsData;
        end
        
        function legendUIAxes(~, ~,evnt, axes)
            lines = axes.Children;
            
            click_locations = linspace(0,1,numel(lines)+1);
            
            line_num = discretize(evnt.IntersectionPoint(2),click_locations);
            
            relevant_line = lines(line_num);
            if strcmp(relevant_line.Visible,'on')
                relevant_line.Visible = 'off';
            else
                relevant_line.Visible = 'on';
            end            
        end
                
        function handles = preparePlots(~, netLabels)
            % Creates a figure to display training progress information.
            numNets = length(netLabels);
            colors = lines(numNets);
            f = figure('Units', 'normalized', 'Position', [0.1 0.1 0.5 0.5]);
            f.Visible = true;
            subplot(2, 1, 1), ylabel('RMSE'), grid on;
            subplot(2, 1, 2), ylabel('Loss'), xlabel('Iteration'), grid on;
            for i=1:numNets
                subplot(2, 1, 1);
                handles.RMSELines(i) = animatedline('Color', colors(i, :), 'LineStyle', '-');
                handles.validationRMSELines(i) = animatedline('Color', colors(i, :), 'LineStyle', 'none', 'Marker', '.', 'MarkerSize', 14);
                subplot(2, 1, 2)
                handles.lossLines(i) = animatedline('Color', colors(i, :), 'LineStyle', '-');
                handles.validationLossLines(i) = animatedline('Color', colors(i, :), 'LineStyle', 'none', 'Marker', '.', 'MarkerSize', 14);
            end
            subplot(2, 1, 1), legend(handles.RMSELines, netLabels);
            subplot(2, 1, 2), legend(handles.lossLines, netLabels);
        end
        
        function updatePlots(~, handles, data)
        % Updates the plots in handles, created by PREPAREPLOTS, with
        % training progress information.
            info = data.info;
            if info.State == "iteration"
                addpoints(handles.RMSELines(data.experimentNumber), info.Iteration, info.TrainingRMSE);
                if ~isempty(info.ValidationRMSE)
                    addpoints(handles.validationRMSELines(data.experimentNumber), info.Iteration, info.ValidationRMSE);
                end
                addpoints(handles.lossLines(data.experimentNumber), info.Iteration, info.TrainingLoss);
                if ~isempty(info.ValidationLoss)
                    addpoints(handles.validationLossLines(data.experimentNumber), info.Iteration, info.ValidationLoss);
                end
            end
        end

%% Data preparation functions

        function r = validateCustomData(~, data)
            univariateColumnNames = ['timestamp', 'value', 'is_anomaly'];

            if length(data.Properties.VariableNames) < 4
                % Univariate time series
                for i = 1:length(data.Properties.VariableNames)
                    if ~ismember(data.Properties.VariableNames{i}, univariateColumnNames)
                        f = errordlg(['Wrong column names. Should be: timestamp, value, is_anomaly. ' ...
                            'Third column (is_anomaly) is optional for training data.'], ...
                            'Column Name Error');
                        r = false;
                        return
                    end
                end
                r = true;
            else
                % Multivariate time series. Not yet supported!
                f = errordlg(['Multivariate timeseries currently not supported. ' ...
                    'Use univariate timeseries with columns: timestamp, value, is_anomaly. ' ...
                    'Third column (is_anomaly) is optional for training data.'], ...
                    'Timeseries Format Error');
                r = false;
                return
            end
        end
               
        function [trainingData, timestampsTraining, filesTraining, ...
                testingData, timestampsTesting, labelsTesting, filesTesting] = loadCustomDataset(app, path)
            trainingData = 0;
            testingData = 0;
            timestampsTraining = 0;
            timestampsTesting = 0;
            filesTraining = 0;
            filesTesting = 0;
            labelsTesting = 0;
            
            dataPath = path;

            if ~isfolder(path)
                dataPath = fullfile(pwd, path);
                if ~isfolder(dataPath)
                    f = errordlg('Please select a valid folder!', ...
                            'Folder Error');
                    return
                end
            end
            
            dataTrainPath = fullfile(dataPath, 'noAnomaly');
            dataTestPath = fullfile(dataPath, 'withAnomaly');


            trainingFiles = dir(fullfile(dataTrainPath, '*.csv'));
            testingFiles = dir(fullfile(dataTestPath, '*.csv'));

            if numel(trainingFiles) == 0 && numel(testingFiles) == 0
                f = warndlg(['No training or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: noAnomaly, withAnomaly. The folders should include .csv files with fields:' ...
                    'timestamp, value, is_anomaly (optional for training data).'], ...
                            'Data Error');
                return
            end
            
            numOfTrainingFiles = numel(trainingFiles);
            numOfTestingFiles = numel(testingFiles);
            if numOfTrainingFiles > 0
                trainingData = cell(numOfTrainingFiles, 1);
                timestampsTraining = cell(numOfTrainingFiles, 1);
                filesTraining = strings(1, numOfTrainingFiles);
            end
            if numOfTestingFiles > 0
                testingData = cell(numOfTestingFiles, 1);
                timestampsTesting = cell(numOfTestingFiles, 1);
                labelsTesting = cell(numOfTestingFiles, 1);
                filesTesting = strings(1, numOfTestingFiles);
            end
                
            for i = 1:numOfTrainingFiles
                file = fullfile(dataTrainPath, trainingFiles(i).name);
                data = readtable(file);

                if ~app.validateCustomData(data)
                    trainingData = 0;
                    testingData = 0;
                    f = errordlg("Some of the files are in a wrong format. " + ...
                        "Each file should be a .csv file with fields: timestamp, " + ...
                        "value, is_anomaly (optional for training data)", ...
                            'Data Format Error');
                    return
                end
                name = strsplit(trainingFiles(i).name, '.');
                filesTraining(1, i) = name(1);
                trainingData{i, 1} = data{:, 2}';

                try
                    timestampsTraining{i, 1} = datetime(data{:, 1})';
                catch
                    f = errordlg("Couldn't convert timestamps to datetime format.", ...
                            'Data Format Error');
                    return
                end
            end


            for i = 1:numOfTestingFiles
                file = fullfile(dataTestPath, testingFiles(i).name);
                data = readtable(file);

                if ~app.validateCustomData(data)
                    trainingData = 0;
                    testingData = 0;
                    f = errordlg("Some of the files are in a wrong format. " + ...
                        "Each file should be a .csv file with fields: timestamp, " + ...
                        "value, is_anomaly (optional for training data)", ...
                            'Data Format Error');
                    return
                end
                name = strsplit(testingFiles(i).name, '.');
                filesTesting(1, i) = name(1);
                testingData{i, 1} = data{:, 2}';
                % TODO: Dataset with actual labels instead of random labels
                labels = zeros(1, size(data, 1));
                labels(1, 2990:3097) = 1;
                labelsTesting{i, 1} = labels;

                try
                    timestampsTesting{i, 1} = datetime(data{:, 1})';
                catch
                    f = errordlg("Couldn't convert timestamps to datetime format.", ...
                            'Data Format Error');
                    return
                end
            end

            %% DUMMY DATA. DELETE THIS!
%             x = ones(1, size(testingData{1, 1}, 2));
%             trainingData{1, 1} = x;
%             
%             y = zeros(1, size(testingData{1, 1}, 2));
%             y(1, 300:400) = 1;
%             labelsTesting{1, 1} = y;
% 
%             x(1, 300:400) = 0.5;
%             testingData{1, 1} = x;
            %%
        end
                
        function preprocessData(app)
            preprocMethod = app.P_MethodButtonGroup.SelectedObject;
            app.preprocessedTrainingData  = 0;
            app.preprocessedTestingData = 0;

            switch preprocMethod
                case app.P_RescaleButton
                    if ~isequal(app.rawTrainingData, 0)
                        app.maximum = max(max([app.rawTrainingData{:, 1}]));
                        app.minimum = min(min([app.rawTrainingData{:, 1}]));
    
                        app.preprocessedTrainingData = app.rescaleData(app.rawTrainingData, app.maximum, app.minimum);
                    end
                    if ~isequal(app.rawTestingData, 0)
                        app.preprocessedTestingData = app.rescaleData(app.rawTestingData, app.maximum, app.minimum);
                    end
                    app.preprocessingMethod = 'Rescale';
                case app.P_StandardizeButton
                    if ~isequal(app.rawTrainingData, 0)
                        [~, mu, sigma] = zscore([app.rawTrainingData{:, 1}]);
                        app.mu = mean(mu);
                        app.sigma = mean(sigma);

                        app.preprocessedTrainingData = app.standardizeData(app.rawTrainingData, app.mu, app.mu);
                    end
                    if ~isequal(app.rawTestingData, 0)
                        app.preprocessedTestingData = app.standardizeData(app.rawTestingData, app.sigma, app.sigma);
                    end
                    app.preprocessingMethod = 'Standardize';
                case app.P_RawDataButton
                    app.preprocessedTrainingData = app.rawTrainingData;
                    app.preprocessedTestingData = app.rawTestingData;
                    app.preprocessingMethod = 'Raw';
            end

            app.splitTestVal();

            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes)
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes)
            
            if ~isequal(app.preprocessedTrainingData, 0)
                hold(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'on')
                for i = 1:size(app.preprocessedTrainingData, 1)
                    plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
                    app.timestampsTrainingData{i, 1},...
                    app.preprocessedTrainingData{i, 1})
                end
                hold(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'off')
    
                set(app.P_PreprocessedTrainingDatasetPreviewUIAxes.Children(1:end-1), 'Visible', 'off')
                l_p_tr = legend(app.P_PreprocessedTrainingDatasetPreviewUIAxes, "Location","bestoutside"); 
                set(l_p_tr, 'ButtonDownFcn', {@app.legendUIAxes, app.P_PreprocessedTrainingDatasetPreviewUIAxes})
            end

            if ~isequal(app.preprocessedTestingData, 0)
                hold(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'on')
                for i = 1:size(app.preprocessedTestingData, 1)
                    plot(app.P_PreprocessedTestingDatasetPreviewUIAxes, ...
                    app.timestampsTestingData{i, 1},...
                    app.preprocessedTestingData{i, 1})
                end
                hold(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'off')
    
                set(app.P_PreprocessedTestingDatasetPreviewUIAxes.Children(1:end-1), 'Visible', 'off')
                l_p_ts = legend(app.P_PreprocessedTestingDatasetPreviewUIAxes, "Location","bestoutside"); 
                set(l_p_ts, 'ButtonDownFcn', {@app.legendUIAxes, app.P_PreprocessedTestingDatasetPreviewUIAxes})
            end
        end
        
        function rescaledData = rescaleData(~, data, maximum, minimum)
            rescaledData = cell(size(data, 1), 1);
            for i = 1:size(data, 1)
                rescaledData{i, 1} = (data{i, 1} - minimum) / (maximum - minimum);
            end
        end

        function standardizedData = standardizeData(~, data, mu, sigma)
            standardizedData = cell(size(data, 1), 1);
            for i = 1:size(data, 1)
                standardizedData{i, 1} = (data{i, 1} - mu) / sigma;
            end
        end
        
        function [XTrain, YTrain, XVal, YVal] = prepareDataForTraining(~, data, windowSize, stepSize, ratioTrainVal, modelType, model)
            XTrain = [];
            YTrain = [];

            for i = 1:size(data, 1)
                numOfWindows = round((size(data{i, 1}, 2) - windowSize - stepSize + 1) / stepSize);

                XTrainLag = lagmatrix(data{i, 1}, 1:windowSize);
                XTrainAll = XTrainLag((windowSize + 1):end,:);

                XTrainTmp = cell(numOfWindows, 1);

                for j = 1:numOfWindows
                    XTrainTmp{j, 1} = flip(XTrainAll(j * stepSize, :));
                end
                
                XTrain = [XTrain; XTrainTmp];

                if strcmp(modelType, 'Predictive')
                    YTrainCell = data{i, 1};
                    YTrainAll = YTrainCell(1, (windowSize + 1):end);

                    YTrainTmp = zeros(numOfWindows, 1);
                    for j = 1:numOfWindows
                        YTrainTmp(j, 1) = YTrainAll(:, (j * stepSize))';
                    end

                    YTrain = [YTrain; YTrainTmp];
                else
                    YTrain = XTrain;
                end
            end
            
            % Transpose data for CNN networks
            if strcmp(model, 'CNN (DeepAnT)')
                XTrain = cellfun(@transpose, XTrain, 'UniformOutput', false);
            end
            if strcmp(model, 'Basic AE')
                XTrain = cellfun(@transpose, XTrain, 'UniformOutput', false);
                YTrain = XTrain;
            end

            if ratioTrainVal ~= 1
                numOfWindows = size(XTrain, 1);
            
                l = round((1 - ratioTrainVal) * numOfWindows);

                XTrain = XTrain(1:(end - l - 1), :);
                YTrain = YTrain(1:(end - l - 1), :);
                XVal = XTrain((end - l):end, :);
                YVal = YTrain((end - l):end, :);
            else
                XVal = 0;
                YVal = 0;
            end
        end
        
        function [XTest, YTest, YLabels] = prepareTestingData(~, data, labels, windowSize, modelType, model)
            XTest = [];
            YTest = [];
            YLabels = [];

            for i = 1:size(data, 1)
                numOfWindows = round((size(data{i, 1}, 2) - windowSize));

                XTestLag = lagmatrix(data{i, 1}, 1:windowSize);
                XTestAll = XTestLag((windowSize + 1):end,:);

                XTestTmp = cell(numOfWindows, 1);

                for j = 1:numOfWindows
                    XTestTmp{j, 1} = flip(XTestAll(j, :));
                end
                
                XTest = [XTest; XTestTmp];
                        
                if strcmp(modelType, 'Predictive')
                    YTestCell = data{i, 1};
                    YTestAll = YTestCell(1, (windowSize + 1):end);
    
                    YLabelsCell = labels{i, 1};
                    YLabelsAll = YLabelsCell(1, (windowSize + 1):end);
                    
    
                    YTestTmp = zeros(numOfWindows, 1);
                    YLabelsTmp = zeros(numOfWindows, 1);
                    
                    for j = 1:numOfWindows
                        YTestTmp(j, 1) = YTestAll(:, j)';
                        YLabelsTmp(j, 1) = logical(YLabelsAll(:, j))';
                    end
                    
                    YTest = [YTest; YTestTmp];
                    YLabels = [YLabels; YLabelsTmp];
                else
                    YTestCell = data{i, 1};
                    YTestAll = YTestCell(1, windowSize:end);
    
                    YLabelsCell = labels{i, 1};
                    YLabelsAll = YLabelsCell(1, windowSize:end);
                    
    
                    YTestTmp = zeros(numOfWindows, 1);
                    YLabelsTmp = zeros(numOfWindows, 1);
                    
                    for j = 1:numOfWindows
                        YTestTmp(j, 1) = YTestAll(:, j)';
                        YLabelsTmp(j, 1) = logical(YLabelsAll(:, j))';
                    end
                    
                    YTest = [YTest; YTestTmp];
                    YLabels = [YLabels; YLabelsTmp];
                end
            end

            if strcmp(model, 'CNN (DeepAnT)') || strcmp(model, 'Basic AE')
                XTest = cellfun(@transpose, XTest, 'UniformOutput', false);
            end
        end

        function splitTestVal(app)
            if isequal(app.preprocessedTestingData, 0)
                app.testValData = 0;
                app.testValLabels = 0;
                return
            end
            if size(app.preprocessedTestingData, 1) > 1
                app.testValData = app.preprocessedTestingData(1, 1);
                app.testValLabels = app.labelsTestingData(1, 1);
            else
                dataTmp = app.preprocessedTestingData{1, 1};
                labelsTmp = app.labelsTestingData{1, 1};
                app.testValData = cell(1, 1);
                app.testValLabels = cell(1, 1);

                splitPoint = round(0.2 * size(dataTmp, 2));

                app.testValData{1, 1} = dataTmp(1, (end - splitPoint:end));
                app.testValLabels{1, 1} = labelsTmp(1, (end - splitPoint:end));
                app.preprocessedTestingData = dataTmp(1, 1:(splitPoint - 1));
                app.labelsTestingData = labelsTmp(1, 1:(splitPoint - 1));
            end
        end

%% Network training functions
         
function layers = getLayers(~, options, numFeatures, numResponses)
            switch options.model
                case 'Basic AE'
                    neurons = options.neurons;
                    layers = [sequenceInputLayer(numFeatures, Name='Input')
                        fullyConnectedLayer(neurons, Name=strcat('Encode: Fully connected with ', num2str(neurons), ' neurons'))
                        fullyConnectedLayer(floor(neurons / 2), Name=strcat('Encode: Fully connected with ', num2str(floor(neurons/2)), ' neurons'))
                        fullyConnectedLayer(floor(floor(neurons / 2) / 2), Name=strcat('Encode: Fully connected with ', num2str(floor(floor(neurons/2)/2)), ' neurons'))
                        fullyConnectedLayer(floor(neurons / 2), Name=strcat("Decode:Fully connected with ", num2str(floor(neurons/2)), ' neurons'))
                        fullyConnectedLayer(neurons, Name=strcat('Decode: Fully connected with ', num2str(neurons), ' neurons'))
                        fullyConnectedLayer(numResponses, Name='Out')
                        regressionLayer(Name='Output')];
                case 'LSTM AE'
                    layers = [
                        sequenceInputLayer(numFeatures, MinLength=options.windowSize)
                        lstmLayer(options.hiddenUnits)
                        dropoutLayer(0.3)
                        lstmLayer(options.hiddenUnits)
                        fullyConnectedLayer(numResponses)
                        regressionLayer];
                case 'Hybrid CNN-LSTM AE'
                    layers = [...
                        sequenceInputLayer(numFeatures, MinLength=options.windowSize)
                        convolution1dLayer(5, options.filter, Padding='same', WeightsInitializer='he', DilationFactor=1);
                        batchNormalizationLayer()
                        reluLayer()
                        convolution1dLayer(5, options.filter, Padding='same', Weightsinitializer='he', DilationFactor=1);
                        reluLayer()
                        averagePooling1dLayer(1, Stride=1, Padding='same')
                        lstmLayer(options.hiddenUnits, RecurrentWeightsInitializer='He', InputWeightsInitializer='He')
                        dropoutLayer(0.25, Name='Dropout')
                        lstmLayer(options.hiddenUnits, RecurrentWeightsInitializer='He', InputWeightsInitializer='He')
                        fullyConnectedLayer(numResponses)
                        regressionLayer(Name='Output')];
                case 'LSTM'
                    outputMode = 'last';

                    layers = [
                        sequenceInputLayer(numFeatures, MinLength=options.windowSize)
                        lstmLayer(options.hiddenUnits)
                        dropoutLayer(0.3)
                        lstmLayer(options.hiddenUnits, OutputMode=outputMode)
                        fullyConnectedLayer(numResponses)
                        regressionLayer];
                case 'Hybrid CNN-LSTM'
                    outputMode = 'last';

                    layers = [...
                        sequenceInputLayer(numFeatures, MinLength=options.windowSize, Name='Input')
                        convolution1dLayer(5, options.filter, Padding='same', WeightsInitializer='he', Name=strcat('Conv1: filter size: 5, #filters: ', num2str(options.filter)), DilationFactor=1);
                        batchNormalizationLayer(Name='bn')
                        reluLayer(Name='elu')
                        convolution1dLayer(5, options.filter, Padding='same', WeightsInitializer='he', Name=strcat('Conv2: filter size: 5, #filters: ', num2str(options.filter)), DilationFactor=1);
                        reluLayer(Name='elu1')
                        averagePooling1dLayer(1, Stride=1, Padding='same')
                        lstmLayer(options.hiddenUnits, Name=strcat('LSTM1: ', num2str(options.hiddenUnits), ' hidden units'), RecurrentWeightsInitializer='He', InputWeightsInitializer='He')
                        dropoutLayer(0.25, Name='Dropout')
                        lstmLayer(options.hiddenUnits, Name=strcat('LSTM2: ', num2str(options.hiddenUnits), ' hidden units'), RecurrentWeightsInitializer='He', InputWeightsInitializer='He', OutputMode=outputMode)
                        fullyConnectedLayer(numResponses, Name='Fully connected')
                        regressionLayer(Name='Output')];
                case 'GRU'
                    outputMode = 'last';

                    layers = [
                        sequenceInputLayer(numFeatures, MinLength=options.windowSize)
                        gruLayer(options.hiddenUnits)
                        dropoutLayer(0.3)
                        gruLayer(options.hiddenUnits, OutputMode=outputMode)
                        fullyConnectedLayer(numResponses)
                        regressionLayer];
                case 'CNN (DeepAnT)'
                    layers = [
                        sequenceInputLayer([numFeatures 1 1], Name='Input')
                        sequenceFoldingLayer(Name='Fold');
                        convolution2dLayer([5 1], options.filter, Stride=1, Padding='same')
                        reluLayer()
                        maxPooling2dLayer(1, Name='Maxpool1')
                        convolution2dLayer([5 1], options.filter, Stride=1, Padding='same')
                        reluLayer()
                        maxPooling2dLayer(1, Name='Maxpool2')
                        sequenceUnfoldingLayer(Name='Unfold');
                        flattenLayer()
                        globalMaxPooling1dLayer()
                        fullyConnectedLayer(numResponses)
                        regressionLayer(Name='Output')
                        ];
                    layers = layerGraph(layers);
                    layers = connectLayers(layers, 'Fold/miniBatchSize', 'Unfold/miniBatchSize');
            end
        end

        function trainOptions = getOptions(~, options, XVal, YVal, numWindows)
            if gpuDeviceCount > 0
                device = 'gpu';
            else
                device = 'cpu';
            end

            switch options.model
                case 'Basic AE'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device);
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'LSTM AE'
                    if length(XVal) <= 1
                        learningRate = 0.001;
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device);
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'Hybrid CNN-LSTM AE'            
                    learningRate = 0.001;                    
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots', 'training-progress');
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots', 'training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'LSTM'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device);
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'Hybrid CNN-LSTM'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots', 'training-progress');
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots','training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'GRU'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device);
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'ExecutionEnvironment', device, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
                case 'CNN (DeepAnT)'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots', 'training-progress');
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'ExecutionEnvironment', device,...
                            'Plots', 'training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)));
                    end
            end
        end

        function trainOptions = getOptionsForParallel(~, options, XVal, YVal, numWindows, dataqueue, idx)
            switch options.model
                case 'Basic AE'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'LSTM AE'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'Hybrid CNN-LSTM AE'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots', 'training-progress', ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots', 'training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'LSTM'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'Hybrid CNN-LSTM'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots', 'training-progress', ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots','training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'GRU'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions("adam", ...
                            "Plots", "training-progress", ...
                            "MaxEpochs", options.epochs, ...
                            "MiniBatchSize", options.minibatchSize, ...
                            "InitialLearnRate", learningRate, ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
                case 'CNN (DeepAnT)'
                    learningRate = 0.001;
                    if length(XVal) <= 1
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots', 'training-progress', ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    else
                        trainOptions = trainingOptions('adam', ...
                            'MaxEpochs', options.epochs, ...
                            'GradientThreshold', 1, ...
                            'InitialLearnRate', learningRate, ...
                            'LearnRateSchedule', "piecewise", ...
                            'LearnRateDropPeriod', 96, ...
                            'LearnRateDropFactor', 0.25, ...
                            'MiniBatchSize', options.minibatchSize,...
                            'Verbose', false, ...
                            'Shuffle', "every-epoch",...
                            'Plots', 'training-progress', ...
                            "ValidationData", {XVal, YVal}, ...
                            "ValidationFrequency", floor(numWindows / (3 * options.minibatchSize)), ...
                            'OutputFcn', @(info) send(dataqueue, struct('experimentNumber', idx, 'info', info)));
                    end
            end
        end
        
        function trainConsecutive(app, networks)
            for i = 1:length(networks)
                options = networks(i).options;

                [XTrain, YTrain, XVal, YVal] = app.prepareDataForTraining(app.preprocessedTrainingData, ...
                        options.windowSize, options.stepSize, options.ratioTrainVal, options.modelType, options.model);
                
                if iscell(YTrain)
                    numResponses = size(YTrain{1, 1}, 1);
                else
                    numResponses = 1;
                end

                layers = app.getLayers(options, size(XTrain{1, 1}, 1), numResponses);
                trainOptions = app.getOptions(options, XVal, YVal, size(XTrain, 1));

                analyzeNetwork(layers);
    
                [net, netOptions] = trainNetwork(XTrain, YTrain, layers, trainOptions);
    
                [staticThreshold, probDist] = app.getStaticThreshold(XVal, YVal, net, options);
                
                trainedNetwork.net = net;
                trainedNetwork.netOptions = netOptions;
                trainedNetwork.options = options;
                trainedNetwork.staticThreshold = staticThreshold;
                trainedNetwork.probDist = probDist;
                trainedNetwork.scores.dynamic.weighted.precision = 0;
                trainedNetwork.scores.dynamic.weighted.recall = 0;
                trainedNetwork.scores.dynamic.weighted.f1score = 0;
                trainedNetwork.scores.dynamic.weighted.f05score = 0;
                trainedNetwork.scores.dynamic.unweighted.precision = 0;
                trainedNetwork.scores.dynamic.unweighted.recall = 0;
                trainedNetwork.scores.dynamic.unweighted.f1score = 0;
                trainedNetwork.scores.dynamic.unweighted.f05score = 0;
                trainedNetwork.scores.static.weighted.precision = 0;
                trainedNetwork.scores.static.weighted.recall = 0;
                trainedNetwork.scores.static.weighted.f1score = 0;
                trainedNetwork.scores.static.weighted.f05score = 0;
                trainedNetwork.scores.static.unweighted.precision = 0;
                trainedNetwork.scores.static.unweighted.recall = 0;
                trainedNetwork.scores.static.unweighted.f1score = 0;
                trainedNetwork.scores.static.unweighted.f05score = 0;

                app.trainedNetworks.(options.id) = trainedNetwork;
            end
        end
        
        function trainParallel(app, networks)
            numGPUs = gpuDeviceCount("available");
            
            if ~isempty(gcp('nocreate'))
                delete(gcp('nocreate'));
            end

            if numGPUs == 0
                parpool
            else
                parpool(numGPUs);
            end

            dataqueue = parallel.pool.DataQueue;
            
            numNetworks = length(networks);

            layersCell = cell(1, numNetworks);
            trainOptions = cell(1, numNetworks);
            XTrainCell = cell(1, numNetworks);
            YTrainCell = cell(1, numNetworks);
            XValCell = cell(1, numNetworks);
            YValCell = cell(1, numNetworks);

            netLabels = strings(1, numNetworks);


            for i = 1:numNetworks
                options = networks(i).options;
                netLabels(i) = options.label;

                [XTrain, YTrain, XVal, YVal] = app.prepareDataForTraining(app.preprocessedTrainingData, ...
                        options.windowSize, options.stepSize, options.ratioTrainVal, options.modelType, options.model);

                XTrainCell{i} = XTrain;
                YTrainCell{i} = YTrain;
                XValCell{i} = XVal;
                YValCell{i} = YVal;

                if iscell(YTrain)
                    numResponses = size(YTrain{1, 1}, 1);
                else
                    numResponses = 1;
                end

                layersCell{i} = app.getLayers(options, size(XTrain{1, 1}, 1), numResponses);
                trainOptions{i} = app.getOptionsForParallel(options, XVal, YVal, size(XTrain, 1), dataqueue, i);
            end
            
            handles = app.preparePlots(netLabels);
            afterEach(dataqueue, @(data) app.updatePlots(handles, data));

            trainingFuture(1:numel(layersCell)) = parallel.FevalFuture;
            
            for i=1:numel(layersCell)
                trainingFuture(i) = parfeval(@trainNetwork, 2, XTrainCell{i}, YTrainCell{i}, layersCell{i}, trainOptions{i});
            end

            [trainedNetworks, trainingInfo] = fetchOutputs(trainingFuture, 'UniformOutput', false);

            for i = 1:numel(networks)
                options = networks(i).options;
                [staticThreshold, probDist] = app.getStaticThreshold(XValCell{i}, YValCell{i}, trainedNetworks{i}, options);

                trainedNetwork.net = trainedNetworks{i};
                trainedNetwork.netOptions = trainingInfo{i};
                trainedNetwork.options = options;
                trainedNetwork.staticThreshold = staticThreshold;
                trainedNetwork.probDist = probDist;
                trainedNetwork.scores.dynamic.weighted.precision = 0;
                trainedNetwork.scores.dynamic.weighted.recall = 0;
                trainedNetwork.scores.dynamic.weighted.f1score = 0;
                trainedNetwork.scores.dynamic.weighted.f05score = 0;
                trainedNetwork.scores.dynamic.unweighted.precision = 0;
                trainedNetwork.scores.dynamic.unweighted.recall = 0;
                trainedNetwork.scores.dynamic.unweighted.f1score = 0;
                trainedNetwork.scores.dynamic.unweighted.f05score = 0;
                trainedNetwork.scores.static.weighted.precision = 0;
                trainedNetwork.scores.static.weighted.recall = 0;
                trainedNetwork.scores.static.weighted.f1score = 0;
                trainedNetwork.scores.static.weighted.f05score = 0;
                trainedNetwork.scores.static.unweighted.precision = 0;
                trainedNetwork.scores.static.unweighted.recall = 0;
                trainedNetwork.scores.static.unweighted.f1score = 0;
                trainedNetwork.scores.static.unweighted.f05score = 0;

                app.trainedNetworks.(options.id) = trainedNetwork;
            end
            
            delete(gcp('nocreate'));
        end                              

%% Anomaly detection functions
        
        function pred = reshapeReconstructivePrediction(~, data, windowSize)
            c = [];
            for i = 1:size(data, 1)
                c(:, i) = data{i};
            end
            b = [];
            c = flip(c);
            for i = 1:(size(c, 2) - windowSize)
                b(i, :) = median(diag(c(:, i:(i + windowSize - 1))));
            end
            pred = b;
        end
        
        function thr = computeThreshold(~, resAnom, anomLabels, param, pd)
            beta = 1;
            idx = [];
            if param == 1
                resAnom2=resAnom;
                resAnom = pdf(pd, resAnom);
            end
            threshMax = sort(resAnom);
            y = logical([]);
            if size(anomLabels, 2) ~= 1
                anomLabels = anomLabels';
            end
            length = max(size(threshMax));
            if param == 0
                for i = 1:length
                    y(:,i) = resAnom > threshMax(i);
                end
            else
                for i = 1:length
                    y(:,i) = resAnom < threshMax(i);
                end
            end
            if size(anomLabels) ~= size(y)
                anomLabels = anomLabels';
            end
            precision = []; recall = []; Fscore = [];
            for i = 1:length
                k = confusionmat(logical(anomLabels), logical(y(:,i)));
                if width(k) > 1
                    precision(i) = k(2,2)/(k(2,2)+k(1,2));
                    recall(i) = k(2,2)/(k(2,2)+k(2,1));
                    Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
                end
            end
            
            if isempty(Fscore) || ~any(Fscore)
                if param == 1
                    thr = mean(resAnom2) + 4 * std(resAnom2);
                else
                    thr = mean(resAnom) +  4 * std(resAnom);
                end
                return
            end

            MaxFScore = max(Fscore);
            thrIdx = find(Fscore == MaxFScore);
            clear thrMax
            if size(thrIdx, 2) >1
                p = thrIdx(1);
            else
                p = thrIdx;
            end
            
            thr = threshMax(p);
            if param == 1
                try
                    thr = resAnom2(find(resAnom==thr, 1));
                catch
                    thr = 0.1;
                end
            end
        end

        function [staticThreshold, probDist] = getStaticThreshold(app, XVal, YVal, net, options)
            [XTestVal, YTestVal, labelsTestVal] = app.prepareTestingData(app.testValData, ...
                        app.testValLabels, options.windowSize, options.modelType, options.model);

            pred = predict(net, XTestVal);
            
            if strcmp(options.modelType, 'Predictive')
                res = abs(pred - YTestVal);
            else
                pred = app.reshapeReconstructivePrediction(pred, options.windowSize);
                YTestVal = YTestVal(1:(end - options.windowSize), 1);
                labelsTestVal = labelsTestVal(1:(end - options.windowSize), 1);
                res = abs(pred - YTestVal);
            end

            if length(XVal) <= 1
                staticThreshold = app.computeThreshold(res, labelsTestVal, 0, 0);
                probDist = 0;
            else            
                staticThreshold = [];
                staticThreshold(1) = app.computeThreshold(res, labelsTestVal, 0, 0);
                valPred = predict(net, XVal);

                if strcmp(options.modelType, 'Predictive')
                    resVal = abs(valPred - YVal);
                else
                    YVal = cellfun(@(x) x(end), YVal, 'UniformOutput', false);
                    YVal = cell2mat(YVal);
                    YVal = YVal(1:(end - options.windowSize));
                    valPred = app.reshapeReconstructivePrediction(valPred, options.windowSize);

                    if length(valPred) > length(YVal)
                        valPred = valPred(1: length(YVal));
                    end
                    resVal = abs(valPred - YVal);
                end
                probDist = fitdist(resVal, "Normal");
                staticThreshold(2) = app.computeThreshold(res, labelsTestVal, 1, probDist);
            end
        end

        function index = getIndexes(~, data)
            try
                shift = [0; data(1:end-1)];
            catch
                shift = [0 data(1:end-1)];
            end
            change = shift ~= data;
            begin = find(data & change);
            endI = find(change & shift) -1;
            if length(endI) == length(begin) -1
                try
                    endI = [endI length(data)];
                catch
                    endI = [endI; length(data)];
                end
            end
            if size(begin, 1) ~= 1
                begin = begin';
            end
            if size(endI, 1) ~= 1
                endI = endI';
            end
            index = [begin; endI; endI; begin];
        end
             
        function anomaly = combineAnomsAndStatic(~, res, anoms)
            d = diff(res);
            anoms(find(d == 0)) = true;
            anomaly = anoms;
        end
        
        function [anoms, threshold] = calcDynamicThresholdPrediction(app, res, labels, padding, windowSize, min_percent, z_range)
            [anom_times, threshold] = find_anomalies(res, 'anomaly_padding', padding, ...
                'window_size', windowSize, 'min_percent', min_percent, 'z_range', z_range);

            anoms = false(length(labels), 1);
            for i = 1:size(anom_times, 1)
                begIdx = anom_times(i, 1);
                endIdx = anom_times(i, 2);
                if endIdx > length(anoms)
                    endIdx = length(anoms);
                end
                anoms(begIdx:endIdx, 1) = 1;
            end
            anoms = app.combineAnomsAndStatic(res, anoms);
        end

        function anoms = calcStaticThresholdPrediction(app, res, staticThresh)
            anoms = res > staticThresh;
            anoms = app.combineAnomsAndStatic(res, anoms);
        end

        function [scoresWeighted, scoresUnweighted] = calcScores(~, anoms, labels)
            try
                confmat = confusionmat(logical(labels),logical(anoms));
                tp = confmat(2,2);
                fp = confmat(1,2);
                fn = confmat(2,1);
                precision = tp/(tp+fp);
                recall = tp/(tp+fn);
                f1 = 2*precision*recall/(precision+recall);
                f05 = (1+0.5^2)*(precision*recall)/((0.5^2*precision)+recall);
                scoresWeighted = [precision; recall; f1; f05];
            catch ME
                scoresWeighted = [NaN; NaN; NaN; NaN];
            end

            [fp, fn, tp] = overlap_seg(labels, anoms);
            pre = tp/(tp+fp);
            rec = tp/(tp+fn);
            f1_score = 2*pre*rec/(pre+rec);
            f05 = (1+0.5^2)*(pre*rec)/((0.5^2*pre)+rec);                
            scoresUnweighted = [pre; rec; f1_score; f05];

            scoresWeighted = round(scoresWeighted, 4);
            scoresUnweighted = round(scoresUnweighted, 4);
        end
        
        function plotDynamicThresholdPrediction(app, res, anoms, labels, dataY, threshold, windowSize, scoresWeighted, scoresUnweighted)
            threshY = [];
            threshX = [];
            for i = 1:length(threshold)
                threshY(i,:) = ones(1, windowSize) * threshold(i);
                threshX(i,:) = ((i - 1) * windowSize + 1):(windowSize * i);
            end

            time = 1:length(res);
            indices = app.getIndexes(anoms);
            trueIndices = app.getIndexes(labels);
            
            plot(app.DE_DynamicThresholdingDataUIAxes, dataY)

            YLims = get(app.DE_DynamicThresholdingDataUIAxes, 'YLim');
            limit = [];
            trueLims = [];
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end

            patch(app.DE_DynamicThresholdingDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            try
                patch(app.DE_DynamicThresholdingDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                legend(app.DE_DynamicThresholdingDataUIAxes, 'data', "Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_DynamicThresholdingDataUIAxes, 'data', "Detected Anomalies")
            end
            
            plot(app.DE_DynamicThresholdingResidualUIAxes, time, res)
            hold(app.DE_DynamicThresholdingResidualUIAxes, "on")
            plot(app.DE_DynamicThresholdingResidualUIAxes, threshX', threshY', 'Color', 'red')
            hold(app.DE_DynamicThresholdingResidualUIAxes, "off")
            set(app.DE_DynamicThresholdingResidualUIAxes, 'XLim', [0 length(res)])


            app.DE_DynamicThresholdingUITable.Data = table(["Precision"; "Recall"; "F1 Score"; "F0.5 Score"], scoresWeighted, scoresUnweighted);
        end

        function plotStaticThresholdPrediction(app, res, anoms, labels, dataY, threshold, scoresWeighted, scoresUnweighted)
            plot(app.DE_StaticThresholdingDataUIAxes, dataY)

            time = 1:length(res);
            indices = app.getIndexes(anoms);
            trueIndices = app.getIndexes(labels);

            plot(app.DE_StaticThresholdingResidualUIAxes, time, res)
            yline(app.DE_StaticThresholdingResidualUIAxes, threshold, 'r')

            limit = [];
            YLims = get(app.DE_StaticThresholdingDataUIAxes, 'YLim');
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            
            if ~isempty(indices)
                patch(app.DE_StaticThresholdingDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
                try
                    patch(app.DE_StaticThresholdingDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                    legend(app.DE_StaticThresholdingDataUIAxes, 'data',"Detected Anomalies", "True Labels")
                catch ME
                    legend(app.DE_StaticThresholdingDataUIAxes, 'data', "Detected anomalies")
                end
            else
                try
                    patch(app.DE_StaticThresholdingDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                    legend(app.DE_StaticThresholdingDataUIAxes, 'data', "Detected Anomalies", "True Labels")
                catch ME
                    
                end
            end
            
            set(app.DE_StaticThresholdingResidualUIAxes, 'XLim', [0 length(res)])
            set(app.DE_StaticThresholdingDataUIAxes, 'XLim', [0 length(res)])

            app.DE_StaticThresholdingUITable.Data = table(["Precision"; "Recall"; "F1 Score"; "F0.5 Score"], scoresWeighted, scoresUnweighted);
        end
        
        function updateNetworkScores(app, scoresWeighted, scoresUnweighted, netName, type)            
            switch type
                case 'Dynamic'
                    app.trainedNetworks.(netName).scores.dynamic.weighted.precision = scoresWeighted(1);
                    app.trainedNetworks.(netName).scores.dynamic.weighted.recall = scoresWeighted(2);
                    app.trainedNetworks.(netName).scores.dynamic.weighted.f1score = scoresWeighted(3);
                    app.trainedNetworks.(netName).scores.dynamic.weighted.f05score = scoresWeighted(4);
                    app.trainedNetworks.(netName).scores.dynamic.unweighted.precision = scoresUnweighted(1);
                    app.trainedNetworks.(netName).scores.dynamic.unweighted.recall = scoresUnweighted(2);
                    app.trainedNetworks.(netName).scores.dynamic.unweighted.f1score = scoresUnweighted(3);
                    app.trainedNetworks.(netName).scores.dynamic.unweighted.f05score = scoresUnweighted(4);
                case 'Static'
                    app.trainedNetworks.(netName).scores.static.weighted.precision = scoresWeighted(1);
                    app.trainedNetworks.(netName).scores.static.weighted.recall = scoresWeighted(2);
                    app.trainedNetworks.(netName).scores.static.weighted.f1score = scoresWeighted(3);
                    app.trainedNetworks.(netName).scores.static.weighted.f05score = scoresWeighted(4);
                    app.trainedNetworks.(netName).scores.static.unweighted.precision = scoresUnweighted(1);
                    app.trainedNetworks.(netName).scores.static.unweighted.recall = scoresUnweighted(2);
                    app.trainedNetworks.(netName).scores.static.unweighted.f1score = scoresUnweighted(3);
                    app.trainedNetworks.(netName).scores.static.unweighted.f05score = scoresUnweighted(4);
            end
        end
        
        function updateDetectionScores(app, pred, dataY, labels, staticThreshold, netName, type, plotEnabled)
            res = abs(pred - dataY);

            if strcmp(type, 'All') || strcmp(type, 'Dynamic')
                padding = app.DE_AnomalyPaddingSlider.Value;
                windowSize = round(app.DE_WindowSizeSlider.Value);            
                z_range = 1:app.DE_ZRangeSlider.Value;
                min_percent = 1;
    
                [anomsDynamic, threshold] = app.calcDynamicThresholdPrediction(res, labels, padding, ...
                    windowSize, min_percent, z_range);
                [scoresWeightedDynamic, scoresUnweightedDynamic] = app.calcScores(anomsDynamic, labels);

                if plotEnabled
                    app.plotDynamicThresholdPrediction(res, anomsDynamic, labels, dataY, threshold, windowSize, ...
                        scoresWeightedDynamic, scoresUnweightedDynamic)
                end

                app.updateNetworkScores(scoresWeightedDynamic, scoresUnweightedDynamic, netName, 'Dynamic');
            end
            
            if strcmp(type, 'All') || strcmp(type, 'Static')
                if size(staticThreshold, 2) > 1 && app.DE_StaticThresholdingParametricCheckBox.Value
                    staticThresh = staticThreshold(2);
                else
                    staticThresh = staticThreshold(1);
                end
                anomsStatic = app.calcStaticThresholdPrediction(res, staticThresh);
                [scoresWeightedStatic, scoresUnweightedStatic] = app.calcScores(anomsStatic, labels);

                if plotEnabled
                    app.plotStaticThresholdPrediction(res, anomsStatic, labels, dataY, staticThresh, ...
                        scoresWeightedStatic, scoresUnweightedStatic)
                end

                app.updateNetworkScores(scoresWeightedStatic, scoresUnweightedStatic, netName, 'Static');
            end
        end
        
        function runDetectionFor(app, nets)
            if isempty(nets)
                f = errordlg("No Trained Networks (selected).", ...
                                'Detection Error');
                return
            end
            for i = 1:(numel(nets) - 1)
                netName = nets(i);

                options = app.trainedNetworks.(netName).options;

                selectedItem = app.DE_FaultyDataSelectionDropDown.Value;
                [~, idxData] = ismember(selectedItem, app.DE_FaultyDataSelectionDropDown.Items);
                
                testingData = app.preprocessedTestingData(idxData, 1);
                testingLabels = app.labelsTestingData(idxData, 1);
    
                [XTest, YTest, labels] = app.prepareTestingData(testingData, testingLabels, ...
                    options.windowSize, options.modelType, options.model);
                
                pred = predict(app.trainedNetworks.(netName).net, XTest);
                
                if strcmp(options.modelType, 'Reconstructive')
                    pred = app.reshapeReconstructivePrediction(pred, options.windowSize);
                    YTest = YTest(1:(end - options.windowSize), 1);
                    labels = labels(1:(end - options.windowSize), 1);
                end
                
                app.DE_AnomalyPaddingSlider.Limits = [1 50];
                app.DE_AnomalyPaddingSlider.Value = 3;
                app.DE_WindowSizeSlider.Limits = [1 floor(length(YTest) / 2)];
                app.DE_WindowSizeSlider.Value = 300;
    
                app.updateDetectionScores(pred, YTest, labels, ...
                    app.trainedNetworks.(netName).staticThreshold, netName, 'All', false)
            end


            netName = nets(end);

            options = app.trainedNetworks.(netName).options;

            selectedItem = app.DE_FaultyDataSelectionDropDown.Value;
            [~, idxData] = ismember(selectedItem, app.DE_FaultyDataSelectionDropDown.Items);
            
            testingData = app.preprocessedTestingData(idxData, 1);
            testingLabels = app.labelsTestingData(idxData, 1);

            [XTest, YTest, labels] = app.prepareTestingData(testingData, testingLabels, ...
                options.windowSize, options.modelType, options.model);
            
            pred = predict(app.trainedNetworks.(netName).net, XTest);
            
            if strcmp(options.modelType, 'Reconstructive')
                pred = app.reshapeReconstructivePrediction(pred, options.windowSize);
                YTest = YTest(1:(end - options.windowSize), 1);
                labels = labels(1:(end - options.windowSize), 1);
            end
            
            app.DE_AnomalyPaddingSlider.Limits = [1 50];
            app.DE_AnomalyPaddingSlider.Value = 3;
            app.DE_WindowSizeSlider.Limits = [1 floor(length(YTest) / 2)];
            app.DE_WindowSizeSlider.Value = 300;

            app.updateDetectionScores(pred, YTest, labels, ...
                app.trainedNetworks.(netName).staticThreshold, netName, 'All', true)

            app.currentPred = pred;
            app.currentYTest = YTest;
            app.currentLabels = labels;
            app.currentNetName = netName;

            currenNetLabel = app.trainedNetworks.(netName).options.label;
            app.DE_DetectionShownForLabel.Text = sprintf('Detection shown for: %s', currenNetLabel);
        end
        

        function showTrainedNetworks(app, scoreType)
            app.DE_TrainedNetworksListBox.Items = {};
            app.DE_TrainedNetworksListBox.ItemsData = [];
            
            fields = fieldnames(app.trainedNetworks);
            for i = 1:numel(fields)
                label = app.trainedNetworks.(fields{i}).options.label;

                switch scoreType
                    case 'precision weighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.weighted.precision;
                        scoreName = 'pre (w, st)';
                    case 'recall weighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.weighted.recall;
                        scoreName = 'rec (w, st)';
                    case 'f1score weighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.weighted.f1score;
                        scoreName = 'f1 (w, st)';
                    case 'f05score weighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.weighted.f05score;
                        scoreName = 'f0.5 (w, st)';
                    case 'precision unweighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.unweighted.precision;
                        scoreName = 'pre (uw, st)';
                    case 'recall unweighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.unweighted.recall;
                        scoreName = 'rec (uw, st)';
                    case 'f1score unweighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.unweighted.f1score;
                        scoreName = 'f1 (uw, st)';
                    case 'f05score unweighted static'
                        score = app.trainedNetworks.(fields{i}).scores.static.unweighted.f05score;
                        scoreName = 'f0.5 (uw, st)';
                    case 'precision weighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.precision;
                        scoreName = 'pre (w, dy)';
                    case 'recall weighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.recall;
                        scoreName = 'rec (w, dy)';
                    case 'f1score weighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.f1score;
                        scoreName = 'f1 (w, dy)';
                    case 'f05score weighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.f05score;
                        scoreName = 'f0.5 (w, dy)';
                    case 'precision unweighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.precision;
                        scoreName = 'pre (uw, dy)';
                    case 'recall unweighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.recall;
                        scoreName = 'rec (uw, dy)';
                    case 'f1score unweighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.f1score;
                        scoreName = 'f1 (uw, dy)';
                    case 'f05score unweighted dynamic'
                        score = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.f05score;
                        scoreName = 'f0.5 (uw, dy)';
                end

                fullLabel = sprintf('%s    %s: %f', ...
                    label, scoreName, score);

                app.DE_TrainedNetworksListBox.Items = [app.DE_TrainedNetworksListBox.Items ...
                    fullLabel];
                app.DE_TrainedNetworksListBox.ItemsData = [app.DE_TrainedNetworksListBox.ItemsData ...
                    string(fields{i})];
            end
        end
        
        function sortTrainedNetworks(app, sortby)
            sortbySplit = split(sortby, ' ');
            thrType = sortbySplit{3};
            wghType = sortbySplit{2};
            scoType = sortbySplit{1};

            numOfNets = length(app.DE_TrainedNetworksListBox.ItemsData);
            values = zeros(1, numOfNets);
            for i = 1:numOfNets
                fieldName = app.DE_TrainedNetworksListBox.ItemsData(i);
                values(i) = app.trainedNetworks.(fieldName).scores.(thrType).(wghType).(scoType);
            end

            [~, idxs] = sort(values, 'descend');

            app.DE_TrainedNetworksListBox.Items = app.DE_TrainedNetworksListBox.Items(idxs);
            app.DE_TrainedNetworksListBox.ItemsData = app.DE_TrainedNetworksListBox.ItemsData(idxs);
        end
        
        function showScoresInTable(app)
            finalTable = table([
                "Precision weighted static"; ...
                "Precision weighted dynamic"; ...
                "Precision unweighted static"; ...
                "Precision unweighted dynamic"; ...
                "Recall weighted static"; ...
                "Recall weighted dynamic"; ...
                "Recall unweighted static"; ...
                "Recall unweighted dynamic"; ...
                "F1 Score weighted static"; ...
                "F1 Score weighted dynamic"; ...
                "F1 Score unweighted static"; ...
                "F1 Score unweighted dynamic"; ...
                "F0.5 Score weighted static"; ...
                "F0.5 Score weighted dynamic"; ...
                "F0.5 Score unweighted static"; ...
                "F0.5 Score unweighted dynamic"; ...
                ]);

            fields = fieldnames(app.trainedNetworks);
            scoreMatrix = zeros(16, numel(fields));
            variableNames = strings(1, numel(fields) + 1);
            variableNames(1) = "Metric";

            for i = 1:numel(fields)
                scoreMatrix(1, i) = app.trainedNetworks.(fields{i}).scores.static.weighted.precision;
                scoreMatrix(2, i) = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.precision;
                scoreMatrix(3, i) = app.trainedNetworks.(fields{i}).scores.static.unweighted.precision;
                scoreMatrix(4, i) = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.precision;
                scoreMatrix(5, i) = app.trainedNetworks.(fields{i}).scores.static.weighted.recall;
                scoreMatrix(6, i) = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.recall;
                scoreMatrix(7, i) = app.trainedNetworks.(fields{i}).scores.static.unweighted.recall;
                scoreMatrix(8, i) = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.recall;
                scoreMatrix(9, i) = app.trainedNetworks.(fields{i}).scores.static.weighted.f1score;
                scoreMatrix(10, i) = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.f1score;
                scoreMatrix(11, i) = app.trainedNetworks.(fields{i}).scores.static.unweighted.f1score;
                scoreMatrix(12, i) = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.f1score;
                scoreMatrix(13, i) = app.trainedNetworks.(fields{i}).scores.static.weighted.f05score;
                scoreMatrix(14, i) = app.trainedNetworks.(fields{i}).scores.dynamic.weighted.f05score;
                scoreMatrix(15, i) = app.trainedNetworks.(fields{i}).scores.static.unweighted.f05score;
                scoreMatrix(16, i) = app.trainedNetworks.(fields{i}).scores.dynamic.unweighted.f05score;
            
                label = app.trainedNetworks.(fields{i}).options.label;
                variableNames(i + 1) = string(label);
            end

            scoreTable = array2table(scoreMatrix);
            
            finalTable = [finalTable scoreTable];

            style = uistyle;
            style.FontWeight = 'bold';
            
            rows = [];
            columns = [];

            for i = 1:16
                [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                
                for j = 1:numel(col)
                    rows(end + 1, 1) = i;
                    columns(end + 1, 1) = col(j);
                end
            end

            app.DE_FullDetectionUITable.Data = finalTable;
            app.DE_FullDetectionUITable.ColumnName = variableNames;

            addStyle(app.DE_FullDetectionUITable, style, 'cell', [rows, columns + 1]);      
        end

    end

    methods (Access = public)
        
        function addNetwork(app, networkOptions)
            [label, id] = app.getLabelForNetwork(networkOptions.model);
            networkOptions.id = id;
            networkOptions.label = label;
            netInfo.options = networkOptions;
            app.MT_NetworksListBox.Items = [app.MT_NetworksListBox.Items label];
            app.MT_NetworksListBox.ItemsData = [app.MT_NetworksListBox.ItemsData netInfo];

            app.toggleTrainButtons(true)
        end

        function addNetworks(~, networks)
            for i = 0:length(networks)
                app.addNetwork(networks(i));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.MT_PreviewUIAxes, 'off') % Turn off axes for network preview
            
            app.UIFigure.Visible = 'off';

            app.DE_FullDetectionUITable.Data = table([
                "Precision weighted static"; ...
                "Precision weighted dynamic"; ...
                "Precision unweighted static"; ...
                "Precision unweighted dynamic"; ...
                "Recall weighted static"; ...
                "Recall weighted dynamic"; ...
                "Recall unweighted static"; ...
                "Recall unweighted dynamic"; ...
                "F1 Score weighted static"; ...
                "F1 Score weighted dynamic"; ...
                "F1 Score unweighted static"; ...
                "F1 Score unweighted dynamic"; ...
                "F0.5 Score weighted static"; ...
                "F0.5 Score weighted dynamic"; ...
                "F0.5 Score unweighted static"; ...
                "F0.5 Score unweighted dynamic"; ...
                ]);
            
            movegui(app.UIFigure,"center")
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            oldP = beginTask(app, app.DataPreparationPanel);
            switch app.NetworkButtonGroup.SelectedObject
                case app.TrainnewnetworkButton
                    switch app.SelectDatasetButtonGroup.SelectedObject
                        case app.SWaTButton
                            app.faultFreeData = app.SWaT.SWaTDatasetNormalv0;
                            app.UITable.Data = app.faultFreeData;
                            app.UITable.ColumnName = app.faultFreeData.Properties.VariableNames;
                            s = uistyle('BackgroundColor','blue');
                            addStyle(app.UITable,s,'column',[3 20 29 30])
                            openAndResizeWindow(app, app.DataPreparationPanel, app.SWaTDataPanel);
                        case app.AVSButton
                            openAndResizeWindow(app, app.DataPreparationPanel, app.AVSPreparationPanel);
                        case app.UAVButton
                            openAndResizeWindow(app, app.DataPreparationPanel, app.UAVPreparationPanel);
                            % exportapp(app.UIFigure, 'C:\Users\Alia\Desktop\report\Figures\APP\UAV.pdf')
                    end
                case app.PretrainedButton
                    switch app.SelectDatasetButtonGroup.SelectedObject
                        case app.SWaTButton
                            
                        case app.AVSButton
                            
                        case app.UAVButton
                            
                    end
            end
            endTask(app, app.DataPreparationPanel, oldP);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            resetApp(app);
        end

        % Callback function
        function VisualizedataButtonPushed(app, event)
            oldP = beginTask(app, app.PreprocessingPanel_Old);
            if app.SelectDatasetButtonGroup.SelectedObject == app.SWaTButton
                plot(datetime(app.faultFreeData{7*60*60+60*30:end,1}),app.faultFreeData{7*60*60+60*30:end,3})
            end
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global indices
            try
                removeStyle(app.UITable,2)
            catch ME
                
            end
            indices = event.Indices;
            idx = indices(1,2);
            if ismember(idx, [3 20 29 30])
                app.SelectedSensorCheckBox.Value = 1;
                app.VisualizedataButton_2.Enable = 1;
                app.NextButton_3.Enable = 1;
                app.SelectedSensorLampLabel.Text = app.UITable.ColumnName(idx);
                app.SelectedSensorLamp.Color = 'green';
                app.SelectedSensorCheckBox.Text = app.UITable.ColumnName(idx);
                s = uistyle('BackgroundColor','green');
                addStyle(app.UITable,s,'column', idx)
            else
                app.SelectedSensorCheckBox.Value = 0;
                app.VisualizedataButton_2.Enable = 0;
                app.NextButton_3.Enable = 0;
                app.SelectedSensorLampLabel.Text = app.UITable.ColumnName(idx);
                app.SelectedSensorLamp.Color = 'red';
                app.SelectedSensorCheckBox.Text = app.UITable.ColumnName(idx);
                s = uistyle('BackgroundColor','red');
                addStyle(app.UITable,s,'column', idx)
            end
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            resetApp(app);
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            global indices
            oldP = beginTask(app, app.SWaTDataPanel);
            beg = 7*60*60+60*30;
            endI = beg + 60*60*18;
            app.faultFreeData = app.UITable.Data{beg:endI,indices(1,2)};
            openAndResizeWindow(app, app.SWaTDataPanel, app.PreprocessingPanel_Old)
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Button pushed function: VisualizedataButton_2
        function VisualizedataButton_2Pushed(app, event)
            global indices
            oldP = beginTask(app, app.SWaTDataPanel);
            if app.SelectedSensorCheckBox.Value == 1
                fig = uifigure;
                name = get(app.SelectedSensorCheckBox, 'Text');
                name = "Sensor: "+char(name);
                set(fig, 'Name', name)
                ax = uiaxes(fig,'Position',[10 10 550 400]);
                x = datetime(app.UITable.Data{7*60*60+60*30:end,1});
                y = app.UITable.Data{7*60*60+60*30:end,indices(1,2)};
                plot(ax,x,y)
                lim1 = datetime(app.UITable.Data{7*60*60+60*30,1});
                lim2 = datetime(app.UITable.Data{end,1});
                xlim(ax, [lim1 lim2])
            end
            endTask(app, app.SWaTDataPanel, oldP);
        end

        % Value changed function: NetworkspecificityDropDown
        function NetworkspecificityDropDownValueChanged(app, event)
            value = app.NetworkspecificityDropDown.Value;
            if strcmp(value, 'Basic')
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 0;
                app.HiddenUnitsEditFieldLabel.Visible = 0;
                app.NumberofneuronsEditField.Visible = 1;
                app.NumberofneuronsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Value = 32;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            elseif strcmp(value, 'LSTM')
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 80;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            elseif strcmp(value, 'Hybrid (RNN-CNN)')
                app.FilterNumberEditField.Visible = 1;
                app.FilterNumberEditFieldLabel.Visible = 1;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 50;
                app.FilterNumberEditField.Value = 32;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            else
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 80;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            oldP = beginTask(app, app.PreprocessingPanel_Old);
            preprocData(app)
            switch app.NetworkButtonGroup_2.SelectedObject
                case app.ReconstructionButton
                    openAndResizeWindow(app, app.PreprocessingPanel_Old, app.NetworkOptionsPanel)
                case app.PredictiveButton
                    app.NetworkspecificityDropDown.Items{1} = 'GRU';
                    app.FilterNumberEditField.Visible = 0;
                    app.FilterNumberEditFieldLabel.Visible = 0;
                    app.HiddenUnitsEditField.Visible = 1;
                    app.HiddenUnitsEditFieldLabel.Visible = 1;
                    app.NumberofneuronsEditField.Visible = 0;
                    app.NumberofneuronsEditFieldLabel.Visible = 0;
                    app.HiddenUnitsEditField.Value = 80;
                    app.EpochsEditField.Value = 35;
                    app.MinibatchsizeEditField.Value = 70;
                    openAndResizeWindow(app, app.PreprocessingPanel_Old, app.NetworkOptionsPanel)
            end
            drawnow
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Button pushed function: RestartButton_2
        function RestartButton_2Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NetworkdesciptionButton
        function NetworkdesciptionButtonPushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            delete(app.NetworkDescApp)
            lay = createGraph(app);
            app.netDescription = generateDesc(app);
            app.NetworkDescApp = netDescApp(app, lay, app.netDescription);
            endTask(app, app.NetworkOptionsPanel, oldP);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            %train on non anomalous data
            [app.dataXTrain,app.dataYTrain] = splitDatRnd(app);            
            splitTrainVal(app)
            switch app.NetworkButtonGroup_2.SelectedObject
                case app.ReconstructionButton
                    switch app.NetworkspecificityDropDown.Value
                        case app.NetworkspecificityDropDown.Items{2} %LSTM
                            trainLSTM_R(app);
                        case app.NetworkspecificityDropDown.Items{1} %Shallow
                            trainShallow_R(app);
                        case app.NetworkspecificityDropDown.Items{3} %Hybrid
                            trainHy_R(app);
                    end
                case app.PredictiveButton
                    switch app.NetworkspecificityDropDown.Value
                        case app.NetworkspecificityDropDown.Items{1} %GRU
                            trainLSTM(app);
                        case app.NetworkspecificityDropDown.Items{2} %LSTM
                            trainRNN(app);
                        case app.NetworkspecificityDropDown.Items{3} %Hybrid
                            trainHy(app)
                    end
            end
            if ~isempty(app.net)
                app.NextButton_4.Enable = 1;
            end
            assignin("base","net" ,app.net)
            %preproc ans split val anom data
            switch app.SelectDatasetButtonGroup.SelectedObject
                case app.AVSButton
                    if size(app.anomValData, 1) == 1
                        switch app.PreprocessingmethodButtonGroup.SelectedObject
                            case app.RawdataButton
                                
                            case app.Rescale01Button
                                app.anomValData{1,1} = (app.anomValData{1,1}-app.mini)/(app.maxi-app.mini);
                            case app.StandardizeButton
                                app.anomValData{1,1} = (app.anomValData{1,1}-app.mu)/app.sig;
                        end
                        [XValAnom, YValAnom] = splitData(app, app.anomValData{1,1});
                        [~, anomLabels] = splitData(app, app.anomValData{1,2});
                        anomLabels = logical(anomLabels);
                    else
                        for i = 1:size(app.anomValData,1)
                            switch app.PreprocessingmethodButtonGroup.SelectedObject
                                case app.RawdataButton
                                    app.anomValData{i,1} = app.anomValData{i,1};
                                case app.Rescale01Button
                                    app.anomValData{i,1} = (app.anomValData{i,1}-app.mini)/(app.maxi-app.mini);
                                case app.StandardizeButton
                                    app.anomValData{i,1} = (app.anomValData{i,1}-app.mu)/app.sig;
                            end
                        end
                        XValAnom = cell(0,1);
                        YValAnom = [];
                        anomLabels = [];
                        for i = 1:size(app.anomValData,1)
                            [A, B] = splitData(app, app.anomValData{i,1});
                            [~, C] = splitData(app, app.anomValData{i,2});
                            XValAnom = [XValAnom; A];
                            YValAnom = [YValAnom; B];
                            anomLabels = [anomLabels; C];
                        end
                        anomLabels = logical(anomLabels);
                    end
                    anomPred = predict(app.net, XValAnom);
                    if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                        resAnom = abs(anomPred-YValAnom);
                    else
                        anomPred = reshapeAutoencPred(app, anomPred);
                        YValAnom = YValAnom(1:end-app.WindowsizewEditField.Value);
                        anomLabels = anomLabels(1:end-app.WindowsizewEditField.Value);
                        resAnom = abs(anomPred-YValAnom);
                    end
                    app.staticThreshold = findStaticThresh(app, resAnom, anomLabels);
                case app.UAVButton
                    fn = fieldnames(app.anomValData);
                    for k = 1:numel(fn)
                        if size(app.anomValData.(fn{k}), 1) == 1
                            switch app.PreprocessingmethodButtonGroup.SelectedObject
                                case app.RawdataButton
                                    
                                case app.Rescale01Button
                                    app.anomValData.(fn{k}){1,1} = (app.anomValData.(fn{k}){1,1}-app.mini)/(app.maxi-app.mini);
                                case app.StandardizeButton
                                    app.anomValData.(fn{k}){1,1} = (app.anomValData.(fn{k}){1,1}-app.mu)/app.sig;
                            end
                            [XValAnom, YValAnom] = splitData(app, app.anomValData.(fn{k}){1,1});
                            [~, anomLabels] = splitData(app, app.anomValData.(fn{k}){1,2});
                            anomLabels = logical(anomLabels);
                        else
                            for i = 1:size(app.anomValData,1)
                                switch app.PreprocessingmethodButtonGroup.SelectedObject
                                    case app.RawdataButton
                                        app.anomValData.(fn{k}){i,1} = app.anomValData.(fn{k}){i,1};
                                    case app.Rescale01Button
                                        app.anomValData.(fn{k}){i,1} = (app.anomValData.(fn{k}){i,1}-app.mini)/(app.maxi-app.mini);
                                    case app.StandardizeButton
                                        app.anomValData.(fn{k}){i,1} = (app.anomValData.(fn{k}){i,1}-app.mu)/app.sig;
                                end
                            end
                            XValAnom = cell(0,1);
                            YValAnom = [];
                            anoms = [];
                            for i = 1:size(app.anomValData,1)
                                [A, B] = splitData(app, app.anomValData.(fn{k}){i,1});
                                [~, C] = splitData(app, app.anomValData.(fn{k}){i,2});
                                XValAnom = [XValAnom; A];
                                YValAnom = [YValAnom; B];
                                anoms = [anoms; C];
                            end
                            anomLabels.(fn{k}) = logical(anoms);
                        end
                        assignin("base", "Xanom", XValAnom)
                        assignin("base", "Yanom", YValAnom)
                        anomPred = predict(app.net, XValAnom);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-YValAnom);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            YValAnom = YValAnom(1:end-app.WindowsizewEditField.Value);
                            anomLabels.(fn{k}) = anomLabels.(fn{k})(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-YValAnom);
                        end
                        app.staticThreshold.(fn{k}) = findStaticThresh(app, resAnom, anomLabels.(fn{k}));
                    end
                case app.SWaTButton
                    if app.RatioEditField.Enable == 1
                        anomPred = predict(app.net, app.XVal);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-app.YVal);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            app.YVal = app.YVal(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-app.YVal);
                        end
                        app.staticThreshold = mean(resAnom)+4*std(resAnom);
                    else
                        anomPred = predict(app.net, app.XTrain);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-app.YTrain);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            app.YTrain = app.YTrain(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-app.YTrain);
                        end
                        app.staticThreshold = mean(resAnom)+4*std(resAnom);
                    end
                    
            end
            assignin("base", "staticThresh", app.staticThreshold)
            %prepare non anomalous training data
            % %             app.trainPredict = predict(app.net, app.XTrain);
            % %             if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
            % %                 res = abs(app.trainPredict-app.YTrain);
            % %                 labels = false(1,length(res));
            % %             else
            % %                 app.trainPredict = reshapeAutoencPred(app, app.trainPredict);
            % %                 YTrain = app.YTrain(1:end-app.WindowsizewEditField.Value);
            % %                 labels = false(1,length(YTrain));
            % %                 res = abs(app.trainPredict-YTrain);
            % %             end
            
            %find best threshold
            % %             app.staticThreshold = findStaticThresh(app, resAnom, anomLabels);
            % %             beta = 1;
            % %             idx = [];
            % %             threshMax = linspace(min(resAnom),max(resAnom), 200);
            % %             y = logical([]);
            % %             if size(anomLabels, 2) ~= 1
            % %                 anomLabels = anomLabels';
            % %             end
            % %             for i = 1:200
            % %                 y(:,i) = resAnom > threshMax(i);
            % %             end
            % %
            % %             precision = []; recall = []; Fscore = [];
            % %
            % %             for i = 1:200
            % %                 k = confusionmat(anomLabels, y(:,i));
            % %                 if width(k) > 1
            % %                     precision(i) = k(2,2)/(k(2,2)+k(1,2));
            % %                     recall(i) = k(2,2)/(k(2,2)+k(2,1));
            % %                     Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
            % %                 end
            % %             end
            % %             MaxFScore = max(Fscore);
            % %             thrIdx = find(Fscore == MaxFScore);
            % %             clear thrMax
            % %             if size(thrIdx, 2) >1
            % %                 p = thrIdx(1);
            % %                 thrMax = threshMax(thrIdx(end));
            % %             else
            % %                 p = thrIdx;
            % %             end
            % %
            % %             app.staticThreshold = threshMax(p);
            
            endTask(app, app.NetworkOptionsPanel, oldP)
        end

        % Button pushed function: SavenettoworkspaceButton
        function SavenettoworkspaceButtonPushed(app, event)
            if ~isempty(app.net)
                app.NetworkOptionsPanel.Enable = 'off';
                app.saveNetPopupWin = saveNetPopup(app);
                waitfor(app.saveNetPopupWin)
                app.NetworkOptionsPanel.Enable = 'on';
            else
                uialert(app.UIFigure,'There is still no trained network to save','Invalid File');
            end
        end

        % Value changed function: UsevaldataCheckBox
        function UsevaldataCheckBoxValueChanged(app, event)
            value = app.UsevaldataCheckBox.Value;
            if value == 1
                app.RatioEditField.Enable = 1;
                app.RatioEditField_2Label.Enable = 1;
            else
                app.RatioEditField.Enable = 0;
                app.RatioEditField_2Label.Enable = 0;
            end
        end

        % Button pushed function: VisualizedataButton_3
        function VisualizedataButton_3Pushed(app, event)
            oldP = beginTask(app, app.AVSPreparationPanel);
            appTitle = "AVS Data";
            switch app.VehiculescenarioButtonGroup.SelectedObject
                case app.TownButton
                    name = "Town scenario data";
                    y = app.AVS.faultFree(13:end, 1);
                    plotData(app, y, name, appTitle)
                case app.HighwayButton
                    name = "Town scenario data";
                    y = app.AVS.faultFree(1:12, 1);
                    plotData(app, y, name, appTitle)
            end
            endTask(app, app.AVSPreparationPanel, oldP);
        end

        % Button pushed function: RestartButton_3
        function RestartButton_3Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
            oldP = beginTask(app, app.AVSPreparationPanel);
            switch app.VehiculescenarioButtonGroup.SelectedObject
                case app.TownButton
                    app.faultFreeData = app.AVS.faultFree(13:end,1);
                    app.AVSType = 13:24;
                    
                case app.HighwayButton
                    app.faultFreeData = app.AVS.faultFree(1:12, 1);
                    app.AVSType = 1:12;
            end
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            %             data = [app.AVS.dataNoise(faultIdx,:); app.AVS.dataOffset(faultIdx,:)];
            %             [m, n] = size(data);
            %             idx = randperm(m);
            %             app.anomValData = data(idx(1:round(0.2*m)),:);
            %%
            data1 = [app.AVS.dataNoise(1:8:end,:); app.AVS.dataNoise(2:8:end,:); app.AVS.dataNoise(3:8:end,:); app.AVS.dataNoise(4:8:end,:)];
            data2 = [app.AVS.dataOffset(faultIdx,:)];
            [m, n] = size(data2);
            idx = randperm(m);
            data2 = data2(idx,:);
            data = [data1;data2];
            [m, n] = size(data);
            idx = randperm(m);
            %%
            app.anomValData = data(idx(1:round(0.2*m)),:);
            openAndResizeWindow(app, app.AVSPreparationPanel, app.PreprocessingPanel_Old)
            endTask(app, app.AVSPreparationPanel, oldP);
            % exportapp(app.UIFigure, 'C:\Users\Alia\Desktop\report\Figures\APP\preproc.png')
        end

        % Button pushed function: RestartButton_4
        function RestartButton_4Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
            oldP = beginTask(app, app.UAVPreparationPanel);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    app.faultFreeData = app.UAVAcc.faultFreeAcc;
                    for i = 1:size(app.faultFreeData,1)
                        app.faultFreeData{i,1} = downsample(app.faultFreeData{i,1},16);
                    end
                case app.GyroscopeButton
                    app.faultFreeData = app.UAVGyr.faultFreeGyr;
            end
            openAndResizeWindow(app, app.UAVPreparationPanel, app.PreprocessingPanel_Old)
            app.UAVGyrFault = app.UAVGyrFault.faultyGyr;
            app.UAVAccFault = app.UAVAccFault.faultyAcc;
            if app.SensordataselectionButtonGroup.SelectedObject == app.AccelerometerButton
                downsampleData(app)
                app.anomValData.Offset = randomAnomData(app, app.UAVAccFault.Offset, 0.2);
                app.anomValData.PackageDrop = randomAnomData(app, app.UAVAccFault.PackageDrop, 0.2);
                app.anomValData.StuckAt = randomAnomData(app, app.UAVAccFault.StuckAt, 0.2);
                %                 app.anomValData.Offset = app.UAVAccFault.Offset;
                %                 app.anomValData.PackageDrop = app.UAVAccFault.PackageDrop;
                %                 app.anomValData.StuckAt = app.UAVAccFault.StuckAt;
            else
                app.anomValData.Offset = randomAnomData(app, app.UAVGyrFault.Offset, 0.2);
                app.anomValData.Noise = randomAnomData(app, app.UAVGyrFault.Noise, 0.2);
                app.anomValData.StuckAt = randomAnomData(app, app.UAVGyrFault.StuckAt, 0.2);
            end
            endTask(app, app.UAVPreparationPanel, oldP);
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            switch app.SelectDatasetButtonGroup.SelectedObject
                case app.AVSButton
                    openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_AVS)
                case app.UAVButton
                    switch app.SensordataselectionButtonGroup.SelectedObject
                        case app.AccelerometerButton
                            openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_UAVAcc)
                            app.ScenarioListBox_2.Items = app.ScenarioListBox_2.Items(1:10);
                            app.ScenarioListBox_2.ItemsData = app.ScenarioListBox_2.ItemsData(1:10);
                        case app.GyroscopeButton
                            openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_UAVAcc)
                            app.FaulttypeButtonGroup_4.Visible = 'on';
                            app.FaulttypeButtonGroup_2.Visible = 'off';
                    end
                case app.SWaTButton
                    openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_SWaT);
            end
            
            endTask(app, app.NetworkOptionsPanel, oldP);
        end

        % Button pushed function: VisualizedataButton_5
        function VisualizedataButton_5Pushed(app, event)
            %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            dataCleanIdx = str2double(app.ScenarioListBox.Value);
            switch app.FaulttypeButtonGroup.SelectedObject
                case app.StuckvalueButton
                    faultType = app.AVS.dataStuckAt(faultIdx,:);
                case app.NoiseButton
                    faultType = app.AVS.dataNoise(faultIdx,:);
                case app.OffsetButton
                    faultType = app.AVS.dataOffset(faultIdx,:);
            end
            IDX = str2double(app.FaultdurationDropDown.Value);
            dataIdx = 8*(dataCleanIdx-1)+IDX;
            faultData = faultType{dataIdx,1};
            f = uifigure;
            f.Visible = 'off';
            ax = uiaxes(f);
            f.Position(3) = ax.Position(3)+10;
            f.Position(4) = ax.Position(4)+10;
            labels = logical(faultType{dataIdx,2});
            t = 1:length(labels);
            plot(ax, t, faultData)
            patchAnomalies(app, ax, labels)
            legend(ax, 'data', "Anomalies", "Location","bestoutside")
            movegui(f, "center")
            f.Visible = 'on';
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: VisualizedataButton_4
        function VisualizedataButton_4Pushed(app, event)
            oldP = beginTask(app, app.UAVPreparationPanel);
            appTitle = "UAV Data";
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.GyroscopeButton
                    name = "Gyroscope data";
                    y = app.UAVGyr.faultFreeGyr;
                    plotData(app, y, name, appTitle);
                case app.AccelerometerButton
                    name = "Accuator data";
                    y = app.UAVAcc.faultFreeAcc;
                    plotData(app, y, name, appTitle);
            end
            endTask(app, app.UAVPreparationPanel, oldP);
        end

        % Button pushed function: PredictdataButton
        function PredictdataButtonPushed(app, event)
            %oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            dataCleanIdx = str2double(app.ScenarioListBox.Value);
            switch app.FaulttypeButtonGroup.SelectedObject
                case app.StuckvalueButton
                    faultType = app.AVS.dataStuckAt(faultIdx,:);
                case app.NoiseButton
                    faultType = app.AVS.dataNoise(faultIdx,:);
                case app.OffsetButton
                    faultType = app.AVS.dataOffset(faultIdx,:);
            end
            IDX = str2double(app.FaultdurationDropDown.Value);
            dataIdx = 8*(dataCleanIdx-1)+IDX;
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            if isempty(app.pd)
                prodDensity = 0;
            else
                prodDensity = app.pd;
            end
            
            plotResultsAndEval(app, pred(1:end-1), YTest(1:end-1), labels(1:end-1), 100, app.staticThreshold, prodDensity);
            %endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: PredictdataButton_2
        function PredictdataButton_2Pushed(app, event)
            %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            threshold = app.staticThreshold.Noise;
%                             threshold = app.staticThreshold.Offset;
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
            end
            
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            
            if isempty(app.pd)
                prodDensity = 0;
            else
                prodDensity = app.pd;
            end
            if isstruct(threshold)
                threshold = struct2array(threshold);
            end
            
            plotResultsAndEval(app, pred(1:end-1), YTest(1:end-1), labels(1:end-1), 250, threshold, prodDensity);
            
            
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: OnlinedetectionsimulationButton
        function OnlinedetectionsimulationButtonPushed(app, event)
            
                %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
                faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
                dataCleanIdx = str2double(app.ScenarioListBox.Value);
                switch app.FaulttypeButtonGroup.SelectedObject
                    case app.StuckvalueButton
                        faultType = app.AVS.dataStuckAt(faultIdx,:);
                    case app.NoiseButton
                        faultType = app.AVS.dataNoise(faultIdx,:);
                    case app.OffsetButton
                        faultType = app.AVS.dataOffset(faultIdx,:);
                end
                IDX = str2double(app.FaultdurationDropDown.Value);
                dataIdx = 8*(dataCleanIdx-1)+IDX;
                faultData = faultType{dataIdx,1};
                switch app.PreprocessingmethodButtonGroup.SelectedObject
                    case app.RawdataButton
                        dataTest = faultData;
                    case app.Rescale01Button
                        dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                    case app.StandardizeButton
                        dataTest = (faultData-app.mu)/app.sig;
                end
                labels = logical(faultType{dataIdx, 2});
                if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                    method = 'Predictive';
                else
                    method = 'Reconstruction';
                end
                simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold);
            
            
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Selection changed function: FaulttypeButtonGroup_4
        function FaulttypeButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.FaulttypeButtonGroup_4.SelectedObject;
        end

        % Button pushed function: PredictdataButton_3
        function PredictdataButton_3Pushed(app, event)
            global indices
            %             oldP = beginTask(app, app.FaultydataselectionPanel_SWaT);
            day = str2double(app.DayListBox.Value);
            faultData = app.SWaT.SWaTDatasetAttackv0{1+(day-1)*60*60*24:min(day*60*60*24, end),indices(1,2)};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            %             labs = app.SWaT.SWaTDatasetAttackv0.NormalAttack(1+(day-1)*60*60*24:day*60*60*24);
            %             labels = false(length(labs),1);
            %             labels(labs == "Attack") = true;
            attackList = app.SWaTAttacks.attackList;
            text = app.UITable.ColumnName(indices(1,2));
            idx = find(contains(attackList.AttackPoint, text{:}));
            timebegin = attackList.StartTime(idx);
            timeend = attackList.EndTime(idx);
            labels = false(length(YTest),1);
            time = datetime(app.SWaT.SWaTDatasetAttackv0.Timestamp(1+(day-1)*60*60*24:min(day*60*60*24, end)));
            for i = 1:length(timebegin)
                labels(find(time==timebegin(i)):find(time==timeend(i))) = true;
            end
            assignin("base", "labels", labels)
            assignin("base", "attacks", attackList.AttackPoint)
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(labels(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(labels(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            probDens = app.pd;
            plotResultsAndEval(app, pred, YTest, labels, 1500, app.staticThreshold, probDens);
            %             endTask(app, app.FaultydataselectionPanel_SWaT, oldP);
        end

        % Button pushed function: OnlinedetectionsimulationButton_3
        function OnlinedetectionsimulationButton_3Pushed(app, event)
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            threshold = app.staticThreshold.Noise;
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
            end
            assignin("base", "selected", app.FaulttypeButtonGroup_2.SelectedObject)
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            labels = logical(faultType{dataIdx, 2});
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                method = 'Predictive';
            else
                method = 'Reconstruction';
            end
            simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold)
        end

        % Button pushed function: OnlinedetectionsimulationButton_2
        function OnlinedetectionsimulationButton_2Pushed(app, event)
             global indices
            %             oldP = beginTask(app, app.FaultydataselectionPanel_SWaT);
            day = str2double(app.DayListBox.Value);
            faultData = app.SWaT.SWaTDatasetAttackv0{1+(day-1)*60*60*24:min(day*60*60*24, end),indices(1,2)};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
           
            %             labs = app.SWaT.SWaTDatasetAttackv0.NormalAttack(1+(day-1)*60*60*24:day*60*60*24);
            %             labels = false(length(labs),1);
            %             labels(labs == "Attack") = true;
            attackList = app.SWaTAttacks.attackList;
            text = app.UITable.ColumnName(indices(1,2));
            idx = find(contains(attackList.AttackPoint, text{:}));
            timebegin = attackList.StartTime(idx);
            timeend = attackList.EndTime(idx);
            labels = false(length(dataTest),1);
            time = datetime(app.SWaT.SWaTDatasetAttackv0.Timestamp(1+(day-1)*60*60*24:min(day*60*60*24, end)));
            for i = 1:length(timebegin)
                labels(find(time==timebegin(i)):find(time==timeend(i))) = true;
            end
            
             if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                method = 'Predictive';
            else
                method = 'Reconstruction';
            end
            simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold)    
        end

        % Value changed function: ScenarioListBox
        function ScenarioListBoxValueChanged(app, event)
            value = app.ScenarioListBox.Value;
            
        end

        % Button pushed function: VisualizedataButton_7
        function VisualizedataButton_7Pushed(app, event)
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                           
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            
                    end
            end
            faultData = faultType{dataIdx,1};
            f = uifigure;
            f.Visible = 'off';
            ax = uiaxes(f);
            f.Position(3) = ax.Position(3)+10;
            f.Position(4) = ax.Position(4)+10;
            labels = logical(faultType{dataIdx,2});
            t = 1:length(labels);
            plot(ax, t, faultData)
            patchAnomalies(app, ax, labels)
            legend(ax, 'data', "Anomalies", "Location","bestoutside")
            movegui(f, "center")
            f.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.modelSelectionApp)
                app.modelSelectionApp.delete()
            end
            app.delete()
        end

        % Selection change function: MainPanel
        function MainPanelSelectionChanged(app, event)
%             % This is a workaround for disabling tabs since there is no
%             % enable parameter for individual tabs
%             selectedTab = app.MainPanel.SelectedTab;
%             if ~app.loadedTrainingData
%                 app.MainPanel.SelectedTab = event.OldValue;
%             end
        end

        % Selection changed function: D_MainButtonGroup
        function D_MainButtonGroupSelectionChanged(app, event)
            selectedButton = app.D_MainButtonGroup.SelectedObject;
            switch selectedButton
                case app.D_AvailableDatasetsButton
                    app.D_AvailableDatasetsDropDown.Enable = 'on';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'on';
                    app.D_BrowseCustomDatasetButton.Enable = 'off';
                    app.D_ImportCustomDatasetLabel.Enable = 'off';
                    app.D_ImportCustomDatasetEditField.Enable = 'off';
                    app.D_ImportCustomDatasetEditField.Editable = 'off';
                case app.D_CustomDatasetButton
                    app.D_AvailableDatasetsDropDown.Enable = 'off';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'off';
                    app.D_BrowseCustomDatasetButton.Enable = 'on';
                    app.D_ImportCustomDatasetLabel.Enable = 'on';
                    app.D_ImportCustomDatasetEditField.Enable = 'on';
                    app.D_ImportCustomDatasetEditField.Editable = 'on';
            end
            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            app.showDataPreparationFor('None');
            app.togglePreprocessingTab(false);
            app.toggleTrainButtons(false);
            app.toggleDetectionEvaluationTab(false);
        end

        % Value changed function: D_AvailableDatasetsDropDown
        function D_AvailableDatasetsDropDownValueChanged(app, event)
            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            app.showDataPreparationFor('None');
            app.togglePreprocessingTab(false);
            app.toggleTrainButtons(false);
            app.toggleDetectionEvaluationTab(false);
        end

        % Button pushed function: D_BrowseCustomDatasetButton
        function D_BrowseCustomDatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.D_ImportCustomDatasetEditField.Value = path;
            end
        end

        % Button pushed function: D_LoadDataButton
        function D_LoadDataButtonPushed(app, event)
            oldPointer = app.startTask();

            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            
            app.rawTrainingData = [];
            app.timestampsTrainingData = [];
            app.filesTrainingData = [];
            app.rawTestingData = [];
            app.timestampsTestingData = [];
            app.filesTestingData = [];

            switch app.D_MainButtonGroup.SelectedObject
                case app.D_AvailableDatasetsButton
                    f = errordlg("Included dataset are not fully implemented yet! Please import a custom dataset instead.", ...
                                'Implementation Error');
                    app.stopTask(oldPointer);
                    return
%                     switch app.D_AvailableDatasetsDropDown.Value
%                         case 'SWaT'
%                             app.SWaTData = load(fullfile(pwd, "myApp_resources\SWaT\SWaT Data.mat"));
%                             
%                             newItems = strings();
%                             for i = 1: length(app.SWaTData.SWaTDatasetNormalv0.Properties.VariableNames)
%                                 variableName = app.SWaTData.SWaTDatasetNormalv0.Properties.VariableNames{i};
%                                 if ~strcmp(variableName, 'Timestamp') && ~strcmp(variableName, 'NormalAttack')
%                                     newItems(end + 1) = variableName;
%                                 end
%                             end
%                             app.D_SWaTSelectSensorDropDown.Items = [newItems(2:end)];
% 
%                             app.rawTrainingData = app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30:end, 2};
%                             app.timestampsTrainingData = datetime(app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30:end, 1});                            
% 
%                             plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
%                                 app.timestampsTrainingData, ...
%                                 app.rawTrainingData);
% 
%                             lim1 = datetime(app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30,1});
%                             lim2 = datetime(app.SWaTData.SWaTDatasetNormalv0{end,1});
%                             xlim(app.D_RawTrainingDatasetPreviewUIAxes, [lim1 lim2]);
% 
%                             app.datasetType = 'SWaT';
%                             app.selectedSWaTSensor = newItems{1};
%                             app.idx_selectedSWaTSensor = 2;
%                             app.showDataPreparationFor('SWaT');
% 
%                             app.preprocessedTrainingData = app.rawTrainingData;
% 
%                             plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
%                                 app.timestampsTrainingData, ...
%                                 app.preprocessedTrainingData);
%                             xlim(app.P_PreprocessedTrainingDatasetPreviewUIAxes, [lim1 lim2]);
% 
%                             app.preprocessingMethod = 'Raw Data';
% 
%                             app.togglePreprocessingTab(true);
%                         case 'UAV'
%                             app.UAVAccData = load(fullfile(pwd, "myApp_resources\UAV\UAV Acc.mat"), "faultFreeAcc");
%                             app.UAVGyrData = load(fullfile(pwd, "myApp_resources\UAV\UAV Gyr.mat"), "faultFreeGyr");
% 
%                             app.rawTrainingData = app.UAVAccData.faultFreeAcc{1};
%                             app.timestampsTrainingData = 1:length(app.UAVAccData.faultFreeAcc{1});
% 
%                             plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
%                                 app.timestampsTrainingData, ...
%                                 app.rawTrainingData);
% 
%                             app.datasetType = 'UAV';
%                             app.selectedUAVSensor = 'Accelerometer';
%                             app.showDataPreparationFor('UAV');
% 
%                             app.preprocessedTrainingData = app.rawTrainingData;
% 
%                             plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
%                                 app.timestampsTrainingData, ...
%                                 app.preprocessedTrainingData);
% 
%                             app.preprocessingMethod = 'Raw Data';
% 
%                             app.togglePreprocessingTab(true);
%                         case 'AVS'
%                             % Todo: Load AVS data here
%                             app.datasetType = 'AVS';
%                             app.showDataPreparationFor('AVS');
%                     end
                case app.D_CustomDatasetButton
                    [app.rawTrainingData, ...
                        app.timestampsTrainingData, ...
                        app.filesTrainingData, ...
                        app.rawTestingData, ...
                        app.timestampsTestingData, ...
                        app.labelsTestingData, ...
                        app.filesTestingData] = app.loadCustomDataset(app.D_ImportCustomDatasetEditField.Value);
                    
                    if ~isequal(app.rawTestingData, 0) && isequal(app.labelsTestingData, 0) && isequal(app.timestampsTestingData, 0)
                        return
                    end

                    if ~isequal(app.rawTrainingData, 0)
                        hold(app.D_RawTrainingDatasetPreviewUIAxes, 'on')
                        for i = 1:size(app.rawTrainingData, 1)
                            plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
                            app.timestampsTrainingData{i, 1},...
                            app.rawTrainingData{i, 1})
                        end
                        hold(app.D_RawTrainingDatasetPreviewUIAxes, 'off')

                        set(app.D_RawTrainingDatasetPreviewUIAxes.Children(1:end-1), 'Visible', 'off')
                        l_r_tr = legend(app.D_RawTrainingDatasetPreviewUIAxes, "Location","bestoutside"); 
                        set(l_r_tr, 'ButtonDownFcn', {@app.legendUIAxes, app.D_RawTrainingDatasetPreviewUIAxes})  
                    end

                    if ~isequal(app.rawTestingData, 0)
                        hold(app.D_RawTestingDatasetPreviewUIAxes, 'on')
                        for i = 1:size(app.rawTestingData, 1)
                            plot(app.D_RawTestingDatasetPreviewUIAxes, ...
                            app.timestampsTestingData{i, 1},...
                            app.rawTestingData{i, 1})
                        end
                        hold(app.D_RawTestingDatasetPreviewUIAxes, 'off')

                        set(app.D_RawTestingDatasetPreviewUIAxes.Children(1:end-1), 'Visible', 'off')
                        l_r_ts = legend(app.D_RawTestingDatasetPreviewUIAxes, "Location","bestoutside"); 
                        set(l_r_ts, 'ButtonDownFcn', {@app.legendUIAxes, app.D_RawTestingDatasetPreviewUIAxes})

                        app.DE_FaultyDataSelectionDropDown.Items = app.filesTestingData;
                        
                    end
                    
                    app.preprocessData()

                    app.datasetType = 'Custom';
                    app.showDataPreparationFor('Custom');

                    app.togglePreprocessingTab(true);
                    app.toggleModelTrainingTab(true);
            end
            app.stopTask(oldPointer);
            % exportapp(app.UIFigure, 'C:\Ich\Studium\Bachelorarbeit\Inhalt\Template\Figures\DataPanel.pdf');
        end

        % Value changed function: D_SWaTSelectSensorDropDown
        function D_SWaTSelectSensorDropDownValueChanged(app, event)
            oldPointer = app.startTask();

            value = app.D_SWaTSelectSensorDropDown.Value;
            
            [~, idx] = ismember(value, app.D_SWaTSelectSensorDropDown.Items);

            idx = idx + 1;
            
            app.rawTrainingData = app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30:end, idx};
            app.timestampsTrainingData = datetime(app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30:end, 1});

            plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
                app.timestampsTrainingData, ...
                app.rawTrainingData);

            lim1 = datetime(app.SWaTData.SWaTDatasetNormalv0{7*60*60+60*30,1});
            lim2 = datetime(app.SWaTData.SWaTDatasetNormalv0{end,1});
            xlim(app.D_RawTrainingDatasetPreviewUIAxes, [lim1 lim2]);

            app.datasetType = 'SWaT';
            app.selectedSWaTSensor = value;
            app.idx_selectedSWaTSensor = idx;
            app.showDataPreparationFor('SWaT');

            app.preprocessedTrainingData = app.rawTrainingData;

            plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
                app.timestampsTrainingData, ...
                app.preprocessedTrainingData);
            xlim(app.P_PreprocessedTrainingDatasetPreviewUIAxes, [lim1 lim2]);

            app.preprocessingMethod = 'Raw Data';

            app.togglePreprocessingTab(true);

            app.stopTask(oldPointer);
        end

        % Selection changed function: D_UAVSensorDataButtonGroup
        function D_UAVSensorDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.D_UAVSensorDataButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.D_UAVAccelerometerButton
                    app.rawTrainingData = app.UAVAccData.faultFreeAcc{1};
                    app.timestampsTrainingData = 1:length(app.UAVAccData.faultFreeAcc{1});
                    
                    plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
                        app.timestampsTrainingData, ...
                        app.rawTrainingData);

                    app.datasetType = 'UAV';
                    app.selectedUAVSensor = 'Accelerometer';
                    app.showDataPreparationFor('UAV');

                    app.preprocessedTrainingData = app.rawTrainingData;

                    plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
                        app.timestampsTrainingData, ...
                        app.preprocessedTrainingData);

                    app.preprocessingMethod = 'Raw Data';

                    app.togglePreprocessingTab(true);
                case app.D_UAVGyroscopeButton
                    app.rawTrainingData = app.UAVGyrData.faultFreeGyr{1};
                    app.timestampsTrainingData = 1:length(app.UAVGyrData.faultFreeGyr{1});
                    
                    plot(app.D_RawTrainingDatasetPreviewUIAxes, ...
                        app.timestampsTrainingData, ...
                        app.rawTrainingData);

                    app.datasetType = 'UAV';
                    app.selectedUAVSensor = 'Gyroscope';
                    app.showDataPreparationFor('UAV');

                    app.preprocessedTrainingData = app.rawTrainingData;

                    plot(app.P_PreprocessedTrainingDatasetPreviewUIAxes, ...
                        app.timestampsTrainingData, ...
                        app.preprocessedTrainingData);

                    app.preprocessingMethod = 'Raw Data';

                    app.togglePreprocessingTab(true);
            end
        end

        % Button pushed function: D_NextButton
        function D_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Selection changed function: P_MethodButtonGroup
        function P_MethodButtonGroupSelectionChanged(app, event)
            oldPointer = app.startTask();
            
            app.preprocessData();

            app.toggleModelTrainingTab(true);

            app.stopTask(oldPointer);
        end

        % Button pushed function: P_PreviousButton
        function P_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.DataTab;
        end

        % Button pushed function: P_NextButton
        function P_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.ModelTrainingTab;
        end

        % Callback function
        function ShowNetworkOptionsMenuSelected(app, event)
            % TODO: Popup that shows the selected network's options
            f = errordlg("This function is not implemented at this point!\na\na\na\na\na", ...
                        'Implementation Error');
            return
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedItems = app.MT_NetworksListBox.Value;

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).label, app.MT_NetworksListBox.Items);
                app.MT_NetworksListBox.Items(idx) = [];
                app.MT_NetworksListBox.ItemsData(idx) = [];
            end
            if isempty(app.MT_NetworksListBox.Items)
                app.toggleTrainButtons(false)
            end

            app.updateNetworkLabels()
        end

        % Button pushed function: MT_ShowNetworkButton
        function MT_ShowNetworkButtonPushed(app, event)
            oldPointer = app.startTask();
            
            selection = app.MT_NetworksListBox.Value;
            if isempty(selection)
                f = errordlg("No network selected for viewing! Select network first.", ...
                        'Network Viewer Error');
                app.stopTask(oldPointer);
                return
            end
            if length(selection) > 1
                f = errordlg("Can't disply multiple networks at once! Please select just a single network.", ...
                        'Network Viewer Error');
                app.stopTask(oldPointer);
                return
            end

            selectedNetwork = selection.options;
            
            app.MT_PreviewContentModelTypeLabel.Text = selectedNetwork.modelType;
            app.MT_PreviewContentModelLabel.Text = selectedNetwork.model;

            switch selectedNetwork.modelType
                case 'Reconstructive'
                    switch selectedNetwork.model
                        case 'Basic AE'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.neurons);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Neurons:';
                        case 'LSTM AE'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.hiddenUnits);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:'; 
                        case 'Hybrid CNN-LSTM AE'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.hiddenUnits);
                            app.MT_PreviewContentModelHyperparameter_2_Label.Text = string(selectedNetwork.filter);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:';
                            app.MT_PreviewModelHyperparameter_2_Label.Text = 'Filter:'; 
                    end
                case 'Predictive'
                    switch selectedNetwork.model
                        case 'LSTM'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.hiddenUnits);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:';
                        case 'Hybrid CNN-LSTM'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.hiddenUnits);
                            app.MT_PreviewContentModelHyperparameter_2_Label.Text = string(selectedNetwork.filter);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:';
                            app.MT_PreviewModelHyperparameter_2_Label.Text = 'Filter:'; 
                        case 'GRU'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.hiddenUnits);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:';
                        case 'CNN (DeepAnT)'
                            app.MT_PreviewContentModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewModelHyperparameter_1_Label.Visible = 'on';
                            app.MT_PreviewContentModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewModelHyperparameter_2_Label.Visible = 'off';
                            app.MT_PreviewContentModelHyperparameter_1_Label.Text = string(selectedNetwork.filter);
                            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Filter:';
                    end
            end

            app.MT_PreviewContentWindowSizeLabel.Text = string(selectedNetwork.windowSize);
            app.MT_PreviewContentStepSizeLabel.Text = string(selectedNetwork.stepSize);
            app.MT_PreviewContentEpochsLabel.Text = string(selectedNetwork.epochs);
            app.MT_PreviewContentMinibatchSizeLabel.Text = string(selectedNetwork.minibatchSize);
            app.MT_PreviewContentRatioTrainValLabel.Text = string(selectedNetwork.ratioTrainVal);

            app.MT_PreviewOptionsPanel.Visible = 'on';

            graph = app.createModelGraphForPreview(selectedNetwork);
            plot(app.MT_PreviewUIAxes, graph)
%             id = split(selectedNetwork.id, '_');
%             app.MT_PreviewUIAxes.Title.String = sprintf('Network Preview for: %s (%s)', id{1}, id{2});

            app.stopTask(oldPointer);
        end

        % Selection changed function: MT_NetworkSelectionButtonGroup
        function MT_NetworkSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MT_NetworkSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MT_NewNetworksButton
                    app.MT_PretrainedNetworkLabel.Enable = 'off';
                    app.MT_PretrainedNetworkTextArea.Enable = 'off';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'off';
                    app.MT_NetworksLabel.Enable = 'on';
                    app.MT_NetworksListBox.Enable = 'on';
                    app.MT_AddNetworkButton.Enable = 'on';
                    app.MT_ClearNetworksButton.Enable = 'on';
                case app.MT_PretrainedNetworkButton
                    app.MT_PretrainedNetworkLabel.Enable = 'on';
                    app.MT_PretrainedNetworkTextArea.Enable = 'on';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'on';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'on';
                    app.MT_NetworksLabel.Enable = 'off';
                    app.MT_NetworksListBox.Enable = 'off';
                    app.MT_AddNetworkButton.Enable = 'off';
                    app.MT_ClearNetworksButton.Enable = 'off';
            end
        end

        % Button pushed function: MT_AddNetworkButton
        function MT_AddNetworkButtonPushed(app, event)
            app.modelSelectionApp = ModelSelection(app);
        end

        % Button pushed function: MT_TrainSelectedNetworksButton
        function MT_TrainSelectedNetworksButtonPushed(app, event)
            oldPointer = app.startTask();
            
            app.toggleTrainingStatus(false);
            app.toggleDetectionEvaluationTab(false);
            app.DE_TrainedNetworksListBox.Items = {};

            app.trainConsecutive(app.MT_NetworksListBox.Value);
            
            app.toggleTrainingStatus(true);
            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.toggleDetectionEvaluationTab(true);


            msgbox('Successfully trained networks.', 'Training finished');
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;

            app.stopTask(oldPointer);
        end

        % Button pushed function: MT_TrainAllNetworksButton
        function MT_TrainAllNetworksButtonPushed(app, event)
            oldPointer = app.startTask();

            app.toggleTrainingStatus(false);
            app.toggleDetectionEvaluationTab(false);
            app.DE_TrainedNetworksListBox.Items = {};
            
            app.trainConsecutive(app.MT_NetworksListBox.ItemsData);

            app.toggleTrainingStatus(true);
            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.toggleDetectionEvaluationTab(true);

            msgbox('Successfully trained networks.', 'Training finished');
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;

            app.stopTask(oldPointer);
        end

        % Button pushed function: MT_TrainSelectedNetworksParallelButton
        function MT_TrainSelectedNetworksParallelButtonPushed(app, event)
            oldPointer = app.startTask();

            app.toggleTrainingStatus(false);
            app.toggleDetectionEvaluationTab(false);
            app.DE_TrainedNetworksListBox.Items = {};

            app.trainParallel(app.MT_NetworksListBox.Value);

            app.toggleTrainingStatus(true);
            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.toggleDetectionEvaluationTab(true);

            msgbox('Successfully trained networks.', 'Training finished');
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;

            app.stopTask(oldPointer);
        end

        % Button pushed function: MT_TrainAllNetworksParallelButton
        function MT_TrainAllNetworksParallelButtonPushed(app, event)
            oldPointer = app.startTask();

            app.toggleTrainingStatus(false);
            app.toggleDetectionEvaluationTab(false);
            app.DE_TrainedNetworksListBox.Items = {};

            app.trainParallel(app.MT_NetworksListBox.ItemsData);

            app.toggleTrainingStatus(true);
            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.toggleDetectionEvaluationTab(true);

            msgbox('Successfully trained networks.', 'Training finished');
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;

            app.stopTask(oldPointer);
        end

        % Button pushed function: MT_ClearNetworksButton
        function MT_ClearNetworksButtonPushed(app, event)
            app.MT_NetworksListBox.Items = {};
            app.MT_NetworksListBox.ItemsData = [];

            app.toggleTrainButtons(false);
        end

        % Button pushed function: MT_PreviousButton
        function MT_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Button pushed function: DE_RunDetectionForSelectedButton
        function DE_RunDetectionForSelectedButtonPushed(app, event)
            oldPointer = app.startTask();
            
            nets = app.DE_TrainedNetworksListBox.Value;
            if isempty(nets)
                f = errordlg('No Trained Networks selected!', ...
                            'Detection Error');
                app.stopTask(oldPointer);
                return
            end

            app.runDetectionFor(app.DE_TrainedNetworksListBox.Value);

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();

            app.stopTask(oldPointer);
        end

        % Button pushed function: DE_RunDetectionForAllButton
        function DE_RunDetectionForAllButtonPushed(app, event)
            oldPointer = app.startTask();
            
            nets = app.DE_TrainedNetworksListBox.ItemsData;
            if isempty(nets)
                f = errordlg('No Trained Networks!', ...
                            'Detection Error');
                app.stopTask(oldPointer);
                return
            end

            app.runDetectionFor(nets);

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();

            app.stopTask(oldPointer);
        end

        % Value changed function: DE_StaticThresholdingParametricCheckBox
        function DE_StaticThresholdingParametricCheckBoxValueChanged(app, event)
            oldPointer = app.startTask();
            
            if ~isempty(app.currentPred)
                app.updateDetectionScores(app.currentPred, app.currentYTest, app.currentLabels, ...
                    app.trainedNetworks.(app.currentNetName).staticThreshold, app.currentNetName, 'Static', true)
            end

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();
            
            app.stopTask(oldPointer);
        end

        % Value changed function: DE_AnomalyPaddingSlider
        function DE_AnomalyPaddingSliderValueChanged(app, event)
            oldPointer = app.startTask();
            
            if ~isempty(app.currentPred)
                app.updateDetectionScores(app.currentPred, app.currentYTest, app.currentLabels, ...
                    0, app.currentNetName, 'Dynamic', true)
            end

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();
            
            app.stopTask(oldPointer);
        end

        % Value changed function: DE_WindowSizeSlider
        function DE_WindowSizeSliderValueChanged(app, event)
            oldPointer = app.startTask();
            
            if ~isempty(app.currentPred)
                app.updateDetectionScores(app.currentPred, app.currentYTest, app.currentLabels, ...
                    0, app.currentNetName, 'Dynamic', true)
            end

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();
            
            app.stopTask(oldPointer);
        end

        % Value changed function: DE_ZRangeSlider
        function DE_ZRangeSliderValueChanged(app, event)
            oldPointer = app.startTask();
            
            if ~isempty(app.currentPred)
                app.updateDetectionScores(app.currentPred, app.currentYTest, app.currentLabels, ...
                    0, app.currentNetName, 'Dynamic', true)
            end

            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();
            
            app.stopTask(oldPointer);
        end

        % Value changed function: DE_SortByDropDown
        function DE_SortByDropDownValueChanged(app, event)
            oldPointer = app.startTask();
            
            app.showTrainedNetworks(app.DE_SortByDropDown.Value);
            app.sortTrainedNetworks(app.DE_SortByDropDown.Value);
            app.showScoresInTable();

            app.stopTask(oldPointer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataPreparationPanel = uipanel(app.UIFigure);
            app.DataPreparationPanel.Title = 'Data Preparation';
            app.SelectDatasetButtonGroup = uibuttongroup(app.DataPreparationPanel);
            app.SelectDatasetButtonGroup.Title = 'Select Dataset';
            app.SWaTButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.SWaTButton.Text = 'SWaT';
            app.SWaTButton.Value = true;
            app.UAVButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.UAVButton.Text = 'UAV';
            app.AVSButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.AVSButton.Text = 'AVS';
            app.NetworkButtonGroup = uibuttongroup(app.DataPreparationPanel);
            app.NetworkButtonGroup.Title = 'Network';
            app.PretrainedButton = uiradiobutton(app.NetworkButtonGroup);
            app.PretrainedButton.Text = 'Pretrained';
            app.PretrainedButton.Value = true;
            app.TrainnewnetworkButton = uiradiobutton(app.NetworkButtonGroup);
            app.TrainnewnetworkButton.Text = 'Train new network';
            app.NextButton = uibutton(app.DataPreparationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreprocessingPanel_Old = uipanel(app.UIFigure);
            app.PreprocessingPanel_Old.Title = 'Preprocessing';
            app.PreprocessingmethodButtonGroup = uibuttongroup(app.PreprocessingPanel_Old);
            app.PreprocessingmethodButtonGroup.Title = 'Preprocessing method';
            app.StandardizeButton = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.StandardizeButton.Text = 'Standardize';
            app.StandardizeButton.Value = true;
            app.Rescale01Button = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.Rescale01Button.Text = 'Rescale [0,1]';
            app.RawdataButton = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RestartButton = uibutton(app.PreprocessingPanel_Old, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.NextButton_2 = uibutton(app.PreprocessingPanel_Old, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.NetworkButtonGroup_2 = uibuttongroup(app.PreprocessingPanel_Old);
            app.NetworkButtonGroup_2.Title = 'Network';
            app.PredictiveButton = uiradiobutton(app.NetworkButtonGroup_2);
            app.PredictiveButton.Text = 'Predictive';
            app.PredictiveButton.Value = true;
            app.ReconstructionButton = uiradiobutton(app.NetworkButtonGroup_2);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.SWaTDataPanel = uipanel(app.UIFigure);
            app.SWaTDataPanel.Title = 'SWaT Data';
            app.UITable = uitable(app.SWaTDataPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectedSensorCheckBox = uicheckbox(app.SWaTDataPanel);
            app.SelectedSensorCheckBox.Visible = 'off';
            app.SelectedSensorCheckBox.Text = '''Selected Sensor''';
            app.TextArea = uitextarea(app.SWaTDataPanel);
            app.TextArea.Value = {'Please select a sensor from the table'};
            app.NextButton_3 = uibutton(app.SWaTDataPanel, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Enable = 'off';
            app.NextButton_3.Text = 'Next';
            app.ResetButton_2 = uibutton(app.SWaTDataPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.VisualizedataButton_2 = uibutton(app.SWaTDataPanel, 'push');
            app.VisualizedataButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_2Pushed, true);
            app.VisualizedataButton_2.Enable = 'off';
            app.VisualizedataButton_2.Text = 'Visualize data';
            app.SelectedSensorLampLabel = uilabel(app.SWaTDataPanel);
            app.SelectedSensorLampLabel.Text = '''Selected Sensor''';
            app.SelectedSensorLamp = uilamp(app.SWaTDataPanel);
            app.NetworkOptionsPanel = uipanel(app.UIFigure);
            app.NetworkOptionsPanel.Title = 'Network Options';
            app.Image = uiimage(app.NetworkOptionsPanel);
            app.Image.ImageSource = 'window step.png';
            app.RestartButton_2 = uibutton(app.NetworkOptionsPanel, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_2Pushed, true);
            app.RestartButton_2.Text = 'Restart';
            app.TrainButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.NetworkspecificityDropDownLabel = uilabel(app.NetworkOptionsPanel);
            app.NetworkspecificityDropDownLabel.Text = 'Network specificity';
            app.NetworkspecificityDropDown = uidropdown(app.NetworkOptionsPanel);
            app.NetworkspecificityDropDown.Items = {'Basic', 'LSTM', 'Hybrid (RNN-CNN)'};
            app.NetworkspecificityDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkspecificityDropDownValueChanged, true);
            app.NetworkspecificityDropDown.Value = 'Basic';
            app.NumberofneuronsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.NumberofneuronsEditFieldLabel.Text = 'Number of neurons';
            app.NumberofneuronsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.NumberofneuronsEditField.Tooltip = {'Number for first layer. then n2 = n/2 and n3 = n2/2'};
            app.NumberofneuronsEditField.Value = 32;
            app.HiddenUnitsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.HiddenUnitsEditFieldLabel.Text = 'Hidden Units';
            app.HiddenUnitsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.HiddenUnitsEditField.Value = 32;
            app.WindowsizewEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.WindowsizewEditFieldLabel.Text = 'Window size (w)';
            app.WindowsizewEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.WindowsizewEditField.Value = 35;
            app.StepSizesEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.StepSizesEditFieldLabel.Text = 'Step Size (s)';
            app.StepSizesEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.StepSizesEditField.Editable = 'off';
            app.StepSizesEditField.Value = 1;
            app.FilterNumberEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.FilterNumberEditFieldLabel.Text = 'Filter Number';
            app.FilterNumberEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.EpochsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.EpochsEditField.Value = 35;
            app.MinibatchsizeEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.MinibatchsizeEditFieldLabel.Text = 'Minibatch size';
            app.MinibatchsizeEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.MinibatchsizeEditField.Value = 70;
            app.SavenettoworkspaceButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.SavenettoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavenettoworkspaceButtonPushed, true);
            app.SavenettoworkspaceButton.Text = 'Save net to workspace';
            app.UsevaldataCheckBox = uicheckbox(app.NetworkOptionsPanel);
            app.UsevaldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsevaldataCheckBoxValueChanged, true);
            app.UsevaldataCheckBox.Text = 'Use val data';
            app.NetworkdesciptionButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.NetworkdesciptionButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkdesciptionButtonPushed, true);
            app.NetworkdesciptionButton.Text = 'Network desciption';
            app.NextButton_4 = uibutton(app.NetworkOptionsPanel, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Enable = 'off';
            app.NextButton_4.Text = 'Next';
            app.RatioEditField_2Label = uilabel(app.NetworkOptionsPanel);
            app.RatioEditField_2Label.Enable = 'off';
            app.RatioEditField_2Label.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.Enable = 'off';
            app.RatioEditField.Tooltip = {'Train/Validation ratio'; '0 < value < 1'; 'Recommended: 0.8'};
            app.RatioEditField.Value = 0.8;
            app.AVSPreparationPanel = uipanel(app.UIFigure);
            app.AVSPreparationPanel.Title = 'AVS Preparation';
            app.VehiculescenarioButtonGroup = uibuttongroup(app.AVSPreparationPanel);
            app.VehiculescenarioButtonGroup.Title = 'Vehicule scenario';
            app.HighwayButton = uitogglebutton(app.VehiculescenarioButtonGroup);
            app.HighwayButton.Text = 'Highway';
            app.HighwayButton.Value = true;
            app.TownButton = uitogglebutton(app.VehiculescenarioButtonGroup);
            app.TownButton.Text = 'Town';
            app.RestartButton_3 = uibutton(app.AVSPreparationPanel, 'push');
            app.RestartButton_3.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_3Pushed, true);
            app.RestartButton_3.Text = 'Restart';
            app.NextButton_5 = uibutton(app.AVSPreparationPanel, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next';
            app.VisualizedataButton_3 = uibutton(app.AVSPreparationPanel, 'push');
            app.VisualizedataButton_3.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_3Pushed, true);
            app.VisualizedataButton_3.Text = 'Visualize data';
            app.UAVPreparationPanel = uipanel(app.UIFigure);
            app.UAVPreparationPanel.Title = 'UAV Preparation';
            app.SensordataselectionButtonGroup = uibuttongroup(app.UAVPreparationPanel);
            app.SensordataselectionButtonGroup.Title = 'Sensor data selection';
            app.AccelerometerButton = uitogglebutton(app.SensordataselectionButtonGroup);
            app.AccelerometerButton.Text = 'Accelerometer';
            app.AccelerometerButton.Value = true;
            app.GyroscopeButton = uitogglebutton(app.SensordataselectionButtonGroup);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.RestartButton_4 = uibutton(app.UAVPreparationPanel, 'push');
            app.RestartButton_4.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_4Pushed, true);
            app.RestartButton_4.Text = 'Restart';
            app.NextButton_6 = uibutton(app.UAVPreparationPanel, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next';
            app.VisualizedataButton_4 = uibutton(app.UAVPreparationPanel, 'push');
            app.VisualizedataButton_4.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_4Pushed, true);
            app.VisualizedataButton_4.Text = 'Visualize data';
            app.FaultydataselectionPanel_AVS = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_AVS.Title = 'Faulty data selection';
            app.FaulttypeButtonGroup = uibuttongroup(app.FaultydataselectionPanel_AVS);
            app.FaulttypeButtonGroup.Title = 'Fault type';
            app.OffsetButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.OffsetButton.Text = 'Offset';
            app.OffsetButton.Value = true;
            app.NoiseButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.NoiseButton.Text = 'Noise';
            app.StuckvalueButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.StuckvalueButton.Text = 'Stuck value';
            app.VisualizedataButton_5 = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.VisualizedataButton_5.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_5Pushed, true);
            app.VisualizedataButton_5.Text = 'Visualize data';
            app.FaultdurationDropDownLabel = uilabel(app.FaultydataselectionPanel_AVS);
            app.FaultdurationDropDownLabel.Text = 'Fault duration';
            app.FaultdurationDropDown = uidropdown(app.FaultydataselectionPanel_AVS);
            app.FaultdurationDropDown.Items = {'0.6s', '1:2s', '1.8s', '2.4s', '3.0s', '3.6s', '4.2s', '5.0s'};
            app.FaultdurationDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.FaultdurationDropDown.Value = '1';
            app.ScenarioListBoxLabel = uilabel(app.FaultydataselectionPanel_AVS);
            app.ScenarioListBoxLabel.Text = 'Scenario';
            app.ScenarioListBox = uilistbox(app.FaultydataselectionPanel_AVS);
            app.ScenarioListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ScenarioListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ScenarioListBox.ValueChangedFcn = createCallbackFcn(app, @ScenarioListBoxValueChanged, true);
            app.ScenarioListBox.Value = '1';
            app.PredictdataButton = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.PredictdataButton.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButtonPushed, true);
            app.PredictdataButton.Text = 'Predict data';
            app.OnlinedetectionsimulationButton = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.OnlinedetectionsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButtonPushed, true);
            app.OnlinedetectionsimulationButton.Text = 'Online detection simulation';
            app.FaultydataselectionPanel_UAVAcc = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_UAVAcc.Title = 'Faulty data selection';
            app.FaulttypeButtonGroup_2 = uibuttongroup(app.FaultydataselectionPanel_UAVAcc);
            app.FaulttypeButtonGroup_2.Title = 'Fault type';
            app.OffsetButton_2 = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.OffsetButton_2.Text = 'Offset';
            app.OffsetButton_2.Value = true;
            app.PackagedropButton = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.PackagedropButton.Text = 'Package drop';
            app.StuckvalueButton_2 = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.StuckvalueButton_2.Text = 'Stuck value';
            app.ScenarioListBox_2Label = uilabel(app.FaultydataselectionPanel_UAVAcc);
            app.ScenarioListBox_2Label.Text = 'Scenario';
            app.ScenarioListBox_2 = uilistbox(app.FaultydataselectionPanel_UAVAcc);
            app.ScenarioListBox_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ScenarioListBox_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ScenarioListBox_2.Value = '1';
            app.PredictdataButton_2 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.PredictdataButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButton_2Pushed, true);
            app.PredictdataButton_2.Text = 'Predict data';
            app.FaulttypeButtonGroup_4 = uibuttongroup(app.FaultydataselectionPanel_UAVAcc);
            app.FaulttypeButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @FaulttypeButtonGroup_4SelectionChanged, true);
            app.FaulttypeButtonGroup_4.Title = 'Fault type';
            app.FaulttypeButtonGroup_4.Visible = 'off';
            app.OffsetButton_4 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.OffsetButton_4.Text = 'Offset';
            app.OffsetButton_4.Value = true;
            app.NoiseButton_3 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.NoiseButton_3.Text = 'Noise';
            app.StuckvalueButton_3 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.StuckvalueButton_3.Text = 'Stuck value';
            app.VisualizedataButton_7 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.VisualizedataButton_7.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_7Pushed, true);
            app.VisualizedataButton_7.Text = 'Visualize data';
            app.OnlinedetectionsimulationButton_3 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.OnlinedetectionsimulationButton_3.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButton_3Pushed, true);
            app.OnlinedetectionsimulationButton_3.Text = 'Online detection simulation';
            app.FaultydataselectionPanel_SWaT = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_SWaT.Title = 'Faulty data selection';
            app.DayListBoxLabel = uilabel(app.FaultydataselectionPanel_SWaT);
            app.DayListBoxLabel.Text = 'Day';
            app.DayListBox = uilistbox(app.FaultydataselectionPanel_SWaT);
            app.DayListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.DayListBox.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.DayListBox.Value = '1';
            app.PredictdataButton_3 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.PredictdataButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButton_3Pushed, true);
            app.PredictdataButton_3.Text = 'Predict data';
            app.VisualizedataButton_6 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.VisualizedataButton_6.Text = 'Visualize data';
            app.OnlinedetectionsimulationButton_2 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.OnlinedetectionsimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButton_2Pushed, true);
            app.OnlinedetectionsimulationButton_2.Text = 'Online detection simulation';
            app.MainPanel = uitabgroup(app.UIFigure);
            app.MainPanel.SelectionChangedFcn = createCallbackFcn(app, @MainPanelSelectionChanged, true);
            app.DataTab = uitab(app.MainPanel);
            app.DataTab.Title = '1. Data';
            app.DataPanel = uipanel(app.DataTab);
            app.D_RawTrainingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (raw)')
            xlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Z')
            app.D_RawTestingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (raw)')
            xlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Z')
            app.D_MainButtonGroup = uibuttongroup(app.DataPanel);
            app.D_MainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @D_MainButtonGroupSelectionChanged, true);
            app.D_MainButtonGroup.Tooltip = {''};
            app.D_MainButtonGroup.Title = 'Data Selection';
            app.D_CustomDatasetButton = uiradiobutton(app.D_MainButtonGroup);
            app.D_CustomDatasetButton.Tooltip = {''};
            app.D_CustomDatasetButton.Text = 'Custom Dataset';
            app.D_CustomDatasetButton.Value = true;
            app.D_AvailableDatasetsButton = uiradiobutton(app.D_MainButtonGroup);
            app.D_AvailableDatasetsButton.Tooltip = {'Select one of the datasets which are included in the platform by default.'};
            app.D_AvailableDatasetsButton.Text = 'Available Datasets';
            app.D_BrowseCustomDatasetButton = uibutton(app.D_MainButtonGroup, 'push');
            app.D_BrowseCustomDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @D_BrowseCustomDatasetButtonPushed, true);
            app.D_BrowseCustomDatasetButton.Text = 'Browse...';
            app.D_ImportCustomDatasetLabel = uilabel(app.D_MainButtonGroup);
            app.D_ImportCustomDatasetLabel.Text = 'Folder:';
            app.D_AvailableDatasetsDropDown = uidropdown(app.D_MainButtonGroup);
            app.D_AvailableDatasetsDropDown.Items = {'SWaT', 'UAV', 'AVS'};
            app.D_AvailableDatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @D_AvailableDatasetsDropDownValueChanged, true);
            app.D_AvailableDatasetsDropDown.Enable = 'off';
            app.D_AvailableDatasetsDropDown.Value = 'SWaT';
            app.D_AvailableDatasetsDropDownLabel = uilabel(app.D_MainButtonGroup);
            app.D_AvailableDatasetsDropDownLabel.Enable = 'off';
            app.D_AvailableDatasetsDropDownLabel.Text = 'Dataset:';
            app.D_ImportCustomDatasetEditField = uieditfield(app.D_MainButtonGroup, 'text');
            app.D_LoadDataButton = uibutton(app.DataPanel, 'push');
            app.D_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @D_LoadDataButtonPushed, true);
            app.D_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.D_LoadDataButton.Text = 'Load Data';
            app.D_SWaTSelectSensorLabel = uilabel(app.DataPanel);
            app.D_SWaTSelectSensorLabel.Visible = 'off';
            app.D_SWaTSelectSensorLabel.Text = 'Select sensor:';
            app.D_SWaTSelectSensorDropDown = uidropdown(app.DataPanel);
            app.D_SWaTSelectSensorDropDown.Items = {};
            app.D_SWaTSelectSensorDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SWaTSelectSensorDropDownValueChanged, true);
            app.D_SWaTSelectSensorDropDown.Visible = 'off';
            app.D_SWaTSelectSensorDropDown.Value = {};
            app.D_UAVSensorDataButtonGroup = uibuttongroup(app.DataPanel);
            app.D_UAVSensorDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @D_UAVSensorDataButtonGroupSelectionChanged, true);
            app.D_UAVSensorDataButtonGroup.Title = 'Sensor data selection';
            app.D_UAVSensorDataButtonGroup.Visible = 'off';
            app.D_UAVAccelerometerButton = uiradiobutton(app.D_UAVSensorDataButtonGroup);
            app.D_UAVAccelerometerButton.Text = 'Accelerometer';
            app.D_UAVAccelerometerButton.Value = true;
            app.D_UAVGyroscopeButton = uiradiobutton(app.D_UAVSensorDataButtonGroup);
            app.D_UAVGyroscopeButton.Text = 'Gyroscope';
            app.D_AVSVehicleScenarioButtonGroup = uibuttongroup(app.DataPanel);
            app.D_AVSVehicleScenarioButtonGroup.Title = 'Vehicle scenario';
            app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
            app.D_AVSHighwayButton = uiradiobutton(app.D_AVSVehicleScenarioButtonGroup);
            app.D_AVSHighwayButton.Text = 'Highway';
            app.D_AVSHighwayButton.Value = true;
            app.D_AVSTownButton = uiradiobutton(app.D_AVSVehicleScenarioButtonGroup);
            app.D_AVSTownButton.Text = 'Town';
            app.D_NextButton = uibutton(app.DataPanel, 'push');
            app.D_NextButton.ButtonPushedFcn = createCallbackFcn(app, @D_NextButtonPushed, true);
            app.D_NextButton.Enable = 'off';
            app.D_NextButton.Text = 'Next';
            app.PreprocessingTab = uitab(app.MainPanel);
            app.PreprocessingTab.Title = '2. Preprocessing';
            app.PreprocessingPanel = uipanel(app.PreprocessingTab);
            app.PreprocessingPanel.Enable = 'off';
            app.P_PreprocessedTrainingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Z')
            app.P_PreprocessedTestingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Z')
            app.P_NextButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_NextButton.ButtonPushedFcn = createCallbackFcn(app, @P_NextButtonPushed, true);
            app.P_NextButton.Enable = 'off';
            app.P_NextButton.Text = 'Next';
            app.P_PreviousButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @P_PreviousButtonPushed, true);
            app.P_PreviousButton.Text = 'Previous';
            app.P_MethodButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.P_MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @P_MethodButtonGroupSelectionChanged, true);
            app.P_MethodButtonGroup.Title = 'Preprocessing Method';
            app.P_RawDataButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RawDataButton.Text = 'Raw Data';
            app.P_RawDataButton.Value = true;
            app.P_StandardizeButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_StandardizeButton.Text = 'Standardize';
            app.P_RescaleButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RescaleButton.Text = 'Rescale [0, 1]';
            app.ModelTrainingTab = uitab(app.MainPanel);
            app.ModelTrainingTab.Title = '3. Model Training';
            app.ModelTrainingPanel = uipanel(app.ModelTrainingTab);
            app.MT_NextButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_NextButton.Enable = 'off';
            app.MT_NextButton.Text = 'Next';
            app.MT_NetworkSelectionButtonGroup = uibuttongroup(app.ModelTrainingPanel);
            app.MT_NetworkSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MT_NetworkSelectionButtonGroupSelectionChanged, true);
            app.MT_NetworkSelectionButtonGroup.Title = 'Network Selection';
            app.MT_NewNetworksButton = uiradiobutton(app.MT_NetworkSelectionButtonGroup);
            app.MT_NewNetworksButton.Text = 'New Networks';
            app.MT_NewNetworksButton.Value = true;
            app.MT_PretrainedNetworkButton = uiradiobutton(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkButton.Text = 'Pretrained Network';
            app.MT_PretrainedNetworkBrowseButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
            app.MT_PretrainedNetworkBrowseButton.Text = 'Browse...';
            app.MT_PretrainedNetworkTextArea = uitextarea(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkTextArea.Enable = 'off';
            app.MT_PretrainedNetworkLabel = uilabel(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkLabel.Enable = 'off';
            app.MT_PretrainedNetworkLabel.Text = 'File:';
            app.MT_PretrainedNetworkLoadButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_PretrainedNetworkLoadButton.Enable = 'off';
            app.MT_PretrainedNetworkLoadButton.Text = 'Load Net';
            app.MT_NetworksListBox = uilistbox(app.MT_NetworkSelectionButtonGroup);
            app.MT_NetworksListBox.Items = {};
            app.MT_NetworksListBox.Multiselect = 'on';
            app.MT_NetworksListBox.Value = {};
            app.MT_NetworksLabel = uilabel(app.MT_NetworkSelectionButtonGroup);
            app.MT_NetworksLabel.Text = 'Networks';
            app.MT_AddNetworkButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_AddNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @MT_AddNetworkButtonPushed, true);
            app.MT_AddNetworkButton.Text = 'Add';
            app.MT_ClearNetworksButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_ClearNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MT_ClearNetworksButtonPushed, true);
            app.MT_ClearNetworksButton.Text = 'Clear all';
            app.MT_PreviousButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @MT_PreviousButtonPushed, true);
            app.MT_PreviousButton.Text = 'Previous';
            app.MT_TrainSelectedNetworksButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainSelectedNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainSelectedNetworksButtonPushed, true);
            app.MT_TrainSelectedNetworksButton.Enable = 'off';
            app.MT_TrainSelectedNetworksButton.Text = 'Selected Networks';
            app.MT_TrainingStatusInformationLamp = uilamp(app.ModelTrainingPanel);
            app.MT_TrainAllNetworksButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainAllNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainAllNetworksButtonPushed, true);
            app.MT_TrainAllNetworksButton.Enable = 'off';
            app.MT_TrainAllNetworksButton.Text = 'All Networks';
            app.MT_TrainingStatusLabel = uilabel(app.ModelTrainingPanel);
            app.MT_TrainingStatusLabel.Text = 'Training Status: unfinished';
            app.MT_PreviewPanel = uipanel(app.ModelTrainingPanel);
            app.MT_PreviewUIAxes = uiaxes(app.MT_PreviewPanel);
            app.MT_PreviewUIAxes.XTick = [];
            app.MT_PreviewUIAxes.YTick = [];
            app.MT_PreviewUIAxes.Visible = 'off';
            app.MT_PreviewOptionsPanel = uipanel(app.MT_PreviewPanel);
            app.MT_PreviewOptionsPanel.Title = 'Options / Hyperparameters';
            app.MT_PreviewOptionsPanel.Visible = 'off';
            app.MT_PreviewModelLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewModelLabel.Text = 'Model:';
            app.MT_PreviewModelTypeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewModelTypeLabel.Text = 'Model Type:';
            app.MT_PreviewModelHyperparameter_1_Label = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewModelHyperparameter_1_Label.Text = 'Hidden Units:';
            app.MT_PreviewModelHyperparameter_2_Label = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewModelHyperparameter_2_Label.Text = 'Filter:';
            app.MT_PreviewWindowSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewWindowSizeLabel.Text = 'Window Size:';
            app.MT_PreviewStepSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewStepSizeLabel.Text = 'Step Size:';
            app.MT_PreviewEpochsLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewEpochsLabel.Text = 'Epochs:';
            app.MT_PreviewMinibatchSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewMinibatchSizeLabel.Text = 'Minibatch Size:';
            app.MT_PreviewRatioTrainValLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewRatioTrainValLabel.Text = 'Ratio Train/Val:';
            app.MT_PreviewContentModelLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentModelLabel.Text = '';
            app.MT_PreviewContentModelTypeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentModelTypeLabel.Text = '';
            app.MT_PreviewContentModelHyperparameter_1_Label = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentModelHyperparameter_1_Label.Text = '';
            app.MT_PreviewContentModelHyperparameter_2_Label = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentModelHyperparameter_2_Label.Text = '';
            app.MT_PreviewContentWindowSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentWindowSizeLabel.Text = '';
            app.MT_PreviewContentStepSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentStepSizeLabel.Text = '';
            app.MT_PreviewContentEpochsLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentEpochsLabel.Text = '';
            app.MT_PreviewContentMinibatchSizeLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentMinibatchSizeLabel.Text = '';
            app.MT_PreviewContentRatioTrainValLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentRatioTrainValLabel.Text = '';
            app.MT_ShowNetworkButton = uibutton(app.MT_PreviewPanel, 'push');
            app.MT_ShowNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @MT_ShowNetworkButtonPushed, true);
            app.MT_ShowNetworkButton.Text = 'Show selected Network';
            app.MT_TrainConsecutiveLabel = uilabel(app.ModelTrainingPanel);
            app.MT_TrainConsecutiveLabel.Enable = 'off';
            app.MT_TrainConsecutiveLabel.Text = 'Train cons.:';
            app.MT_TrainSelectedNetworksParallelButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainSelectedNetworksParallelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainSelectedNetworksParallelButtonPushed, true);
            app.MT_TrainSelectedNetworksParallelButton.Enable = 'off';
            app.MT_TrainSelectedNetworksParallelButton.Text = 'Selected Networks';
            app.MT_TrainAllNetworksParallelButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainAllNetworksParallelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainAllNetworksParallelButtonPushed, true);
            app.MT_TrainAllNetworksParallelButton.Enable = 'off';
            app.MT_TrainAllNetworksParallelButton.Text = 'All Networks';
            app.MT_TrainParallelLabel = uilabel(app.ModelTrainingPanel);
            app.MT_TrainParallelLabel.Enable = 'off';
            app.MT_TrainParallelLabel.Text = 'Train para.:';
            app.DetectionEvaluationTab = uitab(app.MainPanel);
            app.DetectionEvaluationTab.Title = '4. Detection Evaluation';
            app.DetectionEvaluationPanel = uipanel(app.DetectionEvaluationTab);
            app.MT_PreviousButton_2 = uibutton(app.DetectionEvaluationPanel, 'push');
            app.MT_PreviousButton_2.Text = 'Previous';
            app.MT_NextButton_2 = uibutton(app.DetectionEvaluationPanel, 'push');
            app.MT_NextButton_2.Enable = 'off';
            app.MT_NextButton_2.Text = 'Next';
            app.DE_TrainedNetworksPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_TrainedNetworksPanel.Title = 'Trained Networks';
            app.DE_TrainedNetworksListBox = uilistbox(app.DE_TrainedNetworksPanel);
            app.DE_TrainedNetworksListBox.Items = {};
            app.DE_TrainedNetworksListBox.Value = {};
            app.SortbyLabel = uilabel(app.DE_TrainedNetworksPanel);
            app.SortbyLabel.Text = 'Sort by:';
            app.DE_SortByDropDown = uidropdown(app.DE_TrainedNetworksPanel);
            app.DE_SortByDropDown.Items = {'precision weighted static', 'precision weighted dynamic', 'precision unweighted static', 'precision unweighted dynamic', 'recall weighted static', 'recall weighted dynamic', 'recall unweighted static', 'recall unweighted dynamic', 'f1score weighted static', 'f1score weighted dynamic', 'f1score unweighted static', 'f1score unweighted dynamic', 'f05score weighted static', 'f05score weighted dynamic', 'f05score unweighted static', 'f05score unweighted dynamic'};
            app.DE_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_SortByDropDownValueChanged, true);
            app.DE_SortByDropDown.Value = 'precision weighted static';
            app.DE_DetectionPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_DetectionPanel.Title = 'Detection';
            app.SelectfaultyDataLabel = uilabel(app.DE_DetectionPanel);
            app.SelectfaultyDataLabel.Text = 'Select faulty Data:';
            app.DE_FaultyDataSelectionDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_FaultyDataSelectionDropDown.Items = {};
            app.DE_FaultyDataSelectionDropDown.Value = {};
            app.DE_RunDetectionForSelectedButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForSelectedButtonPushed, true);
            app.DE_RunDetectionForSelectedButton.Text = 'selected Network';
            app.DE_RunDetectionForLabel = uilabel(app.DE_DetectionPanel);
            app.DE_RunDetectionForLabel.Text = 'Run Detection for:';
            app.DE_RunDetectionForAllButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForAllButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForAllButtonPushed, true);
            app.DE_RunDetectionForAllButton.Text = 'all Networks';
            app.DE_DetectionShownForLabel = uilabel(app.DE_DetectionPanel);
            app.DE_DetectionShownForLabel.Text = 'Detection shown for: None';
            app.DE_DetectionTabGroup = uitabgroup(app.DE_DetectionPanel);
            app.DE_PlotsTab = uitab(app.DE_DetectionTabGroup);
            app.DE_PlotsTab.Title = 'Plots';
            app.DE_StaticThresholdingResidualUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_StaticThresholdingResidualUIAxes, 'Residual')
            xlabel(app.DE_StaticThresholdingResidualUIAxes, 'X')
            ylabel(app.DE_StaticThresholdingResidualUIAxes, 'Y')
            zlabel(app.DE_StaticThresholdingResidualUIAxes, 'Z')
            app.DE_DynamicThresholdingResidualUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_DynamicThresholdingResidualUIAxes, 'Residual')
            xlabel(app.DE_DynamicThresholdingResidualUIAxes, 'X')
            ylabel(app.DE_DynamicThresholdingResidualUIAxes, 'Y')
            zlabel(app.DE_DynamicThresholdingResidualUIAxes, 'Z')
            app.DE_DynamicThresholdingDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_DynamicThresholdingDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_DynamicThresholdingDataUIAxes, 'X')
            ylabel(app.DE_DynamicThresholdingDataUIAxes, 'Y')
            zlabel(app.DE_DynamicThresholdingDataUIAxes, 'Z')
            app.DE_StaticThresholdingDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_StaticThresholdingDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_StaticThresholdingDataUIAxes, 'X')
            ylabel(app.DE_StaticThresholdingDataUIAxes, 'Y')
            zlabel(app.DE_StaticThresholdingDataUIAxes, 'Z')
            app.DE_StaticThresholdingParametricCheckBox = uicheckbox(app.DE_PlotsTab);
            app.DE_StaticThresholdingParametricCheckBox.ValueChangedFcn = createCallbackFcn(app, @DE_StaticThresholdingParametricCheckBoxValueChanged, true);
            app.DE_StaticThresholdingParametricCheckBox.Text = 'Parametric';
            app.DE_DynamicThresholdingLabel = uilabel(app.DE_PlotsTab);
            app.DE_DynamicThresholdingLabel.Text = 'Dynamic Thresholding';
            app.DE_StaticThresholdingLabel = uilabel(app.DE_PlotsTab);
            app.DE_StaticThresholdingLabel.Text = 'Static Thresholding';
            app.DE_StaticThresholdingUITable = uitable(app.DE_PlotsTab);
            app.DE_StaticThresholdingUITable.ColumnName = {'Metric'; 'Weightet'; 'Unweighted'};
            app.DE_StaticThresholdingUITable.RowName = {};
            app.AnomalyPaddingSliderLabel = uilabel(app.DE_PlotsTab);
            app.AnomalyPaddingSliderLabel.Text = {'Anomaly'; 'Padding'};
            app.DE_AnomalyPaddingSlider = uislider(app.DE_PlotsTab);
            app.DE_AnomalyPaddingSlider.Limits = [0 1000];
            app.DE_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @DE_AnomalyPaddingSliderValueChanged, true);
            app.DE_DynamicThresholdingUITable = uitable(app.DE_PlotsTab);
            app.DE_DynamicThresholdingUITable.ColumnName = {'Metric'; 'Weightet'; 'Unweighted'};
            app.DE_DynamicThresholdingUITable.RowName = {};
            app.DE_WindowSizeSlider = uislider(app.DE_PlotsTab);
            app.DE_WindowSizeSlider.Limits = [0 1000];
            app.DE_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_WindowSizeSliderValueChanged, true);
            app.WindowSizeSliderLabel = uilabel(app.DE_PlotsTab);
            app.WindowSizeSliderLabel.Text = {'Window'; 'Size'};
            app.DE_ZRangeSlider = uislider(app.DE_PlotsTab);
            app.DE_ZRangeSlider.Limits = [1 10];
            app.DE_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_ZRangeSliderValueChanged, true);
            app.DE_ZRangeSlider.Value = 1;
            app.zrangeSliderLabel = uilabel(app.DE_PlotsTab);
            app.zrangeSliderLabel.Text = 'z-range';
            app.DE_TableTab = uitab(app.DE_DetectionTabGroup);
            app.DE_TableTab.Title = 'Table';
            app.DE_FullDetectionUITable = uitable(app.DE_TableTab);
            app.DE_FullDetectionUITable.ColumnName = {'Metric'};
            app.DE_FullDetectionUITable.RowName = {};
            app.DynamicSwitchTab = uitab(app.MainPanel);
            app.DynamicSwitchTab.Title = '5. Dynamic Switch';
            app.selectedModelsContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteMenu = uimenu(app.selectedModelsContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.selectedModelsContextMenu
            app.MT_NetworksListBox.ContextMenu = app.selectedModelsContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end