classdef DatasetVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADDatasetVisualizationUIFigure  matlab.ui.Figure
        VisualizationPanel            matlab.ui.container.Panel
        SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_2           matlab.ui.control.Label
        SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_2        matlab.ui.control.Label
        SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel             matlab.ui.control.Label
        SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel          matlab.ui.control.Label
        TrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        TestingDatasetPreviewUIAxes   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% App Status Information        
        
        info_numTrainFiles = 0;             % Number of training files
        info_numTestFiles = 0;              % Number of all testing files
        info_numChannels = 0;           % Number of channels in the dataset
        
        channelNames

        dataTrain
        timestampsTrain
        labelsTrain
        filesTrain

        dataTest
        timestampsTest
        labelsTest
        filesTest

        augmentationEnabled
        augmentationMode
        augmentationIntensity
        augmentedTrainingEnabled      
        
    end
    
    methods (Access = private)
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            switch dataset
                case 'train'
                    axes = app.TrainingDatasetPreviewUIAxes;
                    data = app.dataTrain{file_idx, 1}(:, channel_idx);
                case 'test'
                    axes = app.TestingDatasetPreviewUIAxes;
                    data = app.dataTest{file_idx, 1}(:, channel_idx);
                
            end
            
            cla(axes);
            plot(axes, data);
        end


        function createPreviewLegend(app, dataset, file_idx)
            switch dataset
                case 'train'
                    axes = app.TrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{file_idx, 1});
                case 'test'
                    axes = app.TestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTest{file_idx, 1});
               
            end
            
            YLims = get(axes, 'YLim');
            limits = [];
            for i = 1:size(indices, 2)
                limits(1,i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limits)
                patch(axes, indices, limits, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end 

        function resetAllData(app)
            app.dataTrain = [];
            app.dataTest = [];
            app.timestampsTrain = [];  
            app.timestampsTest = [];
            app.labelsTrain = [];  
            app.labelsTest = [];
            app.filesTrain = [];            
            app.filesTest = [];
        end

        function updatePreviewData(app, dataset)
            switch dataset
                case 'train'
                    file = app.SelectedFileTrainDropDown.Value;
                    [~, file_idx] = ismember(file, app.SelectedFileTrainDropDown.Items);
                    channel = app.SelectedChannelTrainDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.SelectedChannelTrainDropDown.Items);
                case 'test'
                    file = app.SelectedFileTestDropDown.Value;
                    [~, file_idx] = ismember(file, app.SelectedFileTestDropDown.Items);
                    channel = app.SelectedChannelTestDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.SelectedChannelTestDropDown.Items);
             
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path, augmentationEnabled, augmentationMode, augmentationIntensity, augmentedTrainingEnabled)
            app.augmentationEnabled = augmentationEnabled;
            app.augmentationMode = augmentationMode;
            app.augmentationIntensity = augmentationIntensity;
            app.augmentedTrainingEnabled = augmentedTrainingEnabled;
        
            
            cla(app.TrainingDatasetPreviewUIAxes);
            cla(app.TestingDatasetPreviewUIAxes);
            
            app.resetAllData();          
            
            try
                [app.dataTrain, ...
                    app.timestampsTrain, ...
                    app.labelsTrain, ...
                    app.filesTrain, ...
                    app.dataTest, ...
                    app.timestampsTest, ...
                    app.labelsTest, ...
                    app.filesTest, ...
                    app.channelNames] = loadCustomDataset(path);
            catch
                uialert(app.TSADDatasetVisualizationUIFigure, ['No testing or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: train, test. The folders should include .csv files with fields:' ...
                    'timestamp, value1, value2, ..., is_anomaly'], ...
                            'Data Error', Icon='error');
                return;
            end
            
            if (app.augmentationEnabled)
                [app.dataTrain, app.dataTest] = augmentData(app.dataTrain, app.dataTest, app.augmentationMode,app.augmentationIntensity,app.augmentedTrainingEnabled);
            end


            if ~isempty(app.dataTrain)
                app.plotLoadedData('train', 1, 1);
                app.createPreviewLegend('train', 1);

                app.info_numChannels = size(app.dataTrain{1, 1}, 2);

                app.SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.dataTest)
                app.plotLoadedData('test', 1, 1);
                app.createPreviewLegend('test', 1);

                app.info_numChannels = size(app.dataTest{1, 1}, 2);

                app.SelectedFileTestDropDown.Items = app.filesTest;
            end

            app.SelectedChannelTestDropDown.Items = app.channelNames;
            app.SelectedChannelTrainDropDown.Items = app.channelNames;
            
        end

        % Value changed function: SelectedFileTrainDropDown
        function SelectedFileTrainDropDownValueChanged(app, event)
            
            app.updatePreviewData('train');
        end

        % Value changed function: SelectedFileTestDropDown
        function SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
            
        end

        % Value changed function: SelectedChannelTrainDropDown
        function SelectedChannelTrainDropDownValueChanged(app, event)
        
            app.updatePreviewData('train');
        end

        % Value changed function: SelectedChannelTestDropDown
        function SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADDatasetVisualizationUIFigure = uifigure('Visible', 'off');
            app.TSADDatasetVisualizationUIFigure.Name = 'TSAD - Dataset Visualization';
            app.TSADDatasetVisualizationUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.VisualizationPanel = uipanel(app.TSADDatasetVisualizationUIFigure);
            app.TestingDatasetPreviewUIAxes = uiaxes(app.VisualizationPanel);
            title(app.TestingDatasetPreviewUIAxes, 'Preview: Testing Dataset')
            xlabel(app.TestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.TestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.TestingDatasetPreviewUIAxes, 'Z')
            app.TrainingDatasetPreviewUIAxes = uiaxes(app.VisualizationPanel);
            title(app.TrainingDatasetPreviewUIAxes, 'Preview: Training Dataset')
            xlabel(app.TrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.TrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.TrainingDatasetPreviewUIAxes, 'Z')
            app.SelectedChannelLabel = uilabel(app.VisualizationPanel);
            app.SelectedChannelLabel.Text = 'Selected Channel:';
            app.SelectedChannelTrainDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedChannelTrainDropDown.Items = {};
            app.SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelTrainDropDownValueChanged, true);
            app.SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel = uilabel(app.VisualizationPanel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.SelectedFileTrainDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedFileTrainDropDown.Items = {};
            app.SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedFileTrainDropDownValueChanged, true);
            app.SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_2 = uilabel(app.VisualizationPanel);
            app.SelectedChannelLabel_2.Text = 'Selected Channel:';
            app.SelectedChannelTestDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedChannelTestDropDown.Items = {};
            app.SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelTestDropDownValueChanged, true);
            app.SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_2 = uilabel(app.VisualizationPanel);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.SelectedFileTestDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedFileTestDropDown.Items = {};
            app.SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedFileTestDropDownValueChanged, true);
            app.SelectedFileTestDropDown.Value = {};
            % Show the figure after all components are created
            app.TSADDatasetVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetVisualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TSADDatasetVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADDatasetVisualizationUIFigure)
        end
    end
end