classdef ModelSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADModelSelectionUIFigure     matlab.ui.Figure
        AddModelPanel                  matlab.ui.container.Panel
        CloseButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        DeepNeuralNetworksTab          matlab.ui.container.Tab
        DNN_DimensionalityLabel        matlab.ui.control.Label
        DNN_DimensionalityDropDown     matlab.ui.control.DropDown
        DNN_ScoringFunctionDropDown    matlab.ui.control.DropDown
        ScoringFunctionDropDownLabel   matlab.ui.control.Label
        DNN_ModelSelectionDropDown     matlab.ui.control.DropDown
        ModelLabel                     matlab.ui.control.Label
        DNN_ReconstructionErrorTypeLabel  matlab.ui.control.Label
        DNN_ReconstructionErrorTypeDropDown  matlab.ui.control.DropDown
        DNN_ModelHyperparametersPanel  matlab.ui.container.Panel
        DNN_ModelParameterLabel_4      matlab.ui.control.Label
        DNN_ModelParameterLabel_3      matlab.ui.control.Label
        DNN_ModelParameterEditField_4  matlab.ui.control.NumericEditField
        DNN_ModelParameterEditField_3  matlab.ui.control.NumericEditField
        DNN_ModelParameterLabel_2      matlab.ui.control.Label
        DNN_ModelParameterLabel_1      matlab.ui.control.Label
        DNN_ModelParameterEditField_2  matlab.ui.control.NumericEditField
        DNN_ModelParameterEditField_1  matlab.ui.control.NumericEditField
        DNN_DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        DNN_LearningRateEditField      matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel     matlab.ui.control.Label
        DNN_RatioLabel                 matlab.ui.control.Label
        DNN_RatioEditField             matlab.ui.control.NumericEditField
        DNN_MinibatchSizeEditField     matlab.ui.control.NumericEditField
        MinibatchSizeEditFieldLabel    matlab.ui.control.Label
        DNN_EpochsEditField            matlab.ui.control.NumericEditField
        EpochsEditFieldLabel           matlab.ui.control.Label
        DNN_StepSizeEditField          matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel         matlab.ui.control.Label
        DNN_WindowSizeEditField        matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        DNN_UseValDataCheckBox         matlab.ui.control.CheckBox
        OtherTab                       matlab.ui.container.Tab
        O_DimensionalityLabel          matlab.ui.control.Label
        O_DimensionalityDropDown       matlab.ui.control.DropDown
        O_ModelSelectionDropDown       matlab.ui.control.DropDown
        ModelLabel_2                   matlab.ui.control.Label
        O_DetectionModeButtonGroup     matlab.ui.container.ButtonGroup
        O_NoveltyDetectionButton       matlab.ui.control.RadioButton
        O_OutlierDetectionButton       matlab.ui.control.RadioButton
        O_MerlinDetectionModeRadioButtonGroup  matlab.ui.container.ButtonGroup
        O_MerlinMergeAllPredictionsButton  matlab.ui.control.RadioButton
        O_MerlinUseMedianDiscordLocationsButton  matlab.ui.control.RadioButton
        O_ModelHyperparametersPanel    matlab.ui.container.Panel
        O_ModelParameterEditField_1    matlab.ui.control.NumericEditField
        O_ModelParameterLabel_4        matlab.ui.control.Label
        O_ModelParameterEditField_4    matlab.ui.control.NumericEditField
        O_ModelParameterLabel_3        matlab.ui.control.Label
        O_ModelParameterEditField_3    matlab.ui.control.NumericEditField
        O_ModelParameterLabel_2        matlab.ui.control.Label
        O_ModelParameterLabel_1        matlab.ui.control.Label
        O_ModelParameterEditField_2    matlab.ui.control.NumericEditField
        O_DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        O_StepSizeLabel                matlab.ui.control.Label
        O_WindowSizeLabel              matlab.ui.control.Label
        O_UseSlidingWindowCheckBox     matlab.ui.control.CheckBox
        O_StepSizeEditField            matlab.ui.control.NumericEditField
        O_WindowSizeEditField          matlab.ui.control.NumericEditField
        AddToModelSelectionButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % The app that called this app
        currentType = "DNN";
    end
    
    methods (Access = private)
        
        function showHyperparametersFor(app, model)
            % Displays amd configures the necessary edit fields for the selected model
            switch model
                case "FC-AE"                    
                    app.DNN_ModelParameterEditField_1.Value = 32;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Neurons";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                    app.DNN_ReconstructionErrorTypeDropDown.Visible = "on";
                    app.DNN_ReconstructionErrorTypeLabel.Visible = "on";
                case "LSTM (reconstruction)"
                    app.DNN_ModelParameterEditField_1.Value = 64;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Hidden Units";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                    app.DNN_ReconstructionErrorTypeDropDown.Visible = "on";
                    app.DNN_ReconstructionErrorTypeLabel.Visible = "on";
                case "Hybrid CNN-LSTM (reconstruction)"
                    app.DNN_ModelParameterEditField_1.Value = 64;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Hidden Units";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                    app.DNN_ModelParameterEditField_2.Value = 32;
                    app.DNN_ModelParameterEditField_2.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_2.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_2.Visible = "on";
                    app.DNN_ModelParameterLabel_2.Visible = "on";
                    app.DNN_ModelParameterLabel_2.Text = "Filter";
                    app.DNN_ReconstructionErrorTypeDropDown.Visible = "on";
                    app.DNN_ReconstructionErrorTypeLabel.Visible = "on";
                case "TCN-AE"
                    app.DNN_ModelParameterEditField_1.Value = 32;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Filter";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                    app.DNN_WindowSizeEditField.Value = 100;
                    app.DNN_ReconstructionErrorTypeDropDown.Visible = "on";
                    app.DNN_ReconstructionErrorTypeLabel.Visible = "on";
                case "LSTM"
                    app.DNN_ModelParameterEditField_1.Value = 64;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Hidden Units";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                case "Hybrid CNN-LSTM"
                    app.DNN_ModelParameterEditField_1.Value = 64;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Hidden Units";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                    app.DNN_ModelParameterEditField_2.Value = 32;
                    app.DNN_ModelParameterEditField_2.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_2.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_2.Visible = "on";
                    app.DNN_ModelParameterLabel_2.Text = "Filter";
                    app.DNN_ModelParameterLabel_2.Visible = "on";
                case "GRU"
                    app.DNN_ModelParameterEditField_1.Value = 64;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Hidden Units";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                case "CNN (DeepAnT)"
                    app.DNN_ModelParameterEditField_1.Value = 8;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Filter";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                case "ResNet"
                    app.DNN_ModelParameterEditField_1.Value = 32;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Filter";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                case "MLP"
                    app.DNN_ModelParameterEditField_1.Value = 32;
                    app.DNN_ModelParameterEditField_1.Limits = [1, inf];
                    app.DNN_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.DNN_ModelParameterEditField_1.Visible = "on";
                    app.DNN_ModelParameterLabel_1.Text = "Neurons";
                    app.DNN_ModelParameterLabel_1.Visible = "on";
                case "iForest"
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                        if app.O_DetectionModeButtonGroup.SelectedObject ~= app.O_OutlierDetectionButton
                            app.O_StepSizeEditField.Visible = "on";
                            app.O_StepSizeLabel.Visible = "on";
                        end
                    end
                    app.O_ModelParameterEditField_1.Value = 100;
                    app.O_ModelParameterEditField_1.Limits = [1, inf];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "iTrees";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_ModelParameterEditField_2.Value = 128;
                    app.O_ModelParameterEditField_2.Limits = [1, inf];
                    app.O_ModelParameterEditField_2.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_2.Visible = "on";
                    app.O_ModelParameterLabel_2.Text = "Observations per iTree";
                    app.O_ModelParameterLabel_2.Visible = "on";
                    app.O_DimensionalityDropDown.Visible = "on";
                    app.O_DetectionModeButtonGroup.Visible = "on";
                case "OC-SVM"
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                        if app.O_DetectionModeButtonGroup.SelectedObject ~= app.O_OutlierDetectionButton
                            app.O_StepSizeEditField.Visible = "on";
                            app.O_StepSizeLabel.Visible = "on";
                        end
                    end
                    app.O_DimensionalityDropDown.Visible = "on";
                    app.O_DetectionModeButtonGroup.Visible = "on";
                case "LOF"
                    app.O_ModelParameterEditField_1.Value = 10;
                    app.O_ModelParameterEditField_1.Limits = [1, inf];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "k";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                    end
                    app.O_DimensionalityDropDown.Visible = "on";
                case "LDOF"
                    app.O_ModelParameterEditField_1.Value = 10;
                    app.O_ModelParameterEditField_1.Limits = [1, inf];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "k";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                    end
                    app.O_DimensionalityDropDown.Visible = "on";
                case "ABOD"
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                    end
                    app.O_DimensionalityDropDown.Visible = "on";
                case "MERLIN"
                    app.O_ModelParameterEditField_1.Value = 90;
                    app.O_ModelParameterEditField_1.Limits = [1, inf];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "minL";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_ModelParameterEditField_2.Value = 100;
                    app.O_ModelParameterEditField_2.Limits = [1, inf];
                    app.O_ModelParameterEditField_2.RoundFractionalValues = "on";
                    app.O_ModelParameterEditField_2.Visible = "on";
                    app.O_ModelParameterLabel_2.Text = "maxL";
                    app.O_ModelParameterLabel_2.Visible = "on";
                    app.O_MerlinDetectionModeRadioButtonGroup.Visible = "on";
                case "Grubbs test"
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterEditField_1.Value = 0.5;
                    app.O_ModelParameterEditField_1.Limits = [0, 1];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "off";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "alpha";
                case "over-sampling PCA"
                    app.O_ModelParameterEditField_1.Value = 0.1;
                    app.O_ModelParameterEditField_1.Limits = [0, 1];
                    app.O_ModelParameterEditField_1.RoundFractionalValues = "off";
                    app.O_ModelParameterEditField_1.Visible = "on";
                    app.O_ModelParameterLabel_1.Text = "Oversampling Ratio";
                    app.O_ModelParameterLabel_1.Visible = "on";
                    app.O_UseSlidingWindowCheckBox.Visible = "on";
                    if app.O_UseSlidingWindowCheckBox.Value
                        app.O_WindowSizeEditField.Visible = "on";
                        app.O_WindowSizeLabel.Visible = "on";
                    end
                    app.O_DimensionalityDropDown.Visible = "on";
            end
        end
        
        function resetHyperparameters_DNN(app)
            app.DNN_ModelParameterEditField_1.Visible = "off";
            app.DNN_ModelParameterEditField_1.Limits = [-inf, inf];
            app.DNN_ModelParameterEditField_1.RoundFractionalValues = "off";
            app.DNN_ModelParameterEditField_2.Visible = "off";
            app.DNN_ModelParameterEditField_2.Limits = [-inf, inf];
            app.DNN_ModelParameterEditField_2.RoundFractionalValues = "off";
            app.DNN_ModelParameterEditField_3.Visible = "off";
            app.DNN_ModelParameterEditField_3.Limits = [-inf, inf];
            app.DNN_ModelParameterEditField_3.RoundFractionalValues = "off";
            app.DNN_ModelParameterEditField_4.Visible = "off";
            app.DNN_ModelParameterEditField_4.Limits = [-inf, inf];
            app.DNN_ModelParameterEditField_4.RoundFractionalValues = "off";
            app.DNN_ModelParameterLabel_1.Text = "";
            app.DNN_ModelParameterLabel_2.Text = "";
            app.DNN_ModelParameterLabel_3.Text = "";
            app.DNN_ModelParameterLabel_4.Text = "";
            app.DNN_ModelParameterLabel_1.Visible = "off";
            app.DNN_ModelParameterLabel_2.Visible = "off";
            app.DNN_ModelParameterLabel_3.Visible = "off";
            app.DNN_ModelParameterLabel_4.Visible = "off";
            app.DNN_ReconstructionErrorTypeDropDown.Visible = "off";
            app.DNN_ReconstructionErrorTypeLabel.Visible = "off";
        end

        function resetHyperparameters_O(app)
            app.O_ModelParameterEditField_1.Visible = "off";
            app.O_ModelParameterEditField_1.Limits = [-inf, inf];
            app.O_ModelParameterEditField_1.RoundFractionalValues = "off";
            app.O_ModelParameterEditField_2.Visible = "off";
            app.O_ModelParameterEditField_2.Limits = [-inf, inf];
            app.O_ModelParameterEditField_2.RoundFractionalValues = "off";
            app.O_ModelParameterEditField_3.Visible = "off";
            app.O_ModelParameterEditField_3.Limits = [-inf, inf];
            app.O_ModelParameterEditField_3.RoundFractionalValues = "off";
            app.O_ModelParameterEditField_4.Visible = "off";
            app.O_ModelParameterEditField_4.Limits = [-inf, inf];
            app.O_ModelParameterEditField_4.RoundFractionalValues = "off";
            app.O_ModelParameterLabel_1.Text = "";
            app.O_ModelParameterLabel_2.Text = "";
            app.O_ModelParameterLabel_3.Text = "";
            app.O_ModelParameterLabel_4.Text = "";
            app.O_ModelParameterLabel_1.Visible = "off";
            app.O_ModelParameterLabel_2.Visible = "off";
            app.O_ModelParameterLabel_3.Visible = "off";
            app.O_ModelParameterLabel_4.Visible = "off";
            app.O_UseSlidingWindowCheckBox.Visible = "off";
            app.O_WindowSizeEditField.Visible = "off";
            app.O_WindowSizeLabel.Visible = "off";
            app.O_StepSizeEditField.Visible = "off";
            app.O_StepSizeLabel.Visible = "off";
            app.O_DimensionalityDropDown.Visible = "off";
            app.O_DetectionModeButtonGroup.Visible = "off";
            app.O_MerlinDetectionModeRadioButtonGroup.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            % Creates the modelOptions struct with all necessary modelOptions to
            % identify the configured model and adds it to tsad platform
            % training panel
            
            if app.TabGroup.SelectedTab == app.DeepNeuralNetworksTab
                modelOptions.type = "deep-learning";
                modelOptions.name = app.DNN_ModelSelectionDropDown.Value;
    
                switch app.DNN_ModelSelectionDropDown.Value
                    case "FC-AE"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "reconstructive";
                        modelOptions.dataType = 1;
    
                        modelOptions.hyperparameters.neurons = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                        modelOptions.hyperparameters.reconstructionErrorType = app.DNN_ReconstructionErrorTypeDropDown.Value;
                    case "LSTM (reconstruction)"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "reconstructive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.hiddenUnits = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                        modelOptions.hyperparameters.reconstructionErrorType = app.DNN_ReconstructionErrorTypeDropDown.Value;
                    case "Hybrid CNN-LSTM (reconstruction)"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "reconstructive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.hiddenUnits = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.filter = app.DNN_ModelParameterEditField_2.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                        modelOptions.hyperparameters.reconstructionErrorType = app.DNN_ReconstructionErrorTypeDropDown.Value;
                    case "TCN-AE"
                        if mod(app.DNN_WindowSizeEditField.Value, 4) ~= 0
                            uialert(app.TSADModelSelectionUIFigure, "Window size must be divisible by 4 for the TCN-AE.", "Parameter Error", Icon="error");
                            return;
                        end
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "reconstructive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.filter = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                        modelOptions.hyperparameters.reconstructionErrorType = app.DNN_ReconstructionErrorTypeDropDown.Value;
                    case "LSTM"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.hiddenUnits = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                    case "Hybrid CNN-LSTM"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.hiddenUnits = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.filter = app.DNN_ModelParameterEditField_2.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                    case "GRU"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 2;
    
                        modelOptions.hyperparameters.hiddenUnits = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                    case "CNN (DeepAnT)"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 3;
    
                        modelOptions.hyperparameters.filter = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                    case "ResNet"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 3;
    
                        modelOptions.hyperparameters.filter = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                    case "MLP"
                        modelOptions.dimensionality = app.DNN_DimensionalityDropDown.Value;
                        modelOptions.learningType = "semi-supervised";
                        modelOptions.outputsLabels = false;
                        modelOptions.modelType = "predictive";
                        modelOptions.dataType = 1;
                        
                        modelOptions.hyperparameters.neurons = app.DNN_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.windowSize = app.DNN_WindowSizeEditField.Value;
                        modelOptions.hyperparameters.stepSize = app.DNN_StepSizeEditField.Value;
                        modelOptions.hyperparameters.epochs = app.DNN_EpochsEditField.Value;
                        modelOptions.hyperparameters.minibatchSize = app.DNN_MinibatchSizeEditField.Value;
                        modelOptions.hyperparameters.learningRate = app.DNN_LearningRateEditField.Value;
            
                        if app.DNN_UseValDataCheckBox.Value
                            modelOptions.hyperparameters.ratioTrainVal = app.DNN_RatioEditField.Value;
                        else
                            modelOptions.hyperparameters.ratioTrainVal = 0;
                        end
                        modelOptions.hyperparameters.scoringFunction = app.DNN_ScoringFunctionDropDown.Value;
                end
            else
                modelOptions.name = app.O_ModelSelectionDropDown.Value;
    
                switch app.O_ModelSelectionDropDown.Value
                    case "iForest"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        if app.O_DetectionModeButtonGroup.SelectedObject == app.O_OutlierDetectionButton
                            modelOptions.learningType = "unsupervised";
                        else
                            modelOptions.learningType = "semi-supervised";
                        end
                        modelOptions.outputsLabels = false;
    
                        modelOptions.hyperparameters.iTrees = app.O_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.observationsPeriTree = app.O_ModelParameterEditField_2.Value;
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                            if app.O_DetectionModeButtonGroup.SelectedObject == app.O_NoveltyDetectionButton 
                                modelOptions.hyperparameters.stepSize = app.O_StepSizeEditField.Value;
                            end
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "OC-SVM"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        if app.O_DetectionModeButtonGroup.SelectedObject == app.O_OutlierDetectionButton
                            modelOptions.learningType = "unsupervised";
                        else
                            modelOptions.learningType = "semi-supervised";
                        end
                        modelOptions.outputsLabels = false;
                        
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                            if app.O_DetectionModeButtonGroup.SelectedObject == app.O_NoveltyDetectionButton 
                                modelOptions.hyperparameters.stepSize = app.O_StepSizeEditField.Value;
                            end
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "ABOD"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = false;
    
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "LOF"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = false;
    
                        modelOptions.hyperparameters.k = app.O_ModelParameterEditField_1.Value;
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "LDOF"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = false;
    
                        modelOptions.hyperparameters.k = app.O_ModelParameterEditField_1.Value;
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "MERLIN"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dimensionality = false;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = true;
                        modelOptions.useSlidingWindow = false;
    
                        modelOptions.hyperparameters.minL = app.O_ModelParameterEditField_1.Value;
                        modelOptions.hyperparameters.maxL = app.O_ModelParameterEditField_2.Value;
                        
                        if app.O_MerlinDetectionModeRadioButtonGroup.SelectedObject == app.O_MerlinUseMedianDiscordLocationsButton
                            modelOptions.hyperparameters.mode = "median";
                        else
                            modelOptions.hyperparameters.mode = "merged";
                        end
                    case "over-sampling PCA"
                        modelOptions.type = "classic-machine-learning";
                        modelOptions.dataType = 1;
                        modelOptions.dimensionality = app.O_DimensionalityDropDown.Value;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = false;
                        
                        modelOptions.hyperparameters.ratioOversample = app.O_ModelParameterEditField_1.Value;
                        if app.O_UseSlidingWindowCheckBox.Value
                            modelOptions.useSlidingWindow = true;
                            modelOptions.hyperparameters.windowSize = app.O_WindowSizeEditField.Value;
                        else
                            modelOptions.useSlidingWindow = false;
                        end
                    case "Grubbs test"
                        modelOptions.type = "statistics";
                        modelOptions.dimensionality = false;
                        modelOptions.learningType = "unsupervised";
                        modelOptions.outputsLabels = true;
    
                        modelOptions.hyperparameters.alpha = app.O_ModelParameterEditField_1.Value;
                        modelOptions.useSlidingWindow = false;
                end
            end
            
            % Add to tsad platform model selection list
            app.callingApp.addModelToList(modelOptions);
        end

        % Value changed function: DNN_ModelSelectionDropDown
        function DNN_ModelSelectionDropDownValueChanged(app, event)
            value = app.DNN_ModelSelectionDropDown.Value;

            app.resetHyperparameters_DNN();
            app.showHyperparametersFor(value);
        end

        % Value changed function: DNN_UseValDataCheckBox
        function DNN_UseValDataCheckBoxValueChanged(app, event)
            value = app.DNN_UseValDataCheckBox.Value;
            if value
                app.DNN_RatioEditField.Enable = "on";
                app.DNN_RatioLabel.Enable = "on";
            else
                app.DNN_RatioEditField.Enable = "off";
                app.DNN_RatioLabel.Enable = "off";
            end
        end

        % Value changed function: O_ModelSelectionDropDown
        function O_ModelSelectionDropDownValueChanged(app, event)
            value = app.O_ModelSelectionDropDown.Value;

            app.resetHyperparameters_O();
            app.showHyperparametersFor(value);
        end

        % Value changed function: O_UseSlidingWindowCheckBox
        function O_UseSlidingWindowCheckBoxValueChanged(app, event)
            if app.O_UseSlidingWindowCheckBox.Value
               app.O_WindowSizeEditField.Visible = "on";
               app.O_WindowSizeLabel.Visible = "on";
                
               % Enable step-size edit field only for semi-supervised models
               if ismember(app.O_ModelSelectionDropDown.Value, ["iForest", "OC-SVM"])
                   if app.O_DetectionModeButtonGroup.SelectedObject ~= app.O_OutlierDetectionButton
                       app.O_StepSizeEditField.Visible = "on";
                       app.O_StepSizeLabel.Visible = "on";
                   end
               end
           else
               app.O_WindowSizeEditField.Visible = "off";
               app.O_WindowSizeLabel.Visible = "off";
               app.O_StepSizeEditField.Visible = "off";
               app.O_StepSizeLabel.Visible = "off";
           end  
        end

        % Selection changed function: O_DetectionModeButtonGroup
        function O_DetectionModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.O_DetectionModeButtonGroup.SelectedObject;
            if selectedButton == app.O_OutlierDetectionButton
                app.O_StepSizeEditField.Visible = "off";
                app.O_StepSizeLabel.Visible = "off";
            else
                if app.O_UseSlidingWindowCheckBox.Value
                    app.O_StepSizeEditField.Visible = "on";
                    app.O_StepSizeLabel.Visible = "on";
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADModelSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADModelSelectionUIFigure.Name = 'TSAD - Model Selection';
            app.TSADModelSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.AddModelPanel = uipanel(app.TSADModelSelectionUIFigure);
            app.AddToModelSelectionButton = uibutton(app.AddModelPanel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.TabGroup = uitabgroup(app.AddModelPanel);
            app.DeepNeuralNetworksTab = uitab(app.TabGroup);
            app.DeepNeuralNetworksTab.Title = 'Deep Neural Networks';
            app.DNN_DataAndTrainingHyperparametersPanel = uipanel(app.DeepNeuralNetworksTab);
            app.DNN_DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.DNN_UseValDataCheckBox = uicheckbox(app.DNN_DataAndTrainingHyperparametersPanel);
            app.DNN_UseValDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @DNN_UseValDataCheckBoxValueChanged, true);
            app.DNN_UseValDataCheckBox.Text = 'Use Val Data';
            app.WindowSizeEditFieldLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.DNN_WindowSizeEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_WindowSizeEditField.Limits = [1 Inf];
            app.DNN_WindowSizeEditField.RoundFractionalValues = 'on';
            app.DNN_WindowSizeEditField.Value = 100;
            app.StepSizeEditFieldLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.DNN_StepSizeEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_StepSizeEditField.Limits = [1 Inf];
            app.DNN_StepSizeEditField.RoundFractionalValues = 'on';
            app.DNN_StepSizeEditField.Value = 1;
            app.EpochsEditFieldLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.DNN_EpochsEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_EpochsEditField.Limits = [1 Inf];
            app.DNN_EpochsEditField.RoundFractionalValues = 'on';
            app.DNN_EpochsEditField.Value = 50;
            app.MinibatchSizeEditFieldLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.MinibatchSizeEditFieldLabel.Text = 'Minibatch Size';
            app.DNN_MinibatchSizeEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_MinibatchSizeEditField.Limits = [1 Inf];
            app.DNN_MinibatchSizeEditField.RoundFractionalValues = 'on';
            app.DNN_MinibatchSizeEditField.Value = 64;
            app.DNN_RatioEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_RatioEditField.Limits = [0 1];
            app.DNN_RatioEditField.Enable = 'off';
            app.DNN_RatioEditField.Value = 0.2;
            app.DNN_RatioLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.DNN_RatioLabel.Enable = 'off';
            app.DNN_RatioLabel.Text = 'Ratio';
            app.LearningRateEditFieldLabel = uilabel(app.DNN_DataAndTrainingHyperparametersPanel);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.DNN_LearningRateEditField = uieditfield(app.DNN_DataAndTrainingHyperparametersPanel, 'numeric');
            app.DNN_LearningRateEditField.Limits = [0 1];
            app.DNN_LearningRateEditField.Value = 0.001;
            app.DNN_ModelHyperparametersPanel = uipanel(app.DeepNeuralNetworksTab);
            app.DNN_ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.DNN_ModelParameterEditField_1 = uieditfield(app.DNN_ModelHyperparametersPanel, 'numeric');
            app.DNN_ModelParameterEditField_1.Limits = [0 Inf];
            app.DNN_ModelParameterEditField_1.RoundFractionalValues = 'on';
            app.DNN_ModelParameterEditField_1.Value = 32;
            app.DNN_ModelParameterEditField_2 = uieditfield(app.DNN_ModelHyperparametersPanel, 'numeric');
            app.DNN_ModelParameterEditField_2.Limits = [0 Inf];
            app.DNN_ModelParameterEditField_2.RoundFractionalValues = 'on';
            app.DNN_ModelParameterEditField_2.Visible = 'off';
            app.DNN_ModelParameterEditField_2.Value = 32;
            app.DNN_ModelParameterLabel_1 = uilabel(app.DNN_ModelHyperparametersPanel);
            app.DNN_ModelParameterLabel_1.Text = 'Neurons';
            app.DNN_ModelParameterLabel_2 = uilabel(app.DNN_ModelHyperparametersPanel);
            app.DNN_ModelParameterLabel_2.Visible = 'off';
            app.DNN_ModelParameterLabel_2.Text = 'Filter';
            app.DNN_ModelParameterEditField_3 = uieditfield(app.DNN_ModelHyperparametersPanel, 'numeric');
            app.DNN_ModelParameterEditField_3.Limits = [0 Inf];
            app.DNN_ModelParameterEditField_3.RoundFractionalValues = 'on';
            app.DNN_ModelParameterEditField_3.Visible = 'off';
            app.DNN_ModelParameterEditField_4 = uieditfield(app.DNN_ModelHyperparametersPanel, 'numeric');
            app.DNN_ModelParameterEditField_4.Limits = [0 Inf];
            app.DNN_ModelParameterEditField_4.RoundFractionalValues = 'on';
            app.DNN_ModelParameterEditField_4.Visible = 'off';
            app.DNN_ModelParameterLabel_3 = uilabel(app.DNN_ModelHyperparametersPanel);
            app.DNN_ModelParameterLabel_3.Text = '';
            app.DNN_ModelParameterLabel_4 = uilabel(app.DNN_ModelHyperparametersPanel);
            app.DNN_ModelParameterLabel_4.Visible = 'off';
            app.DNN_ModelParameterLabel_4.Text = '';
            app.DNN_ReconstructionErrorTypeDropDown = uidropdown(app.DeepNeuralNetworksTab);
            app.DNN_ReconstructionErrorTypeDropDown.Items = {'Median point-wise Values', 'Median point-wise Errors', 'Mean Subsequence MAE', 'Mean Subsequence MSE', 'Mean Subsequence RMSE'};
            app.DNN_ReconstructionErrorTypeDropDown.Tooltip = {'This defines how overlapping reconstructed values are combined to produce a single value per time-point'};
            app.DNN_ReconstructionErrorTypeDropDown.Value = 'Median point-wise Values';
            app.DNN_ReconstructionErrorTypeLabel = uilabel(app.DeepNeuralNetworksTab);
            app.DNN_ReconstructionErrorTypeLabel.Tooltip = {'This defines how overlapping reconstructed values are combined to produce a single value per time-point'};
            app.DNN_ReconstructionErrorTypeLabel.Text = {'Reconstruction'; 'Error Type'};
            app.ModelLabel = uilabel(app.DeepNeuralNetworksTab);
            app.ModelLabel.Text = 'Model';
            app.DNN_ModelSelectionDropDown = uidropdown(app.DeepNeuralNetworksTab);
            app.DNN_ModelSelectionDropDown.Items = {'FC-AE', 'LSTM (reconstruction)', 'Hybrid CNN-LSTM (reconstruction)', 'TCN-AE', 'LSTM', 'Hybrid CNN-LSTM', 'GRU', 'CNN (DeepAnT)', 'ResNet', 'MLP'};
            app.DNN_ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DNN_ModelSelectionDropDownValueChanged, true);
            app.DNN_ModelSelectionDropDown.Value = 'FC-AE';
            app.ScoringFunctionDropDownLabel = uilabel(app.DeepNeuralNetworksTab);
            app.ScoringFunctionDropDownLabel.Tooltip = {'The scoring function determines how the anomaly scores are calculated'};
            app.ScoringFunctionDropDownLabel.Text = 'Scoring Function';
            app.DNN_ScoringFunctionDropDown = uidropdown(app.DeepNeuralNetworksTab);
            app.DNN_ScoringFunctionDropDown.Items = {'Errors (aggregated)', 'Errors (channel-wise)', 'Gauss', 'Gauss (aggregated)', 'Gauss (channel-wise)', 'EWMA'};
            app.DNN_ScoringFunctionDropDown.Tooltip = {'The scoring function determines how the anomaly scores are calculated'};
            app.DNN_ScoringFunctionDropDown.Value = 'Errors (aggregated)';
            app.DNN_DimensionalityDropDown = uidropdown(app.DeepNeuralNetworksTab);
            app.DNN_DimensionalityDropDown.Items = {'multivariate', 'univariate'};
            app.DNN_DimensionalityDropDown.Tooltip = {'Dimensionality of the model. If univariate, a separate model will be trained for every channel of the dataset.'};
            app.DNN_DimensionalityDropDown.Value = 'multivariate';
            app.DNN_DimensionalityLabel = uilabel(app.DeepNeuralNetworksTab);
            app.DNN_DimensionalityLabel.Tooltip = {'Dimensionality of the model. If univariate, a separate model will be trained for every channel of the dataset.'};
            app.DNN_DimensionalityLabel.Text = 'Dimensionality';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.O_DataAndTrainingHyperparametersPanel = uipanel(app.OtherTab);
            app.O_DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.O_WindowSizeEditField = uieditfield(app.O_DataAndTrainingHyperparametersPanel, 'numeric');
            app.O_WindowSizeEditField.Limits = [1 Inf];
            app.O_WindowSizeEditField.RoundFractionalValues = 'on';
            app.O_WindowSizeEditField.Value = 100;
            app.O_StepSizeEditField = uieditfield(app.O_DataAndTrainingHyperparametersPanel, 'numeric');
            app.O_StepSizeEditField.Limits = [1 Inf];
            app.O_StepSizeEditField.RoundFractionalValues = 'on';
            app.O_StepSizeEditField.Visible = 'off';
            app.O_StepSizeEditField.Value = 1;
            app.O_UseSlidingWindowCheckBox = uicheckbox(app.O_DataAndTrainingHyperparametersPanel);
            app.O_UseSlidingWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @O_UseSlidingWindowCheckBoxValueChanged, true);
            app.O_UseSlidingWindowCheckBox.Tooltip = {'Split data into overlapping subsequences using a sliding window. Please refer to the individual algorithm as to what option is correct for your use-case!'};
            app.O_UseSlidingWindowCheckBox.Text = 'Use sliding Window';
            app.O_UseSlidingWindowCheckBox.Value = true;
            app.O_WindowSizeLabel = uilabel(app.O_DataAndTrainingHyperparametersPanel);
            app.O_WindowSizeLabel.Text = 'Window Size';
            app.O_StepSizeLabel = uilabel(app.O_DataAndTrainingHyperparametersPanel);
            app.O_StepSizeLabel.Visible = 'off';
            app.O_StepSizeLabel.Text = 'Step Size';
            app.O_ModelHyperparametersPanel = uipanel(app.OtherTab);
            app.O_ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.O_ModelParameterEditField_2 = uieditfield(app.O_ModelHyperparametersPanel, 'numeric');
            app.O_ModelParameterEditField_2.Limits = [0 Inf];
            app.O_ModelParameterEditField_2.RoundFractionalValues = 'on';
            app.O_ModelParameterEditField_2.Value = 128;
            app.O_ModelParameterLabel_1 = uilabel(app.O_ModelHyperparametersPanel);
            app.O_ModelParameterLabel_1.Text = 'iTrees';
            app.O_ModelParameterLabel_2 = uilabel(app.O_ModelHyperparametersPanel);
            app.O_ModelParameterLabel_2.Text = 'Observations per iTree';
            app.O_ModelParameterEditField_3 = uieditfield(app.O_ModelHyperparametersPanel, 'numeric');
            app.O_ModelParameterEditField_3.Limits = [0 Inf];
            app.O_ModelParameterEditField_3.RoundFractionalValues = 'on';
            app.O_ModelParameterEditField_3.Visible = 'off';
            app.O_ModelParameterLabel_3 = uilabel(app.O_ModelHyperparametersPanel);
            app.O_ModelParameterLabel_3.Visible = 'off';
            app.O_ModelParameterLabel_3.Text = '';
            app.O_ModelParameterEditField_4 = uieditfield(app.O_ModelHyperparametersPanel, 'numeric');
            app.O_ModelParameterEditField_4.Limits = [0 Inf];
            app.O_ModelParameterEditField_4.RoundFractionalValues = 'on';
            app.O_ModelParameterEditField_4.Visible = 'off';
            app.O_ModelParameterLabel_4 = uilabel(app.O_ModelHyperparametersPanel);
            app.O_ModelParameterLabel_4.Visible = 'off';
            app.O_ModelParameterLabel_4.Text = '';
            app.O_ModelParameterEditField_1 = uieditfield(app.O_ModelHyperparametersPanel, 'numeric');
            app.O_ModelParameterEditField_1.Limits = [0 Inf];
            app.O_ModelParameterEditField_1.RoundFractionalValues = 'on';
            app.O_ModelParameterEditField_1.Value = 100;
            app.O_MerlinDetectionModeRadioButtonGroup = uibuttongroup(app.OtherTab);
            app.O_MerlinDetectionModeRadioButtonGroup.Visible = 'off';
            app.O_MerlinUseMedianDiscordLocationsButton = uiradiobutton(app.O_MerlinDetectionModeRadioButtonGroup);
            app.O_MerlinUseMedianDiscordLocationsButton.Tooltip = {'Calculate the median discord location of each discords across all subsequence lengths'};
            app.O_MerlinUseMedianDiscordLocationsButton.Text = 'Use Median Discord Locations';
            app.O_MerlinUseMedianDiscordLocationsButton.Value = true;
            app.O_MerlinMergeAllPredictionsButton = uiradiobutton(app.O_MerlinDetectionModeRadioButtonGroup);
            app.O_MerlinMergeAllPredictionsButton.Tooltip = {'Calculate the detected labels for all subsequence lenghts and merge them afterwards'};
            app.O_MerlinMergeAllPredictionsButton.Text = 'Merge all Predictions';
            app.O_DetectionModeButtonGroup = uibuttongroup(app.OtherTab);
            app.O_DetectionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @O_DetectionModeButtonGroupSelectionChanged, true);
            app.O_OutlierDetectionButton = uiradiobutton(app.O_DetectionModeButtonGroup);
            app.O_OutlierDetectionButton.Text = 'Outlier Detection';
            app.O_OutlierDetectionButton.Value = true;
            app.O_NoveltyDetectionButton = uiradiobutton(app.O_DetectionModeButtonGroup);
            app.O_NoveltyDetectionButton.Text = 'Novelty Detection (requires train data)';
            app.ModelLabel_2 = uilabel(app.OtherTab);
            app.ModelLabel_2.Text = 'Model';
            app.O_ModelSelectionDropDown = uidropdown(app.OtherTab);
            app.O_ModelSelectionDropDown.Items = {'iForest', 'OC-SVM', 'LOF', 'ABOD', 'LDOF', 'MERLIN', 'Grubbs test', 'over-sampling PCA'};
            app.O_ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @O_ModelSelectionDropDownValueChanged, true);
            app.O_ModelSelectionDropDown.Value = 'iForest';
            app.O_DimensionalityDropDown = uidropdown(app.OtherTab);
            app.O_DimensionalityDropDown.Items = {'multivariate', 'univariate'};
            app.O_DimensionalityDropDown.Tooltip = {'Dimensionality of the model. If univariate, a separate model will be trained for every channel of the dataset.'};
            app.O_DimensionalityDropDown.Value = 'multivariate';
            app.O_DimensionalityLabel = uilabel(app.OtherTab);
            app.O_DimensionalityLabel.Tooltip = {'Dimensionality of the model. If univariate, a separate model will be trained for every channel of the dataset.'};
            app.O_DimensionalityLabel.Text = 'Dimensionality';
            app.CloseButton = uibutton(app.AddModelPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TSADModelSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADModelSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADModelSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADModelSelectionUIFigure)
        end
    end
end